classdef P2ROUD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        P2ROUD_UIFigure                matlab.ui.Figure
        ExportPanel                    matlab.ui.container.Panel
        Lamp6                          matlab.ui.control.Lamp
        ExportGIFButton                matlab.ui.control.Button
        Lamp5                          matlab.ui.control.Lamp
        ExportMRDButton                matlab.ui.control.Button
        Lamp4                          matlab.ui.control.Lamp
        ExportNIFTIButton              matlab.ui.control.Button
        ExportProgressGauge            matlab.ui.control.LinearGauge
        Lamp3                          matlab.ui.control.Lamp
        ExportDCMButton                matlab.ui.control.Button
        MessagesTabGroup               matlab.ui.container.TabGroup
        MessagesTab                    matlab.ui.container.Tab
        statusLabel                    matlab.ui.control.Label
        StatusImage                    matlab.ui.control.Image
        StatusBox                      matlab.ui.control.TextArea
        MessageBox                     matlab.ui.control.TextArea
        ReconstructionTabGroup         matlab.ui.container.TabGroup
        ReconstructionTab              matlab.ui.container.Tab
        MaskButton                     matlab.ui.control.Button
        OpenButton                     matlab.ui.control.Button
        CloseButton                    matlab.ui.control.Button
        DilateButton                   matlab.ui.control.Button
        ErodeButton                    matlab.ui.control.Button
        ThresholdSlider                matlab.ui.control.Slider
        ThresholdEditField             matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel        matlab.ui.control.Label
        OrientationSpinner             matlab.ui.control.Spinner
        orientationSpinnerLabel        matlab.ui.control.Label
        MovieImage                     matlab.ui.control.Image
        AutoLabel                      matlab.ui.control.Label
        AutoIntensityCheckBox          matlab.ui.control.CheckBox
        Button_2                       matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        SetButton                      matlab.ui.control.Button
        WindowEditField                matlab.ui.control.NumericEditField
        LevelEditField                 matlab.ui.control.NumericEditField
        WindowSlider                   matlab.ui.control.Slider
        LLabel                         matlab.ui.control.Label
        WLabel                         matlab.ui.control.Label
        LevelSlider                    matlab.ui.control.Slider
        Dim3Label                      matlab.ui.control.Label
        Dim4Label                      matlab.ui.control.Label
        Dim5Label                      matlab.ui.control.Label
        Dim6Label                      matlab.ui.control.Label
        XLabel                         matlab.ui.control.Label
        YLabel                         matlab.ui.control.Label
        Dim3EditField                  matlab.ui.control.NumericEditField
        Dim4EditField                  matlab.ui.control.NumericEditField
        Dim5EditField                  matlab.ui.control.NumericEditField
        Dim6EditField                  matlab.ui.control.NumericEditField
        Dim3Slider                     matlab.ui.control.Slider
        Dim4Slider                     matlab.ui.control.Slider
        Dim5Slider                     matlab.ui.control.Slider
        Dim6Slider                     matlab.ui.control.Slider
        RecoFig                        matlab.ui.control.UIAxes
        MIPTab                         matlab.ui.container.Tab
        MIPResizeButton                matlab.ui.control.StateButton
        PlayMIPxButton                 matlab.ui.control.Button
        PlayMIPyButton                 matlab.ui.control.Button
        MIPOrientationSpinner          matlab.ui.control.Spinner
        orientationLabel_2             matlab.ui.control.Label
        MIPXLabel                      matlab.ui.control.Label
        MIPYLabel                      matlab.ui.control.Label
        MIPSetButton                   matlab.ui.control.Button
        MIPWindowEditField             matlab.ui.control.NumericEditField
        MIPLevelEditField              matlab.ui.control.NumericEditField
        MIPWindowSlider                matlab.ui.control.Slider
        MIPLLabel                      matlab.ui.control.Label
        MIPWLabel                      matlab.ui.control.Label
        MIPLevelSlider                 matlab.ui.control.Slider
        MIPAngleResetButton            matlab.ui.control.Button
        MIPRotateLabel                 matlab.ui.control.Label
        MIPxAngleLabel                 matlab.ui.control.Label
        MIPyAngleLabel                 matlab.ui.control.Label
        MIPxAngleSlider                matlab.ui.control.Slider
        MIPyAngleSlider                matlab.ui.control.Slider
        MIPFig                         matlab.ui.control.UIAxes
        PhaseTab                       matlab.ui.container.Tab
        minPhaseLabel                  matlab.ui.control.Label
        maxPhaseLabel                  matlab.ui.control.Label
        PhaseUnwrapButton              matlab.ui.control.Button
        PhaseOrientationSpinner        matlab.ui.control.Spinner
        OrientationSpinner_2Label_2    matlab.ui.control.Label
        PhaseMovieImage                matlab.ui.control.Image
        PhaseDim6Label                 matlab.ui.control.Label
        PhaseDim5Label                 matlab.ui.control.Label
        PhaseDim4Label                 matlab.ui.control.Label
        PhaseDim3Label                 matlab.ui.control.Label
        PhaseDim6Slider                matlab.ui.control.Slider
        PhaseDim6EditField             matlab.ui.control.NumericEditField
        PhaseDim4EditField             matlab.ui.control.NumericEditField
        PhaseDim5EditField             matlab.ui.control.NumericEditField
        PhaseDim3EditField             matlab.ui.control.NumericEditField
        PhaseDim4Slider                matlab.ui.control.Slider
        PhaseDim5Slider                matlab.ui.control.Slider
        PhaseDim3Slider                matlab.ui.control.Slider
        PhaseMovieStopButton           matlab.ui.control.Button
        PhaseMovieStartButton          matlab.ui.control.Button
        PhaseXLabel                    matlab.ui.control.Label
        PhaseYLabel                    matlab.ui.control.Label
        PhaseRecoFig                   matlab.ui.control.UIAxes
        FlowTab                        matlab.ui.container.Tab
        maxLabel                       matlab.ui.control.Label
        minLabel                       matlab.ui.control.Label
        VencLamp                       matlab.ui.control.Lamp
        FlowDirectionSpinner           matlab.ui.control.Spinner
        FlowDirectionSpinnerLabel      matlab.ui.control.Label
        LoadVencTableButton            matlab.ui.control.Button
        CalcFlowButton                 matlab.ui.control.Button
        FlowMovieStopButton            matlab.ui.control.Button
        FlowMovieStartButton           matlab.ui.control.Button
        FlowOrientationSpinner         matlab.ui.control.Spinner
        FlowOrientationSpinnerLabel    matlab.ui.control.Label
        FlowMovieImage                 matlab.ui.control.Image
        FlowDim6Label                  matlab.ui.control.Label
        FlowDim5Label                  matlab.ui.control.Label
        FlowDim4Label                  matlab.ui.control.Label
        FlowDim3Label                  matlab.ui.control.Label
        FlowDim6Slider                 matlab.ui.control.Slider
        FlowDim6EditField              matlab.ui.control.NumericEditField
        FlowDim4EditField              matlab.ui.control.NumericEditField
        FlowDim5EditField              matlab.ui.control.NumericEditField
        FlowDim3EditField              matlab.ui.control.NumericEditField
        FlowDim4Slider                 matlab.ui.control.Slider
        FlowDim5Slider                 matlab.ui.control.Slider
        FlowDim3Slider                 matlab.ui.control.Slider
        FlowXLabel                     matlab.ui.control.Label
        FlowYLabel                     matlab.ui.control.Label
        FlowRecoFig                    matlab.ui.control.UIAxes
        ScanParameterTabGroup          matlab.ui.container.TabGroup
        ScanParametersTab              matlab.ui.container.Tab
        VencViewField                  matlab.ui.control.EditField
        VencLabel                      matlab.ui.control.Label
        TimeDynViewField               matlab.ui.control.EditField
        TimeNRLabel                    matlab.ui.control.Label
        ScanTimeViewField              matlab.ui.control.EditField
        scantimeLabel                  matlab.ui.control.Label
        TrajectoryViewField            matlab.ui.control.EditField
        TrajectoryLabel                matlab.ui.control.Label
        FAViewField                    matlab.ui.control.EditField
        FAdegEditFieldLabel            matlab.ui.control.Label
        MRDfileViewField               matlab.ui.control.EditField
        MRDfileLabel                   matlab.ui.control.Label
        NEViewField                    matlab.ui.control.NumericEditField
        NEEditFieldLabel               matlab.ui.control.Label
        NFAViewField                   matlab.ui.control.NumericEditField
        NFALabel                       matlab.ui.control.Label
        NAViewField                    matlab.ui.control.NumericEditField
        NALabel                        matlab.ui.control.Label
        TEViewField                    matlab.ui.control.NumericEditField
        TEmsLabel                      matlab.ui.control.Label
        TRViewField                    matlab.ui.control.NumericEditField
        TRmsLabel                      matlab.ui.control.Label
        FOVViewField1                  matlab.ui.control.NumericEditField
        FOVmmLabel                     matlab.ui.control.Label
        KMatrixViewField1              matlab.ui.control.NumericEditField
        matrixLabel                    matlab.ui.control.Label
        SequenceViewField              matlab.ui.control.EditField
        sequenceLabel                  matlab.ui.control.Label
        NRViewField                    matlab.ui.control.NumericEditField
        NREditFieldLabel_3             matlab.ui.control.Label
        MZLabel                        matlab.ui.control.Label
        MYLabel                        matlab.ui.control.Label
        MXLabel                        matlab.ui.control.Label
        FOVViewField3                  matlab.ui.control.NumericEditField
        FOVViewField2                  matlab.ui.control.NumericEditField
        KMatrixViewField3              matlab.ui.control.NumericEditField
        KMatrixViewField2              matlab.ui.control.NumericEditField
        GeometryTab                    matlab.ui.container.Tab
        SlabOverlapEditField           matlab.ui.control.NumericEditField
        FOVLabel_12                    matlab.ui.control.Label
        SliceGapViewField              matlab.ui.control.NumericEditField
        FOVLabel_11                    matlab.ui.control.Label
        FOVLabel_10                    matlab.ui.control.Label
        AngleZViewField                matlab.ui.control.NumericEditField
        FOVLabel_9                     matlab.ui.control.Label
        AngleYViewField                matlab.ui.control.NumericEditField
        FOVLabel_8                     matlab.ui.control.Label
        AngleXViewField                matlab.ui.control.NumericEditField
        FOVLabel_7                     matlab.ui.control.Label
        OffsetZViewField               matlab.ui.control.NumericEditField
        OffsetYViewField               matlab.ui.control.NumericEditField
        FOVLabel_6                     matlab.ui.control.Label
        OffsetXViewField               matlab.ui.control.NumericEditField
        FOVLabel_5                     matlab.ui.control.Label
        SliceThicknessViewField        matlab.ui.control.NumericEditField
        FOVLabel_4                     matlab.ui.control.Label
        PhaseOrientationViewField      matlab.ui.control.NumericEditField
        FOVLabel_3                     matlab.ui.control.Label
        AspectRatioViewField           matlab.ui.control.NumericEditField
        FOVLabel_2                     matlab.ui.control.Label
        FieldOfViewViewField2          matlab.ui.control.NumericEditField
        FOVLabel                       matlab.ui.control.Label
        KspaceTabGroup                 matlab.ui.container.TabGroup
        KspaceTab                      matlab.ui.container.Tab
        CoilSpinner                    matlab.ui.control.Spinner
        CoilSpinnerLabel               matlab.ui.control.Label
        KFlipAngleEditField            matlab.ui.control.NumericEditField
        FALabel                        matlab.ui.control.Label
        KOrientationSpinner            matlab.ui.control.Spinner
        orientationLabel               matlab.ui.control.Label
        KEchoEditField                 matlab.ui.control.NumericEditField
        EchoLabel                      matlab.ui.control.Label
        KWindowEditField               matlab.ui.control.NumericEditField
        KLevelEditField                matlab.ui.control.NumericEditField
        KWindowSlider                  matlab.ui.control.Slider
        KLLabel                        matlab.ui.control.Label
        KWLabel                        matlab.ui.control.Label
        KLevelSlider                   matlab.ui.control.Slider
        FastSlowSwitch                 matlab.ui.control.RockerSwitch
        KspacemovieLabel               matlab.ui.control.Label
        KStopButton                    matlab.ui.control.Button
        KStartButton                   matlab.ui.control.Button
        FillingPercLabel               matlab.ui.control.Label
        FillingViewField               matlab.ui.control.NumericEditField
        KFlipAngleSlider               matlab.ui.control.Slider
        KDim4Label                     matlab.ui.control.Label
        KEchoSlider                    matlab.ui.control.Slider
        KDim3Label                     matlab.ui.control.Label
        ButtonGroup                    matlab.ui.container.ButtonGroup
        senseMapButton                 matlab.ui.control.RadioButton
        fillingButton                  matlab.ui.control.RadioButton
        averagesButton                 matlab.ui.control.RadioButton
        kvaluesButton                  matlab.ui.control.RadioButton
        KyLabel                        matlab.ui.control.Label
        KxLabel                        matlab.ui.control.Label
        KDim4EditField                 matlab.ui.control.NumericEditField
        KDim4Slider                    matlab.ui.control.Slider
        KDim3Slider                    matlab.ui.control.Slider
        KDim3EditField                 matlab.ui.control.NumericEditField
        KspaceFig                      matlab.ui.control.UIAxes
        CoilsTab                       matlab.ui.container.Tab
        SensitivityMapsPanel           matlab.ui.container.Panel
        Label_8                        matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        SenseFig8                      matlab.ui.control.UIAxes
        SenseFig7                      matlab.ui.control.UIAxes
        SenseFig6                      matlab.ui.control.UIAxes
        SenseFig5                      matlab.ui.control.UIAxes
        SenseFig4                      matlab.ui.control.UIAxes
        SenseFig3                      matlab.ui.control.UIAxes
        SenseFig2                      matlab.ui.control.UIAxes
        SenseFig1                      matlab.ui.control.UIAxes
        CoilSelectionPanel             matlab.ui.container.Panel
        AutoSensitivityCheckBox        matlab.ui.control.CheckBox
        S8EditField                    matlab.ui.control.NumericEditField
        S7EditField                    matlab.ui.control.NumericEditField
        S6EditField                    matlab.ui.control.NumericEditField
        S5EditField                    matlab.ui.control.NumericEditField
        S4EditField                    matlab.ui.control.NumericEditField
        S3EditField                    matlab.ui.control.NumericEditField
        RelativesensitivityLabel       matlab.ui.control.Label
        S2EditField                    matlab.ui.control.NumericEditField
        CoilLabel                      matlab.ui.control.Label
        S1EditField                    matlab.ui.control.NumericEditField
        C8CheckBox                     matlab.ui.control.CheckBox
        C7CheckBox                     matlab.ui.control.CheckBox
        C6CheckBox                     matlab.ui.control.CheckBox
        C5CheckBox                     matlab.ui.control.CheckBox
        C4CheckBox                     matlab.ui.control.CheckBox
        C3CheckBox                     matlab.ui.control.CheckBox
        C2CheckBox                     matlab.ui.control.CheckBox
        C1CheckBox                     matlab.ui.control.CheckBox
        RadialTab                      matlab.ui.container.Tab
        CorrectionsPanel               matlab.ui.container.Panel
        DensityCorrectLabel            matlab.ui.control.Label
        DensityCorrectCheckBox         matlab.ui.control.CheckBox
        CenterEchoLabel                matlab.ui.control.Label
        CenterEchoCheckBox             matlab.ui.control.CheckBox
        PhaseCorrectLabel              matlab.ui.control.Label
        PhaseCorrectCheckBox           matlab.ui.control.CheckBox
        GradientdelaysPanel            matlab.ui.container.Panel
        DataOffsetRadialEditField      matlab.ui.control.NumericEditField
        dataoffsetLabel                matlab.ui.control.Label
        GzDelayEditField               matlab.ui.control.NumericEditField
        GzdelayEditFieldLabel          matlab.ui.control.Label
        GyDelayEditField               matlab.ui.control.NumericEditField
        GydelayEditFieldLabel          matlab.ui.control.Label
        GxDelayEditField               matlab.ui.control.NumericEditField
        GxdelayEditFieldLabel          matlab.ui.control.Label
        AbortGradCalButton             matlab.ui.control.Button
        RingMethodLabel                matlab.ui.control.Label
        RingMethodCheckBox             matlab.ui.control.CheckBox
        GradDelayCalibrationLabel      matlab.ui.control.Label
        GradDelayCalibrationCheckBox   matlab.ui.control.CheckBox
        DashboardTabGroup              matlab.ui.container.TabGroup
        DashboardTab                   matlab.ui.container.Tab
        RecoProgressGauge              matlab.ui.control.LinearGauge
        AbortButton                    matlab.ui.control.Button
        ReconstructionparametersPanel  matlab.ui.container.Panel
        DeRingCheckBox                 matlab.ui.control.CheckBox
        DeRingLabel                    matlab.ui.control.Label
        DeNoiseWindowEditField         matlab.ui.control.NumericEditField
        DeNoiseLabel                   matlab.ui.control.Label
        DeNoiseCheckBox                matlab.ui.control.CheckBox
        LRxyzEditField                 matlab.ui.control.NumericEditField
        LRxyzEditFieldLabel            matlab.ui.control.Label
        CSRecoLabel                    matlab.ui.control.Label
        CSRecoCheckBox                 matlab.ui.control.CheckBox
        WVxyzEditField                 matlab.ui.control.NumericEditField
        WVxyzEditFieldLabel            matlab.ui.control.Label
        TVtimeEditField                matlab.ui.control.NumericEditField
        TVtimeEditFieldLabel           matlab.ui.control.Label
        TVxyzEditField                 matlab.ui.control.NumericEditField
        TVxyzEditFieldLabel            matlab.ui.control.Label
        ReconstructiondimensionsPanel  matlab.ui.container.Panel
        NREditField                    matlab.ui.control.NumericEditField
        NREditFieldLabel_4             matlab.ui.control.Label
        ZEditField                     matlab.ui.control.NumericEditField
        ZEditFieldLabel                matlab.ui.control.Label
        YEditField                     matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        XEditFieldLabel                matlab.ui.control.Label
        ExitButton                     matlab.ui.control.Button
        Lamp2                          matlab.ui.control.Lamp
        RecoimagesButton               matlab.ui.control.Button
        Lamp1                          matlab.ui.control.Lamp
        LoadMRDButton                  matlab.ui.control.Button
        P2ROUDLabel                    matlab.ui.control.Label
        MRSImage                       matlab.ui.control.Image
        CreditsLabel                   matlab.ui.control.Label
        TulpImage                      matlab.ui.control.Image
        TitleLabel                     matlab.ui.control.Label
    end


    % ------------------------------------------------------
    %
    % P2ROUD
    % Preclinical PROspective Undersampling in multiple Dimensions
    %
    % Gustav Strijkers
    % Amsterdam UMC
    % g.j.strijkers@amsterdamumc.nl
    %
    %
    % Version 1.0
    % - initial commit
    %
    % Version 1.1
    % - add scaling for k-space visualization
    % - corrected orientation of odd-echo images in multi-gradient-echo
    %
    % Version 1.2
    % - bug fixes
    % - reconstruction of CINE images, for which NE-dimension is the CINE loop
    % - GIF export
    % - Image zoom
    % - NIFTI export for 2D multislice data
    % - Bug fix in DICOM export
    % - Bug fix in NIFTI export dimensions
    %
    % Version 1.3
    % - multi coil reconstruction panel
    % - bug fixes
    % - read total acquisition time from rpr file if available
    %
    % Version 1.4
    % - Low-rank in spatial domain reconstruction added (with BART)
    % - Improved Tukey filter centered around k-space maximum
    % - rtable.rtv is renamed to origrtable.rtv.orig to prevent resorting of kspace with sur export
    % - removed CINE flipping of odd images, for some reason not needed anymore
    % - Added reconstruction parameter export fil
    %
    % Version 3.0
    % - New proudData class introduced for easier app maintenance
    % - Various bug fixes
    % - MIP visualization
    %
    % Version 3.1
    % - Noise suppression algorithm
    %
    % Version 4.0
    % - Phase and 4D-flow reconstruction
    %
    % Version 4.1 
    % - Bug fixes
    %
    % Version 4.2
    % - Some improvements to the MIP
    % - Bug fixes
    %
    % Version 4.3
    % - Bug fixes
    %
    % Version 5.0
    % - 2D radial reconstruction
    % - Image shifts for off-center acquistitions correctly incorporated
    % - Image orientation labels
    % - Mouse wheel scrolling for main image plot added
    % - Import of partially completed acquisition possible
    %
    % Version 5.2
    % - P2ROUD k-space trajectories, with pe1_order = 4 (exLUT)
    % - multi-echo, multi-experiments, multi-flip-angle, P2ROUD reconstruction
    % - Gibbs ringing suppression filter
    % - Improved reconstructions
    % - PCA denoising
    % - Segmented k-space filling
    %
    % Version 5.3
    % - Improvements to 3D image reconstruction (magnitude and phase images)
    % - Logaritmic k-space image for better visibility
    % - Fixed removal of navigator for data that has one
    % - EPI reconstruction improvements
    % - Tooltips updated
    %
    % Version 5.4
    % - Coil sensitivity maps
    %
    %
    %


    % --------------------------------------------------------------
    %
    %                     PUBLIC PROPERTIES
    %
    % --------------------------------------------------------------

    properties (Access = public)

        % Proud class
        pd                          proudData       % Data and meta data

        % App version
        appVersion = "5.4.1"        % App version
        bartVersion                 % Bart version

        % Files and directories
        appDir                      % Application directory
        oldDir                      % Starting directory
        currentDir                  % Deployed directory
        mrdImportPath               % MRD import path
        mrdExportPath               % MRD export path
        dicomExportPath             % Dicom export path
        niftiExportPath             % NIFTI export path
        gifExportPath               % GIF export path
        tag                         % Filename tag
        ignoreFiles                 % Files to ignore during import
       
        % Flags
        bartDetected_flag           % Bart toolbox detected true or false
        mrd_flag                    % MRD directory checked true or false
        dicom_flag                  % Dicom directory checked true or false
        proudRecoScan_flag          % Flag whether this is data previously reconstructed with the same app
        forceProud_flag             % Force P2ROUD trajectory input true / false
        validNIFTIdata_flag         % Data suitable for NIFTI export true or false
        validMovie_flag             % Data with multiple repetitions true or false
        newFig_flag                 % New figure flag false or true
        newPhaseFig_flag            % New phase figure flag false or true
        newFlowFig_flag             % New flow figure flag false or true
        stopReco_flag               % Stop the reco true or false
        stopMovie_flag              % Movie running (false) or stopping (true)
        stopPhaseMovie_flag         % Movie running (false) or stopping (true)
        stopKspaceMovie_flag        % K movie running (false) or stopping (true)
        stopMIPxMovie_flag          % MIP x movie running (false) or stopping (true)
        stopMIPyMovie_flag          % MIP y movie running (false) or stopping (true)
        stopFlowMovie_flag          % Flow movie running (false) or stopping (true)
        stopGradCal_flag            % Stop the grdient calibration (false/true)
        scrollEnable_flag           % Mouse scroll wheel enable true/false
       
        % Figure & plot elements
        imageOrient                 % Image orientation permutation matrix
        kSpaceOrientLabel           % Orientation labels in k-space figure
        imageOrientLabel            % Orientation labels in image figure
        cbar                        % Kspace averages and filling colorbar
        rect                        % Rectangle box
        green                       % Other shade of green
        brightgreen                 % Green
        red                         % Other shade of red
        brightred                   % Red
        blue                        % Blue
        yellow                      % Yellow
        orange                      % Orange
        kmovieFig                   % Kmovie fig object
        buttonStatus                % Status of the buttons
        recoImage                   % Reconstructed image object
        phaseRecoImage              % Reconstructed phase image object
        flowRecoImage               % Reconstructed flow image object
        blackImage                  % Black background image
        MIPcounter                  % MIP angle counter
        MIPstep = 5                 % MIP angle step size
        structElement               % Image morphological operation structuring element

        % Sounds
        warningSound                % Warning sound
        errorSound                  % Error sound

    end


    % --------------------------------------------------------------
    %
    %                     PRIVATE FUNCTIONS
    %
    % --------------------------------------------------------------


    methods (Access = private)


        % --------------------------------------------------------------
        %
        %                     LIGHTS AND BUTTONS
        %
        % --------------------------------------------------------------

        % Set the lights
        function SetLightsFnc(app,code)

            if ~strcmp(code{1},'Null') app.Lamp1.Color = code{1}; end %#ok<*SEPEX> 
            if ~strcmp(code{2},'Null') app.Lamp2.Color = code{2}; end
            if ~strcmp(code{3},'Null') app.Lamp3.Color = code{3}; end
            if ~strcmp(code{4},'Null') app.Lamp4.Color = code{4}; end
            if ~strcmp(code{5},'Null') app.Lamp5.Color = code{5}; end
            if ~strcmp(code{6},'Null') app.Lamp6.Color = code{6}; end
            drawnow;

        end


        % Set the buttons
        function SetButtonsFnc(app,code)

            if ~strcmp(code{1},'Null') app.LoadMRDButton.Enable = code{1}; end
            if ~strcmp(code{2},'Null') app.RecoimagesButton.Enable = code{2}; end
            if ~strcmp(code{3},'Null') app.ExportDCMButton.Enable = code{3}; end
            if ~strcmp(code{4},'Null') app.ExportNIFTIButton.Enable = code{4}; end
            if ~strcmp(code{5},'Null') app.ExportMRDButton.Enable = code{5}; end
            if ~strcmp(code{6},'Null') app.ExportGIFButton.Enable = code{6}; end
            if ~strcmp(code{7},'Null') app.ExitButton.Enable = code{7}; end
            drawnow;

        end


        % Switch of all buttons
        function SwitchOffAllButtonsFnc(app)

            app.SetButtonsFnc({'Off','Off','Off','Off','Off','Off','Off'});

        end


        % Enable/disable reco parameters
        function SetRecoFieldsFnc(app)

            if app.CSRecoCheckBox.Value == 1
                app.WVxyzEditField.Enable = 'on';
                app.TVxyzEditField.Enable = 'on';
                app.LRxyzEditField.Enable = 'on';
                app.TVtimeEditField.Enable = 'on';
            else
                app.WVxyzEditField.Enable = 'off';
                app.TVxyzEditField.Enable = 'off';
                app.LRxyzEditField.Enable = 'off';
                app.TVtimeEditField.Enable = 'off';
            end

            if ~app.bartDetected_flag
                app.TVxyzEditField.Enable = 'off';
                app.LRxyzEditField.Enable = 'off';
            end

            drawnow;

        end


        % Disable reco buttons
        function DisableRecoButtonsFnc(app)

            app.XEditField.Enable = 'off';
            app.YEditField.Enable = 'off';
            app.ZEditField.Enable = 'off';
            app.NREditField.Enable = 'off';
            app.WVxyzEditField.Enable= 'off';
            app.TVxyzEditField.Enable = 'off';
            app.LRxyzEditField.Enable = 'off';
            app.TVtimeEditField.Enable = 'off';
            app.CSRecoCheckBox.Enable = 'off';
            app.DeNoiseCheckBox.Enable = 'off';
            app.DeNoiseWindowEditField.Enable = 'off';
            app.DeRingCheckBox.Enable = 'off';

            drawnow;

        end

        % Disable reco buttons
        function EnableRecoButtonsFnc(app)

            app.XEditField.Enable = 'on';
            app.YEditField.Enable = 'on';
            app.ZEditField.Enable = 'on';
            app.NREditField.Enable = 'on';
            app.WVxyzEditField.Enable= 'on';
            app.TVxyzEditField.Enable = 'on';
            app.LRxyzEditField.Enable = 'on';
            app.TVtimeEditField.Enable = 'on';
            app.CSRecoCheckBox.Enable = 'on';
            app.DeNoiseCheckBox.Enable = 'on';
            app.DeNoiseWindowEditField.Enable = 'on';
            app.DeRingCheckBox.Enable = 'on';

            app.CSRecoCheckBoxValueChanged;

            drawnow;

        end


        % Disable k-buttons
        function DisableKbuttonsFnc(app)

            app.buttonStatus = [app.KDim3EditField.Enable;app.KDim3Slider.Enable;app.KDim4EditField.Enable;app.KDim4Slider.Enable; ...
                app.KFlipAngleEditField.Enable;app.KFlipAngleSlider.Enable;app.KEchoEditField.Enable;app.KEchoSlider.Enable; ...
                app.KOrientationSpinner.Enable;app.fillingButton.Enable;app.averagesButton.Enable;app.kvaluesButton.Enable;app.CoilSpinner.Enable];

            app.KDim3EditField.Enable = 'off';
            app.KDim3Slider.Enable = 'off';
            app.KDim4EditField.Enable = 'off';
            app.KDim4Slider.Enable = 'off';
            app.KFlipAngleEditField.Enable = 'off';
            app.KFlipAngleSlider.Enable = 'off';
            app.KEchoEditField.Enable = 'off';
            app.KEchoSlider.Enable = 'off';
            app.KOrientationSpinner.Enable = 'off';
            app.fillingButton.Enable = 'off';
            app.averagesButton.Enable = 'off';
            app.kvaluesButton.Enable = 'off';
            app.CoilSpinner.Enable = 'off';

            drawnow;

        end


        % Enable k-buttons
        function EnableKbuttonsFnc(app)

            app.KDim3EditField.Enable = app.buttonStatus(1);
            app.KDim3Slider.Enable = app.buttonStatus(2);
            app.KDim4EditField.Enable = app.buttonStatus(3);
            app.KDim4Slider.Enable = app.buttonStatus(4);
            app.KFlipAngleEditField.Enable = app.buttonStatus(5);
            app.KFlipAngleSlider.Enable = app.buttonStatus(6);
            app.KEchoEditField.Enable = app.buttonStatus(7);
            app.KEchoSlider.Enable = app.buttonStatus(8);
            app.KOrientationSpinner.Enable = app.buttonStatus(9);
            app.fillingButton.Enable = app.buttonStatus(10);
            app.averagesButton.Enable = app.buttonStatus(11);
            app.kvaluesButton.Enable = app.buttonStatus(12);
            app.CoilSpinner.Enable = app.buttonStatus(13);

            drawnow;

        end


        % Reset coil buttons
        function ResetCoilButtonsFnc(app)

            app.C1CheckBox.Value = 1;
            app.C1CheckBox.Enable = 'on';
            if app.pd.nrCoils > 1 app.C2CheckBox.Enable = 'on'; app.C2CheckBox.Value = 1; else app.C2CheckBox.Enable = 'off'; app.C2CheckBox.Value = 0; end
            if app.pd.nrCoils > 2 app.C3CheckBox.Enable = 'on'; app.C3CheckBox.Value = 1; else app.C3CheckBox.Enable = 'off'; app.C3CheckBox.Value = 0; end
            if app.pd.nrCoils > 3 app.C4CheckBox.Enable = 'on'; app.C4CheckBox.Value = 1; else app.C4CheckBox.Enable = 'off'; app.C4CheckBox.Value = 0; end
            if app.pd.nrCoils > 4 app.C5CheckBox.Enable = 'on'; app.C5CheckBox.Value = 1; else app.C5CheckBox.Enable = 'off'; app.C5CheckBox.Value = 0; end
            if app.pd.nrCoils > 5 app.C6CheckBox.Enable = 'on'; app.C6CheckBox.Value = 1; else app.C6CheckBox.Enable = 'off'; app.C6CheckBox.Value = 0; end
            if app.pd.nrCoils > 6 app.C7CheckBox.Enable = 'on'; app.C7CheckBox.Value = 1; else app.C7CheckBox.Enable = 'off'; app.C7CheckBox.Value = 0; end
            if app.pd.nrCoils > 7 app.C8CheckBox.Enable = 'on'; app.C8CheckBox.Value = 1; else app.C8CheckBox.Enable = 'off'; app.C8CheckBox.Value = 0; end

            app.S1EditField.Value = 1;
            app.S2EditField.Value = 1;
            app.S3EditField.Value = 1;
            app.S4EditField.Value = 1;
            app.S5EditField.Value = 1;
            app.S6EditField.Value = 1;
            app.S7EditField.Value = 1;
            app.S8EditField.Value = 1;

            app.pd.coilSensitivities = [app.S1EditField.Value app.S2EditField.Value app.S3EditField.Value app.S4EditField.Value ...
                app.S5EditField.Value app.S6EditField.Value app.S7EditField.Value app.S8EditField.Value];

            app.pd.coilActive_flag = [app.C1CheckBox.Value app.C2CheckBox.Value app.C3CheckBox.Value app.C4CheckBox.Value ...
                app.C5CheckBox.Value app.C6CheckBox.Value app.C7CheckBox.Value app.C8CheckBox.Value];

            app.S1EditField.Enable = 'on';
            if app.pd.nrCoils > 1 app.S2EditField.Enable = 'on'; else app.S2EditField.Enable = 'off'; end
            if app.pd.nrCoils > 2 app.S3EditField.Enable = 'on'; else app.S3EditField.Enable = 'off'; end
            if app.pd.nrCoils > 3 app.S4EditField.Enable = 'on'; else app.S4EditField.Enable = 'off'; end
            if app.pd.nrCoils > 4 app.S5EditField.Enable = 'on'; else app.S5EditField.Enable = 'off'; end
            if app.pd.nrCoils > 5 app.S6EditField.Enable = 'on'; else app.S6EditField.Enable = 'off'; end
            if app.pd.nrCoils > 6 app.S7EditField.Enable = 'on'; else app.S7EditField.Enable = 'off'; end
            if app.pd.nrCoils > 7 app.S8EditField.Enable = 'on'; else app.S8EditField.Enable = 'off'; end

            app.AutoSensitivityCheckBox.Value = 0;
            app.AutoSensitivityCheckBox.Enable = 'on';

            drawnow

        end


        % Enable coil buttons
        function EnableCoilButtonsFnc(app)

            app.C1CheckBox.Enable = 'on';
            if app.pd.nrCoils > 1 app.C2CheckBox.Enable = 'on'; else app.C2CheckBox.Enable = 'off'; end
            if app.pd.nrCoils > 2 app.C3CheckBox.Enable = 'on'; else app.C3CheckBox.Enable = 'off'; end
            if app.pd.nrCoils > 3 app.C4CheckBox.Enable = 'on'; else app.C4CheckBox.Enable = 'off'; end
            if app.pd.nrCoils > 4 app.C5CheckBox.Enable = 'on'; else app.C5CheckBox.Enable = 'off'; end
            if app.pd.nrCoils > 5 app.C6CheckBox.Enable = 'on'; else app.C6CheckBox.Enable = 'off'; end
            if app.pd.nrCoils > 6 app.C7CheckBox.Enable = 'on'; else app.C7CheckBox.Enable = 'off'; end
            if app.pd.nrCoils > 7 app.C8CheckBox.Enable = 'on'; else app.C8CheckBox.Enable = 'off'; end

            app.AutoSensitivityCheckBox.Enable = 'on';
            if app.AutoSensitivityCheckBox.Value == 0

                app.S1EditField.Enable = 'on';
                if app.pd.nrCoils > 1 app.S2EditField.Enable = 'on'; else app.S2EditField.Enable = 'off'; end
                if app.pd.nrCoils > 2 app.S3EditField.Enable = 'on'; else app.S3EditField.Enable = 'off'; end
                if app.pd.nrCoils > 3 app.S4EditField.Enable = 'on'; else app.S4EditField.Enable = 'off'; end
                if app.pd.nrCoils > 4 app.S5EditField.Enable = 'on'; else app.S5EditField.Enable = 'off'; end
                if app.pd.nrCoils > 5 app.S6EditField.Enable = 'on'; else app.S6EditField.Enable = 'off'; end
                if app.pd.nrCoils > 6 app.S7EditField.Enable = 'on'; else app.S7EditField.Enable = 'off'; end
                if app.pd.nrCoils > 7 app.S8EditField.Enable = 'on'; else app.S8EditField.Enable = 'off'; end

            else

                app.S1EditField.Enable = 'off';
                app.S2EditField.Enable = 'off';
                app.S3EditField.Enable = 'off';
                app.S4EditField.Enable = 'off';
                app.S5EditField.Enable = 'off';
                app.S6EditField.Enable = 'off';
                app.S7EditField.Enable = 'off';
                app.S8EditField.Enable = 'off';

            end

            drawnow

        end


        % Disable coil buttons
        function DisableCoilButtonsFnc(app)

            app.C1CheckBox.Enable = 'off';
            app.C2CheckBox.Enable = 'off';
            app.C3CheckBox.Enable = 'off';
            app.C4CheckBox.Enable = 'off';
            app.C5CheckBox.Enable = 'off';
            app.C6CheckBox.Enable = 'off';
            app.C7CheckBox.Enable = 'off';
            app.C8CheckBox.Enable = 'off';

            app.AutoSensitivityCheckBox.Enable = 'off';

            app.S1EditField.Enable = 'off';
            app.S2EditField.Enable = 'off';
            app.S3EditField.Enable = 'off';
            app.S4EditField.Enable = 'off';
            app.S5EditField.Enable = 'off';
            app.S6EditField.Enable = 'off';
            app.S7EditField.Enable = 'off';
            app.S8EditField.Enable = 'off';

            drawnow

        end


        % Enable radial buttons
        function EnableRadialButtonsFnc(app)
                
            if app.bartDetected_flag

                switch app.pd.dataType

                    case "2Dradial"

                        app.GradDelayCalibrationCheckBox.Enable = 'on';
                        app.RingMethodCheckBox.Enable = 'on';
                        app.AbortGradCalButton.Enable = 'on';
                        app.DensityCorrectCheckBox.Enable = 'on';
                        app.DataOffsetRadialEditField.Enable = 'off';

                    case "3Dute"

                        app.GradDelayCalibrationCheckBox.Enable = 'on';
                        app.RingMethodCheckBox.Enable = 'off';
                        app.AbortGradCalButton.Enable = 'on';
                        app.DensityCorrectCheckBox.Enable = 'on';
                        app.DataOffsetRadialEditField.Enable = 'on';

                    otherwise

                        app.GradDelayCalibrationCheckBox.Enable = 'off';
                        app.RingMethodCheckBox.Enable = 'off';
                        app.AbortGradCalButton.Enable = 'off';
                        app.DensityCorrectCheckBox.Enable = 'off';
                        app.DataOffsetRadialEditField.Enable = 'off';

                end

            else

                app.GradDelayCalibrationCheckBox.Enable = 'off';
                app.RingMethodCheckBox.Enable = 'off';
                app.AbortGradCalButton.Enable = 'off';
                app.DensityCorrectCheckBox.Enable = 'off';
                app.DataOffsetRadialEditField.Enable = 'off';

            end

            app.GxDelayEditField.Enable = 'on';
            app.GyDelayEditField.Enable = 'on';
            app.GzDelayEditField.Enable = 'on';
            app.CenterEchoCheckBox.Enable = 'on';
            app.PhaseCorrectCheckBox.Enable = 'on';

            drawnow;

        end


        % Disable radial buttons
        function DisableRadialButtonsFnc(app)
                
            app.GradDelayCalibrationCheckBox.Enable = 'off';
            app.RingMethodCheckBox.Enable = 'off';
            app.GxDelayEditField.Enable = 'off';
            app.GyDelayEditField.Enable = 'off';
            app.GzDelayEditField.Enable = 'off';
            app.CenterEchoCheckBox.Enable = 'off';
            app.PhaseCorrectCheckBox.Enable = 'off';
            app.DensityCorrectCheckBox.Enable = 'off';
            app.AbortGradCalButton.Enable = 'off';
            app.DataOffsetRadialEditField.Enable = 'off';

            drawnow;

        end


        % Set images, figure properties, etc..
        function SetAppLayoutFncFnc(app)

            % Images
            app.TulpImage.ImageSource = 'AmsterdamUMC.gif';
            app.MRSImage.ImageSource = 'MRS.gif';
            app.MovieImage.ImageSource = 'Movie.gif';
            app.PhaseMovieImage.ImageSource = 'Movie.gif';
            app.FlowMovieImage.ImageSource = 'Movie.gif';
            app.blackImage = imread('black.png');

            % Buttons
            app.LoadMRDButton.Icon = 'disk.png';
            app.RecoimagesButton.Icon = 'reconstruct.gif';
            app.ExportDCMButton.Icon = 'disk.png';
            app.ExportNIFTIButton.Icon = 'disk.png';
            app.ExportMRDButton.Icon = 'disk.png';
            app.ExportGIFButton.Icon = 'disk.png';
            app.ExitButton.Icon = 'exit.png';
            app.Button.Icon = 'play.gif';
            app.Button_2.Icon = 'stop.gif';
            app.PhaseMovieStartButton.Icon = 'play.gif';
            app.PhaseMovieStopButton.Icon = 'stop.gif';
            app.KStartButton.Icon = 'play.gif';
            app.KStopButton.Icon = 'stop.gif';
            app.PlayMIPxButton.Icon = 'play.gif';
            app.PlayMIPyButton.Icon = 'play.gif';
            app.FlowMovieStartButton.Icon = 'play.gif';
            app.FlowMovieStopButton.Icon = 'stop.gif';
            app.MaskButton.Icon = 'segmentation.png';
            app.PhaseUnwrapButton.Icon = 'unwrap.png';
            app.LoadVencTableButton.Icon = 'disk.png';
            app.CalcFlowButton.Icon = 'flow.png';
           
            % Title
            app.TitleLabel.Text = ['P' char(178) 'ROUD'];

            % Define app colors
            app.brightred = 'Red';
            app.red = '#D95319';
            app.brightgreen = 'Green';
            app.green = '#6CB557';
            app.yellow = 'Yellow';
            app.orange = '#FDA400';
            app.blue = '#0073BE';

            % Load sounds
            [app.warningSound,~] = audioread('Glass.aiff');
            [app.errorSound,~] = audioread('Ping.aiff');

            % Block figure interactivity
            app.KspaceFig.Toolbar.Visible = 'off';
            app.KspaceFig.Interactions = [];
            app.MIPFig.Toolbar.Visible = 'off';
            app.MIPFig.Interactions = [];
            app.PhaseRecoFig.Toolbar.Visible = 'off';
            app.PhaseRecoFig.Interactions = [];
            app.PhaseRecoFig.Interactions = [zoomInteraction regionZoomInteraction];
            app.FlowRecoFig.Toolbar.Visible = 'off';
            app.FlowRecoFig.Interactions = [];
            app.FlowRecoFig.Interactions = [zoomInteraction regionZoomInteraction];
            app.SenseFig1.Toolbar.Visible = 'off';
            app.SenseFig1.Interactions = [];
            app.SenseFig2.Toolbar.Visible = 'off';
            app.SenseFig2.Interactions = [];
            app.SenseFig3.Toolbar.Visible = 'off';
            app.SenseFig3.Interactions = [];
            app.SenseFig4.Toolbar.Visible = 'off';
            app.SenseFig4.Interactions = [];
            app.SenseFig5.Toolbar.Visible = 'off';
            app.SenseFig5.Interactions = [];
            app.SenseFig6.Toolbar.Visible = 'off';
            app.SenseFig6.Interactions = [];
            app.SenseFig7.Toolbar.Visible = 'off';
            app.SenseFig7.Interactions = [];
            app.SenseFig8.Toolbar.Visible = 'off';
            app.SenseFig8.Interactions = [];

            % Set k-space level and window limits
            app.KLevelEditField.Limits = [0 app.pd.maxKspace+1];
            app.KLevelSlider.Limits = [0 app.pd.maxKspace+1];
            app.KWindowEditField.Limits = [0 app.pd.maxKspace+1];
            app.KWindowSlider.Limits = [0 app.pd.maxKspace+1];

            % Set image level and window limits
            app.LevelEditField.Limits = [0 app.pd.maxImage+1];
            app.LevelSlider.Limits = [0 app.pd.maxImage+1];
            app.WindowEditField.Limits = [0 app.pd.maxImage+1];
            app.WindowSlider.Limits = [0 app.pd.maxImage+1];
            app.MIPLevelEditField.Limits = [0 app.pd.maxImage+1];
            app.MIPLevelSlider.Limits = [0 app.pd.maxImage+1];
            app.MIPWindowEditField.Limits = [0 app.pd.maxImage+1];
            app.MIPWindowSlider.Limits = [0 app.pd.maxImage+1];
            app.ThresholdEditField.Limits = [0 app.pd.maxImage+1];
            app.ThresholdSlider.Limits = [0 app.pd.maxImage+1];

            drawnow;

        end


        % --------------------------------------------------------------
        %
        %                           PARAMETERS
        %
        % --------------------------------------------------------------


        % Set data parameters
        function SetParametersFcnFnc(app)

            app.TextMessage('Setting k-space parameters ....');
            app.TrajectoryViewField.Value = '-';

            % Determine the type of multi-dimensional dataset
            app.pd = app.pd.setDataParameters(app);

            % Read the SQL properties
            if app.pd.sqlFlag
                app.pd = app.pd.sqlParameters(app);
            end
          
            % Standard k-space filling
            app.pd.trajType = "standard";

            % Scanner k-space table (RARE sequence)
            if ((exist(strcat(app.mrdImportPath,filesep,'rtable.rtv'), 'file') == 2) || (exist(strcat(app.mrdImportPath,filesep,'origrtable.rtv.orig'), 'file') == 2)) ...
                    && (app.pd.proudRecoScan_flag == false) && (app.pd.dataType ~= "2Dradial")
                app.TextMessage('Scanner k-space table detected ...');
                app.pd.trajType = "rtable";
                app.TrajectoryViewField.Value = 'rtable.rtv';
            end

            % User k-space trajectory & P2ROUD implementation
            if app.pd.proudRecoScan_flag == false

                % pe1_order == 3 : k-space trajectory list in preclinical
                if (app.pd.pe1_order == 3) && (app.pd.dataType ~= "2Dradial")
                    app.pd.trajType = "mul_kspace";
                    app.TrajectoryViewField.Value = 'mul_kspace_traj';
                end

                % pe1_order == 4 : 3D k-space trajectory from txt file
                if ((app.pd.pe1_order == 4) && (app.pd.dataType ~= "2Dradial")) || app.forceProud_flag
                    app.TextMessage('P2ROUD k-space trajectory ...');
                    
                    % Read k-space trajectory file
                    flist = dir(fullfile(app.mrdImportPath,'*.txt'));
                    if ~isempty(flist)
                        for cnt = length(flist):-1:1
                            if contains(flist(cnt).name,app.ignoreFiles)
                                flist(cnt) = [];
                            end
                        end
                    end

                    if ~isempty(flist)
                        app.pd.gp_var_proud = load([flist(1).folder,filesep,flist(1).name]);
                        app.TrajectoryViewField.Value = flist(1).name;
                        app.pd.trajType = "P2ROUD";
                        app.TextMessage(strcat('P2ROUD trajectory file = ',{' '},flist(1).name));
                    else
                        app.pd.trajType = "";
                        app.TextMessage('ERROR: Trajectory file not found ...');
                        app.SetStatus(2);
                    end

                end

            end

            % Show some parameters in app
            app.SequenceViewField.Value = app.pd.PPL;
            app.TRViewField.Value = app.pd.TR;
            app.TEViewField.Value = app.pd.TE;
            app.NAViewField.Value = app.pd.NO_AVERAGES;
            app.NRViewField.Value = app.pd.EXPERIMENT_ARRAY;
            app.NEViewField.Value = app.pd.NO_ECHOES;
            app.FAViewField.Value = num2str(app.pd.flipAngleArray);
            app.NFAViewField.Value = length(app.pd.flipAngleArray);
            app.TextMessage(strcat('Phase orientation = ',{' '},num2str(app.pd.PHASE_ORIENTATION),'...'));

            % Geometry parameters
            app.FieldOfViewViewField2.Value = app.pd.FOV;
            app.AspectRatioViewField.Value = app.pd.aspectratio;
            app.SliceThicknessViewField.Value = app.pd.SLICE_THICKNESS;
            app.SliceGapViewField.Value = app.pd.SQLsliceGap;
            app.AspectRatioViewField.Value = app.pd.aspectratio;
            app.PhaseOrientationViewField.Value = app.pd.PHASE_ORIENTATION;
            app.OffsetXViewField.Value = app.pd.SQLoffsetX;
            app.OffsetYViewField.Value = app.pd.SQLoffsetY;
            app.OffsetZViewField.Value = app.pd.SQLoffsetZ;
            app.AngleXViewField.Value = app.pd.SQLangleX;
            app.AngleYViewField.Value = app.pd.SQLangleY;
            app.AngleZViewField.Value = app.pd.SQLangleZ;

            % Flow
            if app.pd.venc > 0
                app.VencViewField.Value = num2str(app.pd.vencAmp);
            else
                app.VencViewField.Value = '-';
            end

            % Matrix sizes and parameters
            switch app.pd.dataType

                case "3Dute"

                    % 3D data
                    app.MZLabel.Text = 'kz';
                    app.ZEditFieldLabel.Text = 'Z';

                    % Permute k-space to X(readout), Y(spokes)
                    app.pd = app.pd.permute3Dkspace;
                    app.pd.multiRepetitions_flag = false;

                    % New matrix dimensions
                    app.KMatrixViewField1.Value = app.pd.NO_SAMPLES;
                    app.KMatrixViewField2.Value = app.pd.NO_VIEWS;
                    app.KMatrixViewField3.Value = app.pd.NO_VIEWS_2;
                    app.NRViewField.Value = app.pd.nr_repetitions;
                  
                    % Field of view
                    app.FOVViewField1.Value = app.pd.FOV;
                    app.FOVViewField2.Value = app.pd.FOV*app.pd.aspectratio;
                    app.FOVViewField3.Value = app.pd.SLICE_THICKNESS;

                    % Reco size
                    app.XEditField.Value = app.KMatrixViewField1.Value;
                    app.YEditField.Value = app.KMatrixViewField1.Value;
                    app.ZEditField.Value = app.KMatrixViewField3.Value;
                    app.NREditField.Value = app.NRViewField.Value;

                    % Gradient optimization settings
                    app.GradDelayCalibrationCheckBox.Value = 0;
                    app.RingMethodCheckBox.Value = 0;
                    app.CenterEchoCheckBox.Value = 0;
                    app.PhaseCorrectCheckBox.Value = 0;
                    app.DensityCorrectCheckBox.Value = 1;
                    app.EnableRadialButtonsFnc;

                    % Morphological operation structuring element
                    [xx,yy,zz] = ndgrid(-1:1);
                    app.structElement = sqrt(xx.^2 + yy.^2 + zz.^2) <= 1.0;

                case "3D"

                    % 3D data
                    app.MZLabel.Text = 'kz';
                    app.ZEditFieldLabel.Text = 'Z';

                    % Matrix dimensions
                    app.KMatrixViewField1.Value = app.pd.NO_SAMPLES;
                    app.KMatrixViewField2.Value = app.pd.NO_VIEWS;
                    app.KMatrixViewField3.Value = app.pd.NO_VIEWS_2;

                    % Permute k-space to (X, Y, Z, NR, NFA, NE)
                    app.pd = app.pd.permute3Dkspace;

                    % Field of view
                    app.FOVViewField1.Value = app.pd.FOV;
                    app.FOVViewField2.Value = app.pd.FOV*app.pd.aspectratio;
                    app.FOVViewField3.Value = (app.pd.SLICE_THICKNESS+app.pd.SQLsliceGap)*app.pd.NO_SLICES;
                 
                    % Reco size
                    app.XEditField.Value = app.KMatrixViewField1.Value;
                    app.YEditField.Value = app.KMatrixViewField2.Value;
                    app.ZEditField.Value = app.KMatrixViewField3.Value;
                    app.NREditField.Value = app.NRViewField.Value;

                    % Morphological operation structuring element
                    [xx,yy,zz] = ndgrid(-1:1);
                    app.structElement = sqrt(xx.^2 + yy.^2 + zz.^2) <= 1.0;

                case {"2D","2Depi"}

                    % 2D data or radial data
                    app.MZLabel.Text = 'Slices';
                    app.ZEditFieldLabel.Text = 'Slices';

                    % Matrix dimensions
                    app.KMatrixViewField1.Value = app.pd.NO_SAMPLES;
                    app.KMatrixViewField2.Value = app.pd.NO_VIEWS;
                    app.KMatrixViewField3.Value = app.pd.NO_SLICES;

                    % Permute k-space to (X, Y, Z, NR, NFA, NE)
                    app.pd = app.pd.permute2Dkspace;

                    % Field of view
                    app.FOVViewField1.Value = app.pd.FOV;
                    app.FOVViewField2.Value = app.pd.FOV*app.pd.aspectratio;
                    app.FOVViewField3.Value = app.pd.SLICE_THICKNESS;

                    % Reco size
                    app.XEditField.Value = app.KMatrixViewField1.Value;
                    app.YEditField.Value = app.KMatrixViewField2.Value;
                    app.ZEditField.Value = app.KMatrixViewField3.Value;
                    app.NREditField.Value = app.NRViewField.Value;

                    % Morphological operation structuring element
                    [xx,yy] = ndgrid(-1:1);
                    app.structElement = sqrt(xx.^2 + yy.^2) <= 1.0;

                case "2Dradial"

                    % 2D data or radial data
                    app.MZLabel.Text = 'Slices';
                    app.ZEditFieldLabel.Text = 'Slices';

                    % Matrix dimensions
                    app.KMatrixViewField1.Value = app.pd.NO_SAMPLES;
                    app.KMatrixViewField2.Value = app.pd.NO_VIEWS;
                    app.KMatrixViewField3.Value = app.pd.NO_SLICES;

                    % Permute k-space to (X, Y, Z, NR, NFA, NE)
                    app.pd = app.pd.permute2Dkspace;

                    % Field of view
                    app.FOVViewField1.Value = app.pd.FOV;
                    app.FOVViewField2.Value = app.pd.FOV*app.pd.aspectratio;
                    app.FOVViewField3.Value = app.pd.SLICE_THICKNESS;

                    % Reco size
                    app.XEditField.Value = app.KMatrixViewField1.Value;
                    app.YEditField.Value = app.KMatrixViewField2.Value;
                    app.ZEditField.Value = app.KMatrixViewField3.Value;
                    app.NREditField.Value = app.NRViewField.Value;

                    % Gradient optimization settings
                    app.GradDelayCalibrationCheckBox.Value = 1;
                    app.RingMethodCheckBox.Value = 1;
                    app.CenterEchoCheckBox.Value = 1;
                    app.PhaseCorrectCheckBox.Value = 1;
                    app.DensityCorrectCheckBox.Value = 1;
                    app.EnableRadialButtonsFnc;

                    % Morphological operation structuring element
                    [xx,yy] = ndgrid(-1:1);
                    app.structElement = sqrt(xx.^2 + yy.^2) <= 1.0;

            end

            % Image orientations
            switch app.pd.dataType

                case {"3D","3Dute"}

                    app.imageOrient = ...
                        [1 2 3 4 5 6 7; 2 3 1 4 5 6 7; 3 1 2 4 5 6 7; ...
                        4 1 2 3 5 6 7; 4 2 3 1 5 6 7; 4 3 1 2 5 6 7; ...
                        1 2 4 3 5 6 7; 2 3 4 1 5 6 7; 3 1 4 2 5 6 7; ...
                        1 2 3 6 4 5 7; 1 3 2 6 4 5 7; 2 3 1 6 4 5 7];

                    app.kSpaceOrientLabel = ["Kx","Ky","Kz","NR","FA","echo" ; "Ky","Kz","Kx","NR","FA","echo" ; "Kz","Kx","Ky","NR","FA","echo" ; ...
                        "NR","Kx","Ky","Kz","FA","echo" ; "NR","Ky","Kz","Kx","FA","echo" ; "NR","Kz","Kx","Ky","FA","echo" ; ...
                        "Kx","Ky","NR","Kz","FA","echo" ; "Ky","Kz","NR","Kx","FA","echo" ; "Kz","Kx","NR","Ky","FA","echo" ; ...
                        "Kx","Ky","Kz","echo","NR","FA" ; "Kx","Kz","Ky","echo","NR","FA" ; "Ky","Kz","Kx","echo","NR","FA" ];

                    app.imageOrientLabel = ["X","Y","Z","NR","FA","echo" ; "Y","Z","X","NR","FA","echo" ; "Z","X","Y","NR","FA","echo" ; ...
                        "NR","X","Y","Z","FA","echo" ; "NR","Y","Z","X","FA","echo" ; "NR","Z","X","Y","FA","echo" ; ...
                        "X","Y","NR","Z","FA","echo" ; "Y","Z","NR","X","FA","echo" ; "Z","X","NR","Y","FA","echo" ; ...
                        "X","Y","Z","echo","NR","FA" ; "X","Z","Y","echo","NR","FA" ; "Y","Z","X","echo","NR","FA" ];

                case {"2D","2Dradial","2Depi"}

                    app.imageOrient = ...
                        [1 2 3 4 5 6 ; 2 3 1 4 5 6 ; 3 1 2 4 5 6 ; ...
                        4 1 2 3 5 6 ; 4 2 3 1 5 6 ; 4 3 1 2 5 6 ; ...
                        1 2 4 3 5 6 ; 2 3 4 1 5 6 ; 3 1 4 2 5 6 ; ...
                        1 2 3 6 4 5 ; 1 3 2 6 4 5 ; 2 3 1 6 4 5 ];

                    app.kSpaceOrientLabel = ["Kx","Ky","slice","NR","FA","echo" ; "Ky","slice","Kx","NR","FA","echo" ; "slice","Kx","Ky","NR","FA","echo" ; ...
                        "NR","Kx","Ky","slice","FA","echo" ; "NR","Ky","slice","Kx","FA","echo" ; "NR","slice","Kx","Ky","FA","echo" ; ...
                        "Kx","Ky","NR","slice","FA","echo" ; "Ky","slice","NR","Kx","FA","echo" ; "slice","Kx","NR","Ky","FA","echo" ; ...
                        "Kx","Ky","slice","echo","NR","FA" ; "Kx","slice","Ky","echo","NR","FA" ; "Ky","slice","Kx","echo","NR","FA" ];

                    app.imageOrientLabel = ["X","Y","slice","NR","FA","echo" ; "Y","slice","X","NR","FA","echo" ; "slice","X","Y","NR","FA","echo" ; ...
                        "NR","X","Y","slice","FA","echo" ; "NR","Y","slice","X","FA","echo" ; "NR","slice","X","Y","FA","echo" ; ...
                        "X","Y","NR","slice","FA","echo" ; "Y","slice","NR","X","FA","echo" ; "slice","X","NR","Y","FA","echo" ; ...
                        "X","Y","slice","echo","NR","FA" ; "X","slice","Y","echo","NR","FA" ; "Y","slice","X","echo","NR","FA" ];

            end

            % Reset orientation spinners
            app.OrientationSpinner.Value = 1;
            app.MIPOrientationSpinner.Value = 1;
            app.KOrientationSpinner.Value = 1;
            app.PhaseOrientationSpinner.Value = 1;
            app.FlowOrientationSpinner.Value = 1;

            % Total scan time and scan time / dynamic
            app.CalcTotalAcquisitionTimeFcn;

        end % SetParametersFcn


        % Calculate the total acquisition time
        function CalcTotalAcquisitionTimeFcn(app)

            try

                if app.pd.rprFile_flag

                    pos = strfind(app.pd.rprFile,":IM_TEXT_DCM_AcquisitionDuration");
                    txt = app.pd.rprFile(pos:pos+40);
                    pos2 = strfind(txt,"""");
                    tt = extractBetween(txt,pos2(1)+1,pos2(2)-1);
                    app.pd.totalAcqTime = str2num(tt{1}); %#ok<*ST2NM>

                else

                    ME = MException('P2ROUD:acqnotfound','Acquisition duration not found in rpr file ...');
                    throw(ME);

                end

            catch ME

                app.TextMessage(ME.message);

                app.pd.totalAcqTime = app.pd.NO_VIEWS*app.pd.NO_SLICES*app.NEViewField.Value*app.NAViewField.Value*app.NFAViewField.Value*app.NRViewField.Value*app.pd.tr/1000;

                % FSE type sequences
                if contains(app.pd.PPL,'fse')
                    if app.pd.VIEWS_PER_SEGMENT > 1
                        app.pd.totalAcqTime = app.pd.totalAcqTime/app.pd.VIEWS_PER_SEGMENT;
                    else
                        app.pd.totalAcqTime = app.pd.totalAcqTime/app.NEViewField.Value;
                    end
                    app.pd.totalAcqTime = app.pd.totalAcqTime/app.pd.NO_SLICES;
                end

                % FLASH type sequences
                if contains(app.pd.PPL,'flash')
                    app.pd.totalAcqTime = app.pd.totalAcqTime/app.NEViewField.Value;
                    if app.pd.SLICE_INTERLEAVE == 2
                        app.pd.totalAcqTime = app.pd.totalAcqTime/app.pd.NO_SLICES;
                    end
                end

                app.pd.totalAcqTime = app.pd.totalAcqTime*app.pd.NO_VIEWS_2;

            end

            app.TimeDynViewField.Value = datestr(seconds(app.pd.totalAcqTime/app.NREditField.Value),'HH:MM:SS.FFF'); %#ok<*DATST> 
            app.ScanTimeViewField.Value = datestr(seconds(app.pd.totalAcqTime),'HH:MM:SS.FFF');

        end % CalcTotalAcquisitionTimeFcn


        % Set the k-space imaging limits
        function SetKspaceLimitsFcn(app)

            % Coils 
            if app.pd.nrCoils>1
                app.CoilSpinner.Value = 1;
                app.CoilSpinner.Limits = [1 app.pd.nrCoils];
                app.CoilSpinner.Enable = 'on';
                if nnz(app.pd.coilActive_flag) > 1
                    app.AutoSensitivityCheckBox.Value = 1;
                end
            else
                app.CoilSpinner.Value = 1;
                app.CoilSpinner.Limits = [1 2];
                app.CoilSpinner.Enable = 'off';
                app.pd.coilActive_flag = [1 0 0 0 0 0 0 0];
                app.AutoSensitivityCheckBox.Value = 0;
            end
         
            % Dimension 1 selection
            im = permute(abs(app.pd.rawKspace{app.CoilSpinner.Value}),app.imageOrient(app.KOrientationSpinner.Value,:));
            dim1size = size(im,3);
            app.KDim3EditField.Value = 1;
            app.KDim3Slider.Value = 1;
            if dim1size > 1
                app.KDim3EditField.Limits = [1,dim1size];
                app.KDim3Slider.Limits = [1,dim1size];
                app.KDim3EditField.Value = round(dim1size/2);
                app.KDim3Slider.Value = round(dim1size/2);
                app.KDim3EditField.Enable = 'on';
                app.KDim3Slider.Enable = 'on';
            else
                app.KDim3EditField.Limits = [1,2];
                app.KDim3Slider.Limits = [1,2];
                app.KDim3EditField.Enable = 'off';
                app.KDim3Slider.Enable = 'off';
            end

            % Dimension 2 selection
            dim2size = size(im,4);
            app.KDim4EditField.Value = 1;
            app.KDim4Slider.Value = 1;
            if dim2size > 1
                app.KDim4EditField.Limits = [1,dim2size];
                app.KDim4Slider.Limits = [1,dim2size];
                app.KDim4EditField.Value = round(dim2size/2);
                app.KDim4Slider.Value = round(dim2size/2);
                app.KDim4EditField.Enable = 'on';
                app.KDim4Slider.Enable = 'on';
            else
                app.KDim4EditField.Limits = [1,2];
                app.KDim4Slider.Limits = [1,2];
                app.KDim4EditField.Enable = 'off';
                app.KDim4Slider.Enable = 'off';
            end

            % Echo selection
            app.KEchoEditField.Value = 1;
            app.KEchoSlider.Value = 1;
            if app.pd.multiEchoes_flag
                app.KEchoEditField.Limits = [1,app.NEViewField.Value];
                app.KEchoSlider.Limits = [1,app.NEViewField.Value];
                app.KEchoEditField.Enable = 'on';
                app.KEchoSlider.Enable = 'on';
            else
                app.KEchoEditField.Limits = [1,2];
                app.KEchoSlider.Limits = [1,2];
                app.KEchoEditField.Enable = 'off';
                app.KEchoSlider.Enable = 'off';
            end

            % Flip angle selection
            app.KFlipAngleEditField.Value = 1;
            app.KFlipAngleSlider.Value = 1;
            if app.pd.multiFlipAngles_flag
                app.KFlipAngleEditField.Limits = [1,app.NFAViewField.Value];
                app.KFlipAngleSlider.Limits = [1,app.NFAViewField.Value];
                app.KFlipAngleEditField.Enable = 'on';
                app.KFlipAngleSlider.Enable = 'on';

            else
                app.KFlipAngleEditField.Limits = [1,2];
                app.KFlipAngleSlider.Limits = [1,2];
                app.KFlipAngleEditField.Enable = 'off';
                app.KFlipAngleSlider.Enable = 'off';
            end

            % Label the orientations
            if app.senseMapButton.Value == 1
                app.KxLabel.Text = app.imageOrientLabel(app.KOrientationSpinner.Value,1);
                app.KyLabel.Text = app.imageOrientLabel(app.KOrientationSpinner.Value,2);
                app.KDim3Label.Text = app.imageOrientLabel(app.KOrientationSpinner.Value,3);
                app.KDim4Label.Text = app.imageOrientLabel(app.KOrientationSpinner.Value,4);
            else
                app.KxLabel.Text = app.kSpaceOrientLabel(app.KOrientationSpinner.Value,1);
                app.KyLabel.Text = app.kSpaceOrientLabel(app.KOrientationSpinner.Value,2);
                app.KDim3Label.Text = app.kSpaceOrientLabel(app.KOrientationSpinner.Value,3);
                app.KDim4Label.Text = app.kSpaceOrientLabel(app.KOrientationSpinner.Value,4);
            end

            % Movie button
            app.KStopButton.Enable = 'off';

            app.buttonStatus = [app.KDim3EditField.Enable;app.KDim3Slider.Enable;app.KDim4EditField.Enable;app.KDim4Slider.Enable; ...
                app.KFlipAngleEditField.Enable;app.KFlipAngleSlider.Enable;app.KEchoEditField.Enable;app.KEchoSlider.Enable; ...
                app.KOrientationSpinner.Enable;app.fillingButton.Enable;app.averagesButton.Enable;app.kvaluesButton.Enable;app.CoilSpinner.Enable];

        end % SetKspaceLimitsFcn


        % Disable image sliders and edit boxes
        function DisableImageInteractionFnc(app)
            
            app.Dim3EditField.Enable = 'off';
            app.Dim4EditField.Enable = 'off';
            app.Dim5EditField.Enable = 'off';
            app.Dim6EditField.Enable = 'off';
            app.Dim3Slider.Enable = 'off';
            app.Dim4Slider.Enable = 'off';
            app.Dim5Slider.Enable = 'off';
            app.Dim6Slider.Enable = 'off';

            drawnow;

        end


        % Set the image limits
        function SetImageLimitsFcn(app)

            % Dimension 3 selection
            im = permute(abs(app.pd.images),app.imageOrient(app.OrientationSpinner.Value,:));
            dim3size = size(im,3);
            app.Dim3EditField.Value = 1;
            app.Dim3Slider.Value = 1;
            app.PhaseDim3EditField.Value = 1;
            app.PhaseDim3Slider.Value = 1;
            app.FlowDim3EditField.Value = 1;
            app.FlowDim3Slider.Value = 1;
            if dim3size > 1
                app.scrollEnable_flag = true;
                app.Dim3EditField.Limits = [1,dim3size];
                app.Dim3Slider.Limits = [1,dim3size];
                app.Dim3EditField.Value = round(dim3size/2);
                app.Dim3Slider.Value = round(dim3size/2);
                app.Dim3EditField.Enable = 'on';
                app.Dim3Slider.Enable = 'on';
                app.PhaseDim3EditField.Limits = [1,dim3size];
                app.PhaseDim3Slider.Limits = [1,dim3size];
                app.PhaseDim3EditField.Value = round(dim3size/2);
                app.PhaseDim3Slider.Value = round(dim3size/2);
                app.PhaseDim3EditField.Enable = 'on';
                app.PhaseDim3Slider.Enable = 'on';
                app.FlowDim3EditField.Limits = [1,dim3size];
                app.FlowDim3Slider.Limits = [1,dim3size];
                app.FlowDim3EditField.Value = round(dim3size/2);
                app.FlowDim3Slider.Value = round(dim3size/2);
                app.FlowDim3EditField.Enable = 'on';
                app.FlowDim3Slider.Enable = 'on';
            else
                app.scrollEnable_flag = false;
                app.Dim3EditField.Limits = [1,2];
                app.Dim3Slider.Limits = [1,2];
                app.Dim3EditField.Enable = 'off';
                app.Dim3Slider.Enable = 'off';
                app.PhaseDim3EditField.Limits = [1,2];
                app.PhaseDim3Slider.Limits = [1,2];
                app.PhaseDim3EditField.Enable = 'off';
                app.PhaseDim3Slider.Enable = 'off';
                app.FlowDim3EditField.Limits = [1,2];
                app.FlowDim3Slider.Limits = [1,2];
                app.FlowDim3EditField.Enable = 'off';
                app.FlowDim3Slider.Enable = 'off';
            end

            % Dimension 4 selection
            dim4size = size(im,4);
            app.Dim4EditField.Value = 1;
            app.Dim4Slider.Value = 1;
            app.PhaseDim4EditField.Value = 1;
            app.PhaseDim4Slider.Value = 1;
            app.FlowDim4EditField.Value = 1;
            app.FlowDim4Slider.Value = 1;
            if dim4size > 1
                app.Dim4EditField.Limits = [1,dim4size];
                app.Dim4Slider.Limits = [1,dim4size];
                app.Dim4EditField.Value = round(dim4size/2);
                app.Dim4Slider.Value = round(dim4size/2);
                app.Dim4EditField.Enable = 'on';
                app.Dim4Slider.Enable = 'on';
                app.PhaseDim4EditField.Limits = [1,dim4size];
                app.PhaseDim4Slider.Limits = [1,dim4size];
                app.PhaseDim4EditField.Value = round(dim4size/2);
                app.PhaseDim4Slider.Value = round(dim4size/2);
                app.PhaseDim4EditField.Enable = 'on';
                app.PhaseDim4Slider.Enable = 'on';
                app.FlowDim4EditField.Limits = [1,dim4size];
                app.FlowDim4Slider.Limits = [1,dim4size];
                app.FlowDim4EditField.Value = round(dim4size/2);
                app.FlowDim4Slider.Value = round(dim4size/2);
                app.FlowDim4EditField.Enable = 'on';
                app.FlowDim4Slider.Enable = 'on';
            else
                app.Dim4EditField.Limits = [1,2];
                app.Dim4Slider.Limits = [1,2];
                app.Dim4EditField.Enable = 'off';
                app.Dim4Slider.Enable = 'off';
                app.PhaseDim4EditField.Limits = [1,2];
                app.PhaseDim4Slider.Limits = [1,2];
                app.PhaseDim4EditField.Enable = 'off';
                app.PhaseDim4Slider.Enable = 'off';
                app.FlowDim4EditField.Limits = [1,2];
                app.FlowDim4Slider.Limits = [1,2];
                app.FlowDim4EditField.Enable = 'off';
                app.FlowDim4Slider.Enable = 'off';
            end

            % Dimension 5 selection
            dim5size = size(im,5);
            app.Dim5EditField.Value = 1;
            app.Dim5Slider.Value = 1;
            app.PhaseDim5EditField.Value = 1;
            app.PhaseDim5Slider.Value = 1;
            app.FlowDim5EditField.Value = 1;
            app.FlowDim5Slider.Value = 1;
            if dim5size > 1
                app.Dim5EditField.Limits = [1,dim5size];
                app.Dim5Slider.Limits = [1,dim5size];
                app.Dim5EditField.Enable = 'on';
                app.Dim5Slider.Enable = 'on';
                app.PhaseDim5EditField.Limits = [1,dim5size];
                app.PhaseDim5Slider.Limits = [1,dim5size];
                app.PhaseDim5EditField.Enable = 'on';
                app.PhaseDim5Slider.Enable = 'on';
                app.FlowDim5EditField.Limits = [1,dim5size];
                app.FlowDim5Slider.Limits = [1,dim5size];
                app.FlowDim5EditField.Enable = 'on';
                app.FlowDim5Slider.Enable = 'on';
            else
                app.Dim5EditField.Limits = [1,2];
                app.Dim5Slider.Limits = [1,2];
                app.Dim5EditField.Enable = 'off';
                app.Dim5Slider.Enable = 'off';
                app.PhaseDim5EditField.Limits = [1,2];
                app.PhaseDim5Slider.Limits = [1,2];
                app.PhaseDim5EditField.Enable = 'off';
                app.PhaseDim5Slider.Enable = 'off';
                app.FlowDim5EditField.Limits = [1,2];
                app.FlowDim5Slider.Limits = [1,2];
                app.FlowDim5EditField.Enable = 'off';
                app.FlowDim5Slider.Enable = 'off';
            end

            % Flip angle selection
            dim6size = size(im,6);
            app.Dim6EditField.Value = 1;
            app.Dim6Slider.Value = 1;
            app.PhaseDim6EditField.Value = 1;
            app.PhaseDim6Slider.Value = 1;
            app.FlowDim6EditField.Value = 1;
            app.FlowDim6Slider.Value = 1;
            if dim6size > 1
                app.Dim6EditField.Limits = [1,dim6size];
                app.Dim6Slider.Limits = [1,dim6size];
                app.Dim6EditField.Enable = 'on';
                app.Dim6Slider.Enable = 'on';
                app.PhaseDim6EditField.Limits = [1,dim6size];
                app.PhaseDim6Slider.Limits = [1,dim6size];
                app.PhaseDim6EditField.Enable = 'on';
                app.PhaseDim6Slider.Enable = 'on';
                app.FlowDim6EditField.Limits = [1,dim6size];
                app.FlowDim6Slider.Limits = [1,dim6size];
                app.FlowDim6EditField.Enable = 'on';
                app.FlowDim6Slider.Enable = 'on';

            else
                app.Dim6EditField.Limits = [1,2];
                app.Dim6Slider.Limits = [1,2];
                app.Dim6EditField.Enable = 'off';
                app.Dim6Slider.Enable = 'off';
                app.PhaseDim6EditField.Limits = [1,2];
                app.PhaseDim6Slider.Limits = [1,2];
                app.PhaseDim6EditField.Enable = 'off';
                app.PhaseDim6Slider.Enable = 'off';
                app.FlowDim6EditField.Limits = [1,2];
                app.FlowDim6Slider.Limits = [1,2];
                app.FlowDim6EditField.Enable = 'off';
                app.FlowDim6Slider.Enable = 'off';
            end

            % Label the orientations
            app.XLabel.Text = app.imageOrientLabel(app.OrientationSpinner.Value,1);
            app.YLabel.Text = app.imageOrientLabel(app.OrientationSpinner.Value,2);
            app.PhaseXLabel.Text = app.imageOrientLabel(app.PhaseOrientationSpinner.Value,1);
            app.PhaseYLabel.Text = app.imageOrientLabel(app.PhaseOrientationSpinner.Value,2);
            app.FlowXLabel.Text = app.imageOrientLabel(app.FlowOrientationSpinner.Value,1);
            app.FlowYLabel.Text = app.imageOrientLabel(app.FlowOrientationSpinner.Value,2);
            app.MIPXLabel.Text = app.XLabel.Text;
            app.MIPYLabel.Text = app.YLabel.Text;
            app.MIPxAngleLabel.Text = app.XLabel.Text;
            app.MIPyAngleLabel.Text = app.YLabel.Text;
            app.Dim3Label.Text = app.imageOrientLabel(app.OrientationSpinner.Value,3);
            app.Dim4Label.Text = app.imageOrientLabel(app.OrientationSpinner.Value,4);
            app.Dim5Label.Text = app.imageOrientLabel(app.OrientationSpinner.Value,5);
            app.Dim6Label.Text = app.imageOrientLabel(app.OrientationSpinner.Value,6);
            app.PhaseDim3Label.Text = app.imageOrientLabel(app.PhaseOrientationSpinner.Value,3);
            app.PhaseDim4Label.Text = app.imageOrientLabel(app.PhaseOrientationSpinner.Value,4);
            app.PhaseDim5Label.Text = app.imageOrientLabel(app.PhaseOrientationSpinner.Value,5);
            app.PhaseDim6Label.Text = app.imageOrientLabel(app.PhaseOrientationSpinner.Value,6);
            app.FlowDim3Label.Text = app.imageOrientLabel(app.PhaseOrientationSpinner.Value,3);
            app.FlowDim4Label.Text = app.imageOrientLabel(app.PhaseOrientationSpinner.Value,4);
            app.FlowDim5Label.Text = app.imageOrientLabel(app.PhaseOrientationSpinner.Value,5);
            app.FlowDim6Label.Text = app.imageOrientLabel(app.PhaseOrientationSpinner.Value,6);

            % Movie button
            if app.stopMovie_flag
                app.Button.Enable = 'on';
                app.Button_2.Enable = 'off';
            else
                app.Button.Enable = 'off';
                app.Button_2.Enable = 'on';
            end

            % Phase movie button
            if app.stopPhaseMovie_flag
                app.PhaseMovieStartButton.Enable = 'on';
                app.PhaseMovieStopButton.Enable = 'off';
            else
                app.PhaseMovieStartButton.Enable = 'off';
                app.PhaseMovieStopButton.Enable = 'on';
            end

            % Flow movie button
            if app.stopFlowMovie_flag
                app.FlowMovieStartButton.Enable = 'on';
                app.FlowMovieStopButton.Enable = 'off';
            else
                app.FlowMovieStartButton.Enable = 'off';
                app.FlowMovieStopButton.Enable = 'on';
            end

        end % SetImageLimitsFcn


        % Sort and fill k-space
        function FillKspaceFcn(app)

            % Indicate busy
            app.TextMessage('Filling k-space ....');
            app.SetStatus(3);

            try

                if app.pd.validFile_flag

                    app.pd.nsaSpace = app.pd.NO_AVERAGES * ones(size(app.pd.rawKspace{1}));
                    app.pd.fillingSpace = ones(size(app.pd.rawKspace{1}));
                    for coil = 1:app.pd.maxCoils
                        app.pd.sensitivityMaps{coil} = zeros(size(app.pd.rawKspace{1}));
                    end
                    app.pd.validTrajectory_flag = false;

                    switch app.pd.trajType

                        case "rtable"

                            if (exist(strcat(app.mrdImportPath,filesep,'rtable.rtv'), 'file') == 2)
                                scanner_kspace_table = load(strcat(app.mrdImportPath,filesep,'rtable.rtv'))+1;
                            elseif (exist(strcat(app.mrdImportPath,filesep,'origrtable.rtv.orig'), 'file') == 2)
                                scanner_kspace_table = load(strcat(app.mrdImportPath,filesep,'origrtable.rtv.orig'))+1;
                            end

                            % Sort
                            switch app.pd.dataType
                                case "2D"
                                    app.pd = app.pd.sortScanner2DKspaceMRD(app,scanner_kspace_table);
                                case "3D"
                                    app.pd = app.pd.sortScanner3DKspaceMRD(app,scanner_kspace_table);
                            end

                        case "mul_kspace"

                            maxk = max([app.pd.gp_var_mul,-app.pd.gp_var_mul]);
                            if rem(maxk,2) == 0
                                app.pd.NO_VIEWS = 2*maxk;
                            else
                                app.pd.NO_VIEWS = 2*(maxk+1);
                            end
                            app.YEditField.Value = app.pd.NO_VIEWS;
                            app.KMatrixViewField2.Value = app.pd.NO_VIEWS;
                            app.pd.validTrajectory_flag = true;

                            % Sort
                            switch app.pd.dataType
                                case "2D"
                                    app.pd = app.pd.sort2DKspaceMRD(app);
                                case "3D"
                                    app.pd = app.pd.sort3DKspaceMRD(app);
                            end

                        case "P2ROUD"

                            app.pd.proudArray = reshape(app.pd.gp_var_proud,[2,length(app.pd.gp_var_proud)/2]);
                            maxky = max([app.pd.proudArray(1,:),-app.pd.proudArray(1,:)]);
                            if rem(maxky,2) == 0
                                app.pd.NO_VIEWS = 2*maxky;
                            else
                                app.pd.NO_VIEWS = 2*(maxky+1);
                            end
                            maxkz = max([app.pd.proudArray(2,:),-app.pd.proudArray(2,:)]);
                            if rem(maxkz,2) == 0
                                app.pd.NO_VIEWS_2 = 2*maxkz;
                            else
                                app.pd.NO_VIEWS_2 = 2*(maxkz+1);
                            end
                            app.YEditField.Value = app.pd.NO_VIEWS;
                            app.ZEditField.Value = app.pd.NO_VIEWS_2;
                            app.KMatrixViewField2.Value = app.pd.NO_VIEWS;
                            app.KMatrixViewField3.Value = app.pd.NO_VIEWS_2;
                            app.pd.validTrajectory_flag = true;

                            % sort
                            app.pd = app.pd.sort3DProudKspaceMRD(app);

                        case "3Dute"

                            app.pd.validTrajectory_flag = true;
                            
                            % Load the LUT
                            flist = dir(fullfile(app.mrdImportPath,'lut*.txt'));
                            try    
                                app.pd.seqTrajectory = load([flist(1).folder,filesep,flist(1).name]);
                                app.TextMessage(strcat('3D UTE trajectory: ',{' '},flist(1).name,{' '},'...'));
                                app.pd.seqTrajectory = reshape(app.pd.seqTrajectory,[3,length(app.pd.seqTrajectory)/3]);
                                app.pd.seqTrajectory = app.pd.seqTrajectory(:,1:size(app.pd.rawKspace{1},2));           
                            catch ME
                                app.TextMessage(ME.message);
                                app.TextMessage('ERROR: Trajectory LUT file not found or invalid file ...');
                                app.SetStatus(2);
                                app.pd.validTrajectory_flag = false;
                            end

                            % Load the gradient calibration file
                            try
                                app.pd.gradTrajectory = load('ktraj.txt');
                                app.TextMessage('Gradient calibration file: ktraj.txt ...');
                            catch ME
                                app.TextMessage(ME.message);
                                app.TextMessage('ERROR: ktraj.txt file not found ...');
                                app.SetStatus(2);
                                app.pd.validTrajectory_flag = false;
                            end

                            app.pd.NO_SAMPLES = length(app.pd.gradTrajectory);
                            app.pd.NO_VIEWS = app.pd.NO_SAMPLES;
                            app.XEditField.Value = app.pd.NO_SAMPLES;
                            app.YEditField.Value = app.pd.NO_SAMPLES;
                            app.ZEditField.Value = app.pd.NO_SAMPLES;

                    end

                    % Segmented acquisition
                    if app.pd.segmentedData_flag
                        app.pd = app.pd.sort2DsegmKspaceMRD(app);
                    end

                    % Chop of the navigator if present
                    switch app.pd.trajType

                        case {"3Dute","2Dradial","2Dradialms"}
                            % do nothing
                        otherwise
                            app.pd = app.pd.chopNav(app);
                            app.XEditField.Value = app.pd.NO_SAMPLES;
                            app.KMatrixViewField1.Value = app.pd.NO_SAMPLES;

                    end

                end

                % K-space filling percentage, without cropping, dynamic interpolation, or zero-filling
                app.FillingViewField.Value = round(100*nnz(app.pd.fillingSpace)/numel(app.pd.fillingSpace));

                % Apply Tukey k-space filter
                app.TextMessage('Applying Tukey k-space filter ...');
                app.pd = app.pd.applyTukey;

                % Scale k-space to convenient range
                app.pd = app.pd.scaleKspace;

                % Window and level, plot
                app.SetKspaceWLFnc;
                app.KWindowEditFieldValueChanged;
                app.KLevelEditFieldValueChanged;

                % Status
                app.SetStatus(0);

            catch ME

                app.TextMessage('ERROR: something went wrong filling k-space ....');
                app.TextMessage(ME.message);
                app.SetStatus(2);

            end

        end % FillKspaceFcn


        % --------------------------------------------------------------
        %
        %                           FIGURES
        %
        % --------------------------------------------------------------


        % Calculate the k-space image aspect ratio
        function result = CalcKAspectRatioFnc(app,dim1Label,dim2Label)

            switch app.pd.dataType

                case "3Dute"

                    result = app.KMatrixViewField1.Value/app.KMatrixViewField2.Value;

                otherwise

                    L1 = 1;
                    L2 = 1;

                    if strcmp(dim1Label,'Kx') L1 = app.FOVViewField1.Value; end
                    if strcmp(dim1Label,'Ky') L1 = app.FOVViewField2.Value; end
                    if strcmp(dim1Label,'Kz') L1 = app.FOVViewField3.Value; end
                    if strcmp(dim1Label,'NR') L1 = min([app.FOVViewField1.Value,app.FOVViewField2.Value]); end
                    if strcmp(dim1Label,'slice') L1 = app.FOVViewField3.Value*app.KMatrixViewField3.Value; end

                    if strcmp(dim2Label,'Kx') L2 = app.FOVViewField1.Value; end
                    if strcmp(dim2Label,'Ky') L2 = app.FOVViewField2.Value; end
                    if strcmp(dim2Label,'Kz') L2 = app.FOVViewField3.Value; end
                    if strcmp(dim2Label,'NR') L2 = min([app.FOVViewField1.Value,app.FOVViewField2.Value]); end
                    if strcmp(dim2Label,'slice') L2 = app.FOVViewField3.Value*app.KMatrixViewField3.Value; end

                    result = (L2/L1);

            end

        end % CalcKAspectRatioFnc


        % Calculate the image aspect ratio
        function result = CalcImageAspectRatioFnc(app, dim1Label, dim2Label, sizeX, sizeY)

            L1 = 1;
            L2 = 1;

            if (strcmp(dim1Label,'X') || strcmp(dim1Label,'Kx')) L1 = app.FOVViewField1.Value; end
            if (strcmp(dim1Label,'Y') || strcmp(dim1Label,'Ky')) L1 = app.FOVViewField2.Value; end
            if (strcmp(dim1Label,'Z') || strcmp(dim1Label,'Kz')) L1 = app.FOVViewField3.Value; end
            if strcmp(dim1Label,'NR') L1 = mean([app.FOVViewField1.Value,app.FOVViewField2.Value]); end
            if strcmp(dim1Label,'slice') L1 = app.FOVViewField3.Value*app.KMatrixViewField3.Value; end

            if (strcmp(dim2Label,'X') || strcmp(dim2Label,'Kx')) L2 = app.FOVViewField1.Value; end
            if (strcmp(dim2Label,'Y') || strcmp(dim2Label,'Ky')) L2 = app.FOVViewField2.Value; end
            if (strcmp(dim2Label,'Z') || strcmp(dim2Label,'Kz')) L2 = app.FOVViewField3.Value; end
            if strcmp(dim2Label,'NR') L2 = mean([app.FOVViewField1.Value,app.FOVViewField2.Value]); end
            if strcmp(dim2Label,'slice') L2 = app.FOVViewField3.Value*app.KMatrixViewField3.Value; end

            result = (L1/L2)*(sizeY/sizeX);

        end % CalcImageAspectRatioFnc


        % Calculate the MIP image aspect ratio
        function result = CalcMIPAspectRatioFnc(app,dim1Label,dim2Label,sizeX,sizeY,alphaX,alphaY)

            if strcmp(dim1Label,'X') L1 = app.FOVViewField1.Value; end
            if strcmp(dim1Label,'Y') L1 = app.FOVViewField2.Value; end
            if strcmp(dim1Label,'Z') L1 = app.FOVViewField3.Value; end
            if strcmp(dim1Label,'NR') L1 = mean([app.FOVViewField1.Value,app.FOVViewField2.Value]); end
            if strcmp(dim1Label,'slice') L1 = app.FOVViewField3.Value*app.KMatrixViewField3.Value; end

            if strcmp(dim2Label,'X') L2 = app.FOVViewField1.Value; end
            if strcmp(dim2Label,'Y') L2 = app.FOVViewField2.Value; end
            if strcmp(dim2Label,'Z') L2 = app.FOVViewField3.Value; end
            if strcmp(dim2Label,'NR') L2 = mean([app.FOVViewField1.Value,app.FOVViewField2.Value]); end
            if strcmp(dim2Label,'slice') L2 = app.FOVViewField3.Value*app.KMatrixViewField3.Value; end

            if strcmp(app.Dim3Label.Text,'X') L3 = app.FOVViewField1.Value; end
            if strcmp(app.Dim3Label.Text,'Y') L3 = app.FOVViewField2.Value; end
            if strcmp(app.Dim3Label.Text,'Z') L3 = app.FOVViewField3.Value; end
            if strcmp(app.Dim3Label.Text,'NR') L3 = mean([app.FOVViewField1.Value,app.FOVViewField2.Value]); end
            if strcmp(app.Dim3Label.Text,'slice') L3 = app.FOVViewField3.Value*app.KMatrixViewField3.Value; end

            fovx = sqrt((L1 * cos(pi*alphaY/180))^2 + (L3 * sin(pi*alphaY/180))^2);
            fovy = sqrt((L2 * cos(pi*alphaX/180))^2 + (L3 * sin(pi*alphaX/180))^2);
            
            result = (fovy/fovx)*(sizeY/sizeX);

        end % CalcMIPAspectRatioFnc


        % Set the image window and level
        function SetImageWLFnc(app)

            if app.pd.validReco_flag

                % Select the data
                im = app.pd.images;

                im = permute(im,app.imageOrient(app.OrientationSpinner.Value,:));
                im = squeeze(im(:,:,app.Dim3EditField.Value,app.Dim4EditField.Value,app.Dim5EditField.Value,app.Dim6EditField.Value));

                maxScale = double(round(max(im(:))));
                threshold = double(graythresh(mat2gray(im)))*maxScale;
                imt = im;
                imt(imt < threshold) = 0;
                scale = mean(nonzeros(imt(:)));
                scale(scale > maxScale) = maxScale;
                scale(isnan(scale)) = maxScale;
                wScale = 2*scale;
                wScale(wScale > maxScale) = maxScale;
   
                app.LevelSlider.Value = double(scale);
                app.LevelEditField.Value = double(round(scale));   
                app.WindowSlider.Value = double(wScale);
                app.WindowEditField.Value = double(round(wScale));

            end

        end % SetImageWL


        % Set the image window and level
        function SetMIPWLFnc(app)

            if app.pd.validReco_flag

                % Select the data
                im = app.pd.images;
                im = permute(im,app.imageOrient(app.OrientationSpinner.Value,:));
                im = squeeze(im(:,:,:,app.Dim4EditField.Value,app.Dim5EditField.Value,app.Dim6EditField.Value));
                im = max(im,[],3);

                scale = max(im(:));

                app.MIPLevelSlider.Value = scale/3;
                app.MIPLevelEditField.Value = round(scale/3);
                app.MIPWindowSlider.Value = scale/1.5;
                app.MIPWindowEditField.Value = round(scale/1.5);

            end

        end % SetMIPWL


        % Set the k-space image window and level
        function SetKspaceWLFnc(app)

            if app.pd.validFile_flag

                % Select the data
                if app.senseMapButton.Value == 1
                    im = app.pd.sensitivityMaps{app.CoilSpinner.Value};
                else
                    im = abs(app.pd.rawKspace{app.CoilSpinner.Value});
                end

                % Select which slice to show
                im = permute(im,app.imageOrient(app.KOrientationSpinner.Value,:));
                im = squeeze(im(:,:,app.KDim3EditField.Value,app.KDim4EditField.Value,app.KFlipAngleEditField.Value,app.KEchoEditField.Value));

                if app.senseMapButton.Value == 1
                    window = double(round(0.9*app.pd.maxKspace*max(im(:))));
                    level = double(round(0.6*app.pd.maxKspace*max(im(:))));
                else
                    window = double(round(0.8*max(im(:))));
                    level = double(round(0.4*window));
                end

                app.KWindowSlider.Value = window;
                app.KWindowEditField.Value = window;
                app.KLevelSlider.Value = level;
                app.KLevelEditField.Value = level;
              
            end

        end % SetKspaceWL


        % Plot k-space
        function PlotKspaceFigFnc(app)

            try

                if app.pd.validFile_flag

                    cla(app.KspaceFig);
                    delete(app.cbar);

                    % Select the data
                    if app.fillingButton.Value == 1

                        im = app.pd.fillingSpace;
                        cmap = [0 0 0 ; summer(1)];
                        minScale = 0;
                        maxScale = max(im(:));

                    end

                    if app.averagesButton.Value == 1

                        im = app.pd.nsaSpace;
                        cmap = [0 0 0 ; summer(max(im(:)))];
                        minScale = 0;
                        maxScale = max(im(:));
                        app.cbar = colorbar(app.KspaceFig,'location','eastoutside','TickLabelsMode','auto','FontSize',12);

                    end

                    if app.kvaluesButton.Value == 1

                        % Plot the k-space
                        im = abs(app.pd.rawKspace{app.CoilSpinner.Value});
                        cmap = gray;

                        % Window and Level
                        lvl = app.KLevelSlider.Value;
                        wdw = app.KWindowSlider.Value;
                        im = (1/lvl)*(im - lvl + wdw/2);
                        im(im<0) = 0;
                        im(im>1) = 1;
                        minScale = 0;
                        maxScale = 1;

                    end

                    if app.senseMapButton.Value == 1

                        % Plot the sense map
                        im = 16384*abs(app.pd.sensitivityMaps{app.CoilSpinner.Value});
                        cmap = gray;

                        % Rotate the image if phase-orientation equals 1
                        if strcmp(app.KxLabel.Text,'X') && strcmp(app.KyLabel.Text,'Y') && app.pd.PHASE_ORIENTATION
                            im = rot90(im,-1);
                        end

                        % Window and Level
                        lvl = app.KLevelSlider.Value;
                        wdw = app.KWindowSlider.Value;
                        im = (1/lvl)*(im - lvl + wdw/2);
                        im(im<0) = 0;
                        im(im>1) = 1;
                        minScale = 0;
                        maxScale = 1;

                    end

                    % Plot the image in the image panel
                    app.KspaceFig.Position = [34,87,325,325];

                    % Select which slice to show
                    im = permute(im,app.imageOrient(app.KOrientationSpinner.Value,:));
                    im = squeeze(im(:,:,app.KDim3EditField.Value,app.KDim4EditField.Value,app.KFlipAngleEditField.Value,app.KEchoEditField.Value));

                    % Plot the image in the k-space images panel
                    imshow(rot90(im),[minScale maxScale],'ColorMap',cmap,'InitialMagnification','fit','Parent',app.KspaceFig);

                    % Size of the matrix
                    xlim(app.KspaceFig,[0, size(im,1) + 1]);
                    ylim(app.KspaceFig,[0, size(im,2) + 1]);

                    % Calculate aspect ratio
                    if app.senseMapButton.Value == 1
                        recoAspectRatio = app.CalcImageAspectRatioFnc(app.KxLabel.Text,app.KyLabel.Text,size(im,1),size(im,2));
                    else
                        recoAspectRatio = app.CalcKAspectRatioFnc(app.KxLabel.Text,app.KyLabel.Text);
                    end
                    daspect(app.KspaceFig,[1 recoAspectRatio 1]);

                    % Plot the 8 small sensitivity map figs
                    app.PlotSenseMapsFnc;

                    drawnow;

                end

            catch ME

                app.TextMessage(ME.message);
                app.TextMessage("Something has gone wrong during plotting in k-space figure panel ...");

            end

        end % PlotKspaceFig

        % Plot the 8 small sense maps in the coils panel
        function PlotSenseMapsFnc(app)

            try

                % Image colormap
                cmap = gray;

                % Make coil images
                for coil = 1:app.pd.maxCoils
                    im(coil,:,:,:,:,:,:,:) = abs(app.pd.sensitivityMaps{coil}); %#ok<AGROW>
                end

                im = permute(im,[1 1+app.imageOrient(app.KOrientationSpinner.Value,:)]);
                im = im(:,:,:,app.KDim3EditField.Value,app.KDim4EditField.Value,app.KFlipAngleEditField.Value,app.KEchoEditField.Value);

                % Rotate for phase_orientation equals 1
                for coil = 1:app.pd.maxCoils
                    if ((strcmp(app.KxLabel.Text,'X') && strcmp(app.KyLabel.Text,'Y')) || ...
                            (strcmp(app.KxLabel.Text,'Kx') && strcmp(app.KyLabel.Text,'Ky'))) ...
                            && app.pd.PHASE_ORIENTATION
                        im0 = permute(im,[2,3,1]);
                        ims{coil} = rot90(im0(:,:,coil),-1); %#ok<AGROW>
                    else
                        im0 = permute(im,[2,3,1]);
                        ims{coil} = im0(:,:,coil); %#ok<AGROW>
                    end
                end

                % AspectRatio
                recoAspectRatio = app.CalcImageAspectRatioFnc(app.KxLabel.Text,app.KyLabel.Text,size(ims{1},1),size(ims{1},2));

                % Window and level
                for coil = 1:app.pd.nrCoils
                    mx = max(ims{coil}(:));
                    wdw = 0.9*mx;
                    lvl = 0.6*mx;
                    ims{coil} = (1./lvl)*(ims{coil} - lvl + wdw./2);
                end

                % X and Y sizes
                sizeXY = [0 size(ims{1},1)+1 0 size(ims{1},2)+1];

                % Put them in the figures
                cla(app.SenseFig1);
                imshow(rot90(ims{1}),[0 1],'ColorMap',cmap,'InitialMagnification','fit','Parent',app.SenseFig1);
                daspect(app.SenseFig1,[1 recoAspectRatio 1]);
                axis(app.SenseFig1,sizeXY);

                cla(app.SenseFig2);
                imshow(rot90(ims{2}),[0 1],'ColorMap',cmap,'InitialMagnification','fit','Parent',app.SenseFig2);
                daspect(app.SenseFig2,[1 recoAspectRatio 1]);
                axis(app.SenseFig2,sizeXY);

                cla(app.SenseFig3);
                imshow(rot90(ims{3}),[0 1],'ColorMap',cmap,'InitialMagnification','fit','Parent',app.SenseFig3);
                daspect(app.SenseFig3,[1 recoAspectRatio 1]);
                axis(app.SenseFig3,sizeXY);

                cla(app.SenseFig4);
                imshow(rot90(ims{4}),[0 1],'ColorMap',cmap,'InitialMagnification','fit','Parent',app.SenseFig4);
                daspect(app.SenseFig4,[1 recoAspectRatio 1]);
                axis(app.SenseFig4,sizeXY);

                cla(app.SenseFig5);
                imshow(rot90(ims{5}),[0 1],'ColorMap',cmap,'InitialMagnification','fit','Parent',app.SenseFig5);
                daspect(app.SenseFig5,[1 recoAspectRatio 1]);
                axis(app.SenseFig5,sizeXY);

                cla(app.SenseFig6);
                imshow(rot90(ims{6}),[0 1],'ColorMap',cmap,'InitialMagnification','fit','Parent',app.SenseFig6);
                daspect(app.SenseFig6,[1 recoAspectRatio 1]);
                axis(app.SenseFig6,sizeXY);

                cla(app.SenseFig7);
                imshow(rot90(ims{7}),[0 1],'ColorMap',cmap,'InitialMagnification','fit','Parent',app.SenseFig7);
                daspect(app.SenseFig7,[1 recoAspectRatio 1]);
                axis(app.SenseFig7,sizeXY);

                cla(app.SenseFig8);
                imshow(rot90(ims{8}),[0 1],'ColorMap',cmap,'InitialMagnification','fit','Parent',app.SenseFig8);
                daspect(app.SenseFig8,[1 recoAspectRatio 1]);
                axis(app.SenseFig8,sizeXY);

                drawnow;

            catch ME

                app.TextMessage(ME.message);
                app.TextMessage("Something has gone wrong during plotting of sensitivity maps ...");

            end
            
        end % PlotSenseMapsFnc


        % Plot the reconstructed image
        function PlotImageFigFnc(app)

            try

                if app.pd.validReco_flag

                    % Red color map
                    cmap = [linspace(0,1,256)', linspace(0,1,256)', linspace(0,1,256)'];
                    if app.pd.validMask_flag
                        cmap(1,:) = [0.7 0 0 ]; % reddish
                    end

                    % Select the data
                    try
                        im = app.pd.images.*app.pd.mask;
                    catch
                        im = app.pd.images;
                    end

                    % Select which slice to show
                    im = permute(im,app.imageOrient(app.OrientationSpinner.Value,:));
                    im = squeeze(im(:,:,app.Dim3EditField.Value,app.Dim4EditField.Value,app.Dim5EditField.Value,app.Dim6EditField.Value));

                    % Image scale
                    if app.AutoIntensityCheckBox.Value == 1
                        maxScale = double(round(max(im(:))));
                        threshold = double(graythresh(mat2gray(im)))*maxScale;
                        imt = im;
                        imt(imt < threshold) = 0;
                        scale = mean(nonzeros(imt(:)));
                        scale(scale > maxScale) = maxScale;
                        scale(isnan(scale)) = maxScale;
                        wScale = 2*scale;
                        wScale(wScale > maxScale) = maxScale;
                        app.LevelSlider.Value = double(scale);
                        app.LevelEditField.Value = double(round(scale));
                        app.WindowSlider.Value = double(wScale);
                        app.WindowEditField.Value = double(round(wScale));
                    end
                    minScale = app.LevelEditField.Value - app.WindowEditField.Value/2;
                    maxScale = app.LevelEditField.Value + app.WindowEditField.Value/2;
                    if minScale >= maxScale
                        maxScale = minScale + 1;
                    end

                    % Image aspect ratio & dimensions
                    recoAspectRatio = app.CalcImageAspectRatioFnc(app.XLabel.Text,app.YLabel.Text,size(im,1),size(im,2));

                    % If phase orientation = 1 rotate image (for viewing purposes only)
                    if strcmp(app.XLabel.Text,'X') && strcmp(app.YLabel.Text,'Y') && app.pd.PHASE_ORIENTATION
                        im = rot90(im,-1);
                        recoAspectRatio = app.CalcImageAspectRatioFnc(app.YLabel.Text,app.XLabel.Text,size(im,1),size(im,2));
                    end

                    % Plot the image in the images panel
                    if app.newFig_flag

                        % Plot the image in the image panel
                        cla(app.RecoFig);
                        app.RecoFig.Position = [43,87,325,325];

                        % New fig with changed properties
                        app.recoImage = imshow(rot90(im),[minScale maxScale],'Colormap',cmap,'InitialMagnification','fit','Parent',app.RecoFig);

                        % Aspect ratio and dimensions
                        daspect(app.RecoFig,[1 recoAspectRatio 1]);
                        xlim(app.RecoFig,[0, size(im,1) + 1]);
                        ylim(app.RecoFig,[0, size(im,2) + 1]);

                        % Calculate the left, right, top, bottom, image orienation labels
                        app.pd = app.pd.imageOrientLabels(app);

                        % Plot the labels
                        text(app.RecoFig,0.960*size(im,1),0.520*size(im,2),app.pd.rightLabel,'Color',app.yellow,'FontSize',14);
                        text(app.RecoFig,0.015*size(im,1),0.520*size(im,2),app.pd.leftLabel,'Color',app.yellow,'FontSize',14);
                        text(app.RecoFig,0.490*size(im,1),0.970*size(im,2),app.pd.bottomLabel,'Color',app.yellow,'FontSize',14);
                        text(app.RecoFig,0.490*size(im,1),0.030*size(im,2),app.pd.topLabel,'Color',app.yellow,'FontSize',14);

                        % Set the new figure flag to false
                        app.newFig_flag = false;

                    else

                        % Only new data in the figure = fast
                        set(app.recoImage, 'CData', rot90(im));
                        set(app.recoImage, 'CDataMapping', 'scaled');

                        app.RecoFig.CLim = [minScale maxScale];
                        pause(0.002);

                    end

                    drawnow;

                end

            catch ME

                app.TextMessage(ME.message);
                app.TextMessage("Something has gone wrong during plotting of reconstructed images ...");

            end

        end % PlotImageFigFnc(app)


        % Plot the MIP
        function PlotMipFigFnc(app)

             if app.pd.validReco_flag

                % Clear the current figure
                cla(app.MIPFig);
                  
                % Select the data
                im = app.pd.images.*app.pd.mask;

                % Select which volume to show in MIP window
                im = permute(im,app.imageOrient(app.OrientationSpinner.Value,:));
                im = squeeze(im(:,:,:,app.Dim4EditField.Value,app.Dim5EditField.Value,app.Dim6EditField.Value));

                if ndims(im) == 3

                    % Downsample if selected
                    if app.MIPResizeButton.Value
                        im = imresize3(im,size(im)/2);
                    end

                    % Resize image for correct image dimensions
                    maxDim = 1.2*max(size(im));
                    imDim = app.pd.pixelSize(app.imageOrient(app.OrientationSpinner.Value,:));
                    imDim = imDim/min(imDim(1:3));
                    im = imresize3(im,[size(im,1)*imDim(1) size(im,2)*imDim(2) size(im,3)*imDim(3)]);

                    % Image aspect ratio & dimensions
                    recoAspectRatio = app.CalcMIPAspectRatioFnc(app.XLabel.Text,app.YLabel.Text,size(im,2),size(im,1),0,0);

                    % If phase orientation = 1 rotate image (for viewing purposes only)
                    if strcmp(app.XLabel.Text,'X') && strcmp(app.YLabel.Text,'Y') && app.pd.PHASE_ORIENTATION
                        recoAspectRatio = app.CalcMIPAspectRatioFnc(app.YLabel.Text,app.XLabel.Text,size(im,1),size(im,2),0,0);
                    end

                    % Phase orientation
                    if strcmp(app.XLabel.Text,'X') && strcmp(app.YLabel.Text,'Y') && app.pd.PHASE_ORIENTATION
                        im = rot90(im,-1);
                    end

                    % Rotate
                    im = imrotate3(im,app.MIPxAngleSlider.Value,[0 1 0]);
                    im = imrotate3(im,app.MIPyAngleSlider.Value,[1 0 0]);
                    
                    % MIP of 3rd dimension
                    im = max(im,[],3);

                    % Image scale
                    minScale = app.MIPLevelEditField.Value - app.MIPWindowEditField.Value/2;
                    maxScale = app.MIPLevelEditField.Value + app.MIPWindowEditField.Value/2;
                    if minScale >= maxScale
                        maxScale = minScale + 1;
                    end

                    % Plot the image in the image panel
                    app.MIPFig.Position = [43,87,325,325];
                    
                    % New fig with changed properties
                    imshow(app.blackImage,'XData',[-2*maxDim 2*maxDim],'YData',[-2*maxDim 2*maxDim],'Parent',app.MIPFig);
                    hold(app.MIPFig,'on');
                    imshow(rot90(im),[minScale maxScale],'Parent',app.MIPFig);
                    daspect(app.MIPFig,[1 recoAspectRatio 1]);
                    xlim(app.MIPFig,[(size(im,1) - maxDim)/2, (maxDim + size(im,1))/2]);
                    ylim(app.MIPFig,[(size(im,2) - maxDim)/2, (maxDim + size(im,2))/2]);
                    hold(app.MIPFig,'off');
                 
                else

                    % No image
                    emptyImage = ones(10,10);
                    imshow(emptyImage,[0 1.2],'Parent',app.MIPFig); 
                    xlim(app.MIPFig,[0, size(emptyImage,1) + 1]);
                    ylim(app.MIPFig,[0, size(emptyImage,2) + 1]);

                end

            end

        end % PlotMipFig


        % Plot the reconstructed phase image
        function PlotPhaseImageFigFnc(app)

            if app.pd.validReco_flag
        
                % Select the data
                im = app.pd.phaseImages.*app.pd.mask;

                % Select which slice to show
                im = permute(im,app.imageOrient(app.PhaseOrientationSpinner.Value,:));
                im = squeeze(im(:,:,app.PhaseDim3EditField.Value,app.PhaseDim4EditField.Value,app.PhaseDim5EditField.Value,app.PhaseDim6EditField.Value));
             
                % Image aspect ratio & dimensions
                recoAspectRatio = app.CalcImageAspectRatioFnc(app.PhaseXLabel.Text,app.PhaseYLabel.Text,size(im,1),size(im,2));

                % Scale
                scale = floor(max(abs(im(:)))/pi)+1;
                
                % If phase orientation = 1 rotate image (for viewing purposes only)
                if strcmp(app.PhaseXLabel.Text,'X') && strcmp(app.PhaseYLabel.Text,'Y') && app.pd.PHASE_ORIENTATION
                    im = rot90(im,-1);
                    recoAspectRatio = app.CalcImageAspectRatioFnc(app.PhaseYLabel.Text,app.PhaseXLabel.Text,size(im,1),size(im,2));
                end

                % Plot the image in the images panel
                if app.newPhaseFig_flag

                    % Plot the image in the image panel
                    cla(app.PhaseRecoFig);
                    app.PhaseRecoFig.Position = [43,87,325,325];

                    % New fig with changed properties
                    app.phaseRecoImage = imshow(rot90(im),[-pi*scale-0.001 pi*scale],'InitialMagnification','fit','Parent',app.PhaseRecoFig);

                    % Aspect ratio and dimensions
                    daspect(app.PhaseRecoFig,[1 recoAspectRatio 1]);
                    xlim(app.PhaseRecoFig,[0, size(im,1) + 1]);
                    ylim(app.PhaseRecoFig,[0, size(im,2) + 1]);
                    cbr = colorbar(app.PhaseRecoFig);
                    cbr.Position = [0.7 0.5 0.02 0.2];
                    cbr.TickDirection = 'out';
                    if scale == 1
                        app.maxPhaseLabel.Text = ' π';
                        app.minPhaseLabel.Text = '-π';
                    else
                        app.maxPhaseLabel.Text = [' ',num2str(scale,2),'π'];
                        app.minPhaseLabel.Text = ['-',num2str(scale,2),'π'];
                    end

                    app.newPhaseFig_flag = false;

                else

                    % Only new data in the figure = fast
                    set(app.phaseRecoImage, 'CData', rot90(im));
                    set(app.phaseRecoImage, 'CDataMapping', 'scaled');
                    pause(0.002);

                end

                drawnow;

            end

        end % PlotPhaseImageFigFnc(app)


        % Plot the reconstructed flow image
        function PlotFlowImageFigFnc(app)

            if app.pd.validFlow_flag
             
                % Select the data
                im = app.pd.flowImages(:,:,:,:,:,:,app.FlowDirectionSpinner.Value);

                % Scale
                maxsc = max(abs(im(:)));
                if app.FlowDirectionSpinner.Value == 5
                    minsc = 0;
                else
                    minsc = -maxsc;
                end
                
                % Select which slice to show
                im = permute(im,app.imageOrient(app.FlowOrientationSpinner.Value,:));
                im = squeeze(im(:,:,app.FlowDim3EditField.Value,app.FlowDim4EditField.Value,app.FlowDim5EditField.Value,app.FlowDim6EditField.Value));
             
                % Image aspect ratio & dimensions
                recoAspectRatio = app.CalcImageAspectRatioFnc(app.FlowXLabel.Text,app.FlowYLabel.Text,size(im,1),size(im,2));

                % If phase orientation = 1 rotate image (for viewing purposes only)
                if strcmp(app.FlowXLabel.Text,'X') && strcmp(app.FlowYLabel.Text,'Y') && app.pd.PHASE_ORIENTATION
                    im = rot90(im,-1);
                    recoAspectRatio = app.CalcImageAspectRatioFnc(app.FlowYLabel.Text,app.FlowXLabel.Text,size(im,1),size(im,2));
                end

                % Plot the image in the images panel
                if app.newFlowFig_flag 

                    % Plot the image in the image panel
                    cla(app.FlowRecoFig);
                    app.FlowRecoFig.Position = [43,87,325,325];

                    % New fig with changed properties
                    app.flowRecoImage = imshow(rot90(im),[minsc-0.001 maxsc],'InitialMagnification','fit','Parent',app.FlowRecoFig);

                    % Aspect ratio and dimensions
                    daspect(app.FlowRecoFig,[1 recoAspectRatio 1]);
                    xlim(app.FlowRecoFig,[0, size(im,1) + 1]);
                    ylim(app.FlowRecoFig,[0, size(im,2) + 1]);
                    cbr = colorbar(app.FlowRecoFig);
                    cbr.Position = [0.7 0.5 0.02 0.2];
                    cbr.TickDirection = 'out';
                    app.maxLabel.Text = num2str(maxsc,3);
                    app.minLabel.Text = num2str(minsc,3);
                 
                    app.newFlowFig_flag = false;

                else

                    % Only new data in the figure = fast
                    set(app.flowRecoImage, 'CData', rot90(im));
                    set(app.flowRecoImage, 'CDataMapping', 'scaled');
                  
                    pause(0.002);

                end

                drawnow;

            end

        end % PlotFlowImageFigFnc(app)


    end


    % --------------------------------------------------------------
    %
    %                       PUBLIC FUNCTIONS
    %
    % --------------------------------------------------------------

    methods (Access = public)


        % Text messages in the app
        function TextMessage(app, message)

            try
                app.MessageBox.Value = [message ; app.MessageBox.Value];  % Add new message to top
                app.MessageBox.Value = app.MessageBox.Value(1:50,1);      % Limit history to 50 messages
                drawnow;
            catch
            end

        end % TextMessage


        % Status icon
        function SetStatus(app,status)

            switch status

                case 0
                    app.StatusImage.ImageSource = 'statusokay.gif';
                    app.statusLabel.Text = 'OK';
                    app.statusLabel.FontColor = app.green;

                case 1
                    app.StatusImage.ImageSource = 'statuswarning.gif';
                    app.statusLabel.Text = 'WARNING';
                    sound(app.warningSound,48000);
                    app.statusLabel.FontColor = app.orange;

                case 2
                    app.StatusImage.ImageSource = 'statuserror.gif';
                    app.statusLabel.Text = 'ERROR';
                    sound(app.errorSound,48000);
                    app.statusLabel.FontColor = app.brightred;

                case 3
                    app.StatusImage.ImageSource = 'statuswait.gif';
                    app.statusLabel.Text = 'BUSY';
                    app.statusLabel.FontColor = 'black';

            end

            drawnow;

        end

    end


    % --------------------------------------------------------------
    %
    %                   USER INTERFACE CODE
    %
    % --------------------------------------------------------------


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Suppress unneccesary warnings
            warning off;
            clc;

            % Add subfolders to search path
            if (~isdeployed)
                app.oldDir = pwd;
                info = dir(which('P2ROUD.mlapp'));
                app.appDir = info.folder;
                cd(app.appDir);
                addpath(genpath(pwd));
                rmpath("P2ROUD/");
                rmpath("P2ROUD_resources/");
            end

            % Trick to automatically include the dll file during compilation
            try
                if ispc
                    import('libfftw3-3.dll');
                end
            catch
            end

            % Create empty objects of classes
            app.pd = proudData();

            % Set the app layout
            app.SetAppLayoutFncFnc;
            app.SetStatus(0);
            app.SwitchOffAllButtonsFnc;
            app.CreditsLabel.Text = strcat("Gustav Strijkers, Bram Coolen, Amsterdam UMC, Version ",app.appVersion);

            % Empty file import and export paths
            app.mrdImportPath = '';
            app.mrd_flag = true;
            app.dicomExportPath = '';

            % Reset flags
            app.dicom_flag = true;
            app.stopMovie_flag = true;
            app.stopPhaseMovie_flag = true;
            app.stopFlowMovie_flag = true;
            app.stopMIPxMovie_flag = true;
            app.stopMIPyMovie_flag = true;
            app.newFig_flag = true;
            app.newPhaseFig_flag = true;
            app.newFlowFig_flag = true;
            app.forceProud_flag = false;
            app.scrollEnable_flag = false;

            % Files to ignore during import
            app.ignoreFiles = ["cardtrigpoints" "resptrigpoints" "respwindow" "heartrate" "resprate" "recoparameters" "recoparmeters"]; 

            % Add the bart path to the environment variable if available
            try
                if ismac()
                    [~,result] = system('uname -v');
                    is_m1_mac = any(strfind(result,'ARM64'));
                    if is_m1_mac
                        app.TextMessage('Apple arm64 version');
                    else
                        app.TextMessage('Apple Intel version');
                    end
                else
                    app.TextMessage('PC Intel version');
                end
            catch 
            end

            try
                bart(app,'version');
                app.bartDetected_flag = true;
            catch ME
                app.bartDetected_flag = false;
                app.TextMessage(ME.message);
            end

            if app.bartDetected_flag
                app.TextMessage('BART toolbox detected ...');
            else
                app.TextMessage('WARNING: BART toolbox not available ...');
                app.SetStatus(1);
            end

            try
                delays = load('graddelays.txt');
                app.GxDelayEditField.Value = delays(1);
                app.GyDelayEditField.Value = delays(2);
                app.GzDelayEditField.Value = delays(3);
                app.DataOffsetRadialEditField.Value = delays(4);
                app.TextMessage('Gradient delays file found ...');
            catch
                app.TextMessage('Gradient delays file not found or invalid gradient file ...');
            end

            % Determine the current directory in case of deployed app
            if isdeployed
                try
                    [~,result] = system('path');
                    app.currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
                    app.TextMessage(strcat("Path: ",app.currentDir));
                catch
                    app.currentDir = '';
                end
            end

            % Initialize buttons
            app.SetLightsFnc({'Red','Red','Red','Red','Red','Red'});
            app.SetButtonsFnc({'On','Off','Off','Off','Off','Off','On'});
            app.SetRecoFieldsFnc;

            % Some mysterious Matlab bug is sometimes opening a figure
            % If that happens, close the figure
            try
                close(1);
            catch
            end

        end

        % Button pushed function: LoadMRDButton
        function LoadMRDButtonPushed(app, event)

            % Set buttons
            app.SwitchOffAllButtonsFnc;
            app.SetLightsFnc({'Yellow','Red','Red','Red','Red','Red'});
            app.SetStatus(0);

            % Reset object of class proudData
            clear app.proudDataPars;
            app.pd = proudData();

            % Check standard smis directory first if platform is pc, only 1x per session
            if ispc

                try
                    folderName = winqueryreg('HKEY_LOCAL_MACHINE','Software\MRS\MRSClinicalUI\Scan','MrdDirectory');
                    folderName = [folderName,filesep];
                catch ME
                    folderName = 'c:\smis\dev\MRD\';
                    app.TextMessage(ME.message);
                end

                if exist(folderName, 'dir') && app.mrd_flag
                    app.mrdImportPath = folderName;
                    app.mrd_flag = false;
                end

            end

            % Check mrdpath.txt file next, only 1x per session
            if isfile('mrdpath.txt') && app.mrd_flag

                try
                    folderName = fileread('mrdpath.txt');
                    if exist(folderName, 'dir')
                        app.mrdImportPath = folderName;
                        app.mrd_flag = false;
                    end
                catch ME
                    app.TextMessage(ME.message);
                    app.mrdImportPath = '';
                    app.mrd_flag = false;
                end

            end

            % Bart total generalized variation (G) or total variation (T)
            app.pd.totalVariation = 'T';
            if app.bartDetected_flag
                try
                    picsHelp = bart(app,'bart pics -Rh');
                    if contains(picsHelp,"total generalized variation")
                        app.pd.totalVariation = 'G';
                    end
                catch
                end
            end

            % Select 1 MRD file
            app.P2ROUD_UIFigure.Visible = 'off';
            [mrdFile,app.mrdImportPath] = uigetfile(strcat(app.mrdImportPath,filesep,'*.mrd'),'Select MR Solutions data file');
            app.P2ROUD_UIFigure.Visible = 'on';

            % Initial settings
            app.stopMovie_flag = true;
            app.stopKspaceMovie_flag = true;
            app.newFig_flag = true;
            app.newPhaseFig_flag = true;
            app.newFlowFig_flag = true;
            app.CSRecoCheckBox.Value = 0;
            app.DeNoiseCheckBox.Value = 0;
            app.DeNoiseWindowEditField.Value = 8;
            app.DeNoiseLabel.Enable = true;
            app.DeNoiseCheckBox.Enable = 'on';
            app.DeNoiseWindowEditField.Enable = 'on';
            app.DeRingLabel.Enable = true;
            app.DeRingCheckBox.Value = 0;
            app.DeRingCheckBox.Enable = 'on';
            app.VencLamp.Color = app.red;
            app.CalcFlowButton.Enable = 'off';
            app.MIPcounter = 0;
            app.kvaluesButton.Value = 1;

            if ischar(mrdFile)

                % Indicate busy
                app.SetStatus(3);

                try

                        % MRD file list, check for multiple file names with the same name = multicoil
                        app.MRDfileViewField.Value = mrdFile;
                        k = strfind(mrdFile,'_');
                        if length(k)>1
                            mrdStart = extractBefore(mrdFile,k(end-1));
                        else
                            mrdStart = extractBefore(mrdFile,k(end));
                        end
                        mrdFile = [app.mrdImportPath,mrdFile];
                        fList = dir(fullfile(app.mrdImportPath,[mrdStart,'*.MRD']));

                        % Set the number of coils
                        app.pd = app.pd.setNumberOfCoils(app,fList);
                        app.ResetCoilButtonsFnc;

                        % Load the data
                        app.TextMessage('Reading MRD file(s) ...');
                        app.pd = app.pd.readProudData(app, mrdFile, fList);

                        % Filename tag
                        [~,app.tag,~] = fileparts([fList(1).folder, filesep, fList(1).name]);

                        % Read the full mrd-file footer
                        app.pd = app.pd.readMrdFooter(fullfile(fList(1).folder, fList(1).name));

                        % Read the rpr file
                        rprFilename = [fList(1).folder, filesep, app.tag, '.rpr'];
                        app.pd = app.pd.readRprFile(app, rprFilename);

                        % Read the SQL file if present
                        sqlFilename = [];
                        sqlFilenameList = dir(fullfile([fList(1).folder, filesep, '*.SQL']));
                        if ~isempty(sqlFilenameList)
                            sqlFilename = [sqlFilenameList(1).folder, filesep, sqlFilenameList(1).name];
                        end
                        app.pd = app.pd.readSQLfile(app, sqlFilename);

                catch ME

                    app.TextMessage('ERROR: during file loading ...');
                    app.TextMessage(ME.message);
                    app.SetStatus(2);

                end

            end

            % Report on valid/invalid file selection
            if app.pd.validFile_flag

                % Set other parameters
                try

                    app.SetParametersFcnFnc;
                    app.SetKspaceLimitsFcn;

                    app.FillKspaceFcn;
                    app.PlotKspaceFigFnc;
                  
                    app.TextMessage('Data import ready ...');
                    app.TextMessage(strcat('Size of k-space =',{' '},num2str(size(app.pd.rawKspace{1}))));

                    % Set buttons
                    app.SetLightsFnc({'Green','Red','Red','Red','Red','Red'});
                    app.SetButtonsFnc({'On','On','Null','Null','Null','Null','On'});
                    app.SetStatus(0);

                catch ME

                    app.TextMessage(ME.message);
                    app.pd.validFile_flag = false;
                    app.SetStatus(2);

                    % Set the buttons for invalid data file
                    app.SetLightsFnc({'Red','Red','Red','Red','Red','Red'});
                    app.SetButtonsFnc({'On','Off','Off','Off','Off','Off','On'});

                end

            else

                % Not a valid data file
                app.TextMessage('ERROR: Please select a valid data file');
                app.SetStatus(2);

                % Set the buttons for invalid data file
                app.SetLightsFnc({'Red','Red','Red','Red','Red','Red'});
                app.SetButtonsFnc({'On','Off','Off','Off','Off','Off','On'});

            end

        end

        % Value changed function: KFlipAngleEditField
        function KFlipAngleEditFieldValueChanged(app, event)

            if app.stopKspaceMovie_flag
                app.KFlipAngleSlider.Value = app.KFlipAngleEditField.Value;
                app.PlotKspaceFigFnc;
            end

        end

        % Value changed function: KFlipAngleSlider
        function KFlipAngleSliderValueChanged(app, event)

            if app.stopKspaceMovie_flag
                app.KFlipAngleEditField.Value = round(app.KFlipAngleSlider.Value);
                app.PlotKspaceFigFnc;
            end

        end

        % Value changed function: KDim4EditField
        function KDim4EditFieldValueChanged(app, event)

            if app.stopKspaceMovie_flag
                app.KDim4Slider.Value = app.KDim4EditField.Value;
                app.PlotKspaceFigFnc;
            end

        end

        % Value changed function: KDim4Slider
        function KDim4SliderValueChanged(app, event)

            if app.stopKspaceMovie_flag
                app.KDim4EditField.Value = round(app.KDim4Slider.Value);
                app.PlotKspaceFigFnc;
            end

        end

        % Value changed function: KEchoEditField
        function KEchoEditFieldValueChanged(app, event)

            if app.stopKspaceMovie_flag
                app.KEchoSlider.Value = app.KEchoEditField.Value;
                app.PlotKspaceFigFnc;
            end

        end

        % Value changed function: KEchoSlider
        function KEchoSliderValueChanged(app, event)

            if app.stopKspaceMovie_flag
                app.KEchoEditField.Value = round(app.KEchoSlider.Value);
                app.PlotKspaceFigFnc;
            end

        end

        % Value changed function: KDim3EditField
        function KDim3EditFieldValueChanged(app, event)

            if app.stopKspaceMovie_flag
                app.KDim3Slider.Value = app.KDim3EditField.Value;
                app.PlotKspaceFigFnc;
            end

        end

        % Value changed function: KDim3Slider
        function KDim3SliderValueChanged(app, event)

            if app.stopKspaceMovie_flag
                app.KDim3EditField.Value = round(app.KDim3Slider.Value);
                app.PlotKspaceFigFnc;
            end

        end

        % Value changed function: KOrientationSpinner
        function KOrientationSpinnerValueChanged(app, event)

            if app.stopKspaceMovie_flag
                app.SetKspaceLimitsFcn;
                app.SetKspaceWLFnc;
                app.PlotKspaceFigFnc;
            end

        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)

            if app.stopKspaceMovie_flag
                app.SetKspaceLimitsFcn;
                app.SetKspaceWLFnc;
                app.PlotKspaceFigFnc;
            end

        end

        % Value changed function: CoilSpinner
        function CoilSpinnerValueChanged(app, event)

            if app.stopKspaceMovie_flag
                app.SetKspaceWLFnc;
                app.PlotKspaceFigFnc;
            end

        end

        % Value changed function: C1CheckBox, C2CheckBox, C3CheckBox, 
        % ...and 5 other components
        function CoilCheckBoxValueChanged(app, event)

            app.pd.coilActive_flag = [app.C1CheckBox.Value app.C2CheckBox.Value app.C3CheckBox.Value app.C4CheckBox.Value ...
                app.C5CheckBox.Value app.C6CheckBox.Value app.C7CheckBox.Value app.C8CheckBox.Value];

            if nnz(app.pd.coilActive_flag) == 0
                app.C1CheckBox.Value = 1;
                app.pd.coilActive_flag(1) = 1;
            end

            if nnz(app.pd.coilActive_flag) == 1
                app.AutoSensitivityCheckBox.Value = 0;
                app.AutoSensitivityCheckBoxValueChanged;
            end

        end

        % Value changed function: S1EditField, S2EditField, S3EditField, 
        % ...and 5 other components
        function SensitivityEditFieldValueChanged(app, event)

            app.pd.coilSensitivities = [app.S1EditField.Value app.S2EditField.Value app.S3EditField.Value app.S4EditField.Value ...
                app.S5EditField.Value app.S6EditField.Value app.S7EditField.Value app.S8EditField.Value];

        end

        % Value changed function: AutoSensitivityCheckBox
        function AutoSensitivityCheckBoxValueChanged(app, event)

            if nnz(app.pd.coilActive_flag) == 1
                app.AutoSensitivityCheckBox.Value = 0;
            end

            app.EnableCoilButtonsFnc;

        end

        % Button pushed function: AbortGradCalButton
        function AbortGradCalButtonPushed(app, event)
            
            app.stopGradCal_flag = true;
            drawnow;

        end

        % Button pushed function: KStartButton
        function KStartButtonPushed(app, event)

            if app.pd.validTrajectory_flag

                % Indicate busy
                app.SetStatus(3);
                app.stopKspaceMovie_flag = false;
                app.DisableKbuttonsFnc;
                app.KStopButton.Enable = 'on';
                app.KStartButton.Enable = 'off';

                % Set the image dimensions
                im = abs(app.pd.rawKspace{app.CoilSpinner.Value});
                app.KspaceFig.Position = [34,87,325,325];
                im = permute(im,app.imageOrient(app.KOrientationSpinner.Value,:));
                im = squeeze(im(:,:,app.KDim3EditField.Value,app.KDim4EditField.Value,app.KFlipAngleEditField.Value,app.KEchoEditField.Value));
                recoAspectRatio = app.CalcKAspectRatioFnc(app.KxLabel.Text,app.KyLabel.Text);
                daspect(app.KspaceFig,[recoAspectRatio 1 1]);
                sizex = size(im,1);
                sizey = size(im,2);

                hold(app.KspaceFig,'on');
                delete(app.kmovieFig);

                % Get plot size in pixel units
                originalUnits = app.KspaceFig.Units;
                axh.Units = 'Points';
                axSizePix = app.KspaceFig.Position(3:4);
                axh.Units = originalUnits; %#ok<STRNU> 
                pixPerUnit = axSizePix ./ [range(1:sizex),range(1:sizey)];  %pixels per unit

                % Initialize scatter plot
                app.kmovieFig = scatter(app.KspaceFig,1,1,ceil(median(pixPerUnit))^2,"yellow",'filled','s','MarkerFaceAlpha',0.5);
                xlim(app.KspaceFig,[0, sizex+1]);
                ylim(app.KspaceFig,[0, sizey+1]);
                hold(app.KspaceFig,'off');

                % K-space list that will be displayed
                pnts = app.pd.seqTrajectory(:,app.imageOrient(app.KOrientationSpinner.Value,:));
                pnts = pnts(pnts(:,3) == app.KDim3EditField.Value,:);
                pnts = pnts(pnts(:,4) == app.KDim4EditField.Value,:);

                % Accelerate viewing by stepping faster through the list
                stepSize = round(length(pnts)/256);
                stepSize(stepSize < 1) = 1;

                try

                    % Loop through the list and plot the k-space points
                    for cnt = 1:stepSize:length(pnts)

                        app.kmovieFig.XData = pnts(1:cnt,1);
                        app.kmovieFig.YData = sizey + 1 - pnts(1:cnt,2);

                        if app.FastSlowSwitch.Value == "slow"
                            drawnow;
                            pause(0.002);
                        else
                            drawnow limitrate;
                        end

                        if app.stopKspaceMovie_flag
                            break;
                        end

                    end

                    % Finish the plot by showing all points, with a max of 32768
                    stepSize = round(length(pnts)/65536);
                    stepSize(stepSize < 1) = 1;
                    app.kmovieFig.XData = pnts(1:stepSize:end,1);
                    app.kmovieFig.YData = sizey + 1 - pnts(1:stepSize:end,2);


                catch
                end

            else

                app.stopKspaceMovie_flag = true;
                app.TextMessage('K-space trajectory movie not possible for this type of data ...');

            end

            % Reset start/stop buttons
            app.stopKspaceMovie_flag = true;
            app.KStopButton.Enable = 'off';
            app.KStartButton.Enable = 'on';
            drawnow;

            % Set status ok
            app.SetStatus(0);
            app.EnableKbuttonsFnc;

        end

        % Button pushed function: KStopButton
        function KStopButtonPushed(app, event)

            app.stopKspaceMovie_flag = true;
            app.KStopButton.Enable = 'off';
            drawnow;

        end

        % Value changed function: FastSlowSwitch
        function FastSlowSwitchValueChanged(app, event)

            drawnow;

        end

        % Value changed function: KLevelEditField
        function KLevelEditFieldValueChanged(app, event)

            if app.pd.validFile_flag

                app.KLevelSlider.Value = app.KLevelEditField.Value;
                app.PlotKspaceFigFnc;

            end

        end

        % Value changed function: KWindowEditField
        function KWindowEditFieldValueChanged(app, event)

            if app.pd.validFile_flag

                app.KWindowSlider.Value = app.KWindowEditField.Value;
                app.PlotKspaceFigFnc;

            end

        end

        % Value changed function: KLevelSlider
        function KLevelSliderValueChanged(app, event)

            if app.pd.validFile_flag

                app.KLevelEditField.Value = round(app.KLevelSlider.Value);
                app.PlotKspaceFigFnc;

            end

        end

        % Value changed function: KWindowSlider
        function KWindowSliderValueChanged(app, event)

            if app.pd.validFile_flag

                app.KWindowEditField.Value = round(app.KWindowSlider.Value);
                app.PlotKspaceFigFnc;

            end

        end

        % Value changed function: NREditField
        function NREditFieldValueChanged(app, event)

            % Set buttons
            app.SetLightsFnc({'Yellow','Null','Null','Null','Null','Null'});
            app.SwitchOffAllButtonsFnc;
            app.SetStatus(3);

            % Stop the movies, if running
            app.stopMovie_flag = true;
            app.stopKspaceMovie_flag = true;
            drawnow;

            % Reset navigator samples if present
            if app.pd.slice_nav == 1
                app.pd.NO_SAMPLES = app.pd.NO_SAMPLES + app.pd.no_samples_discard + app.pd.no_samples_nav;
                app.XEditField.Value = app.pd.NO_SAMPLES;
                app.KMatrixViewField1.Value = app.pd.NO_SAMPLES;
            end

            % Re-sort the data in the new desired number of frames
            switch app.pd.trajType

                case "P2ROUD"
                    app.pd = app.pd.sort3DProudKspaceMRD(app);

                case "mul_kspace"

                    switch app.pd.dataType

                        case "2D"
                            app.pd = app.pd.sort2DKspaceMRD(app);

                        case "3D"
                            app.pd = app.pd.sort3DKspaceMRD(app);

                    end

            end

            % Chop of the navigator if present
            app.pd = app.pd.chopNav(app);
        
            % K-space filling percentage, without cropping, dynamic interpolation, or zero-filling
            app.FillingViewField.Value = round(100*nnz(app.pd.fillingSpace)/numel(app.pd.fillingSpace));

            % Apply Tukey k-space filter
            app.TextMessage('Applying Tukey k-space filter ...');
            app.pd = app.pd.applyTukey;

            % Total scan time and scan time / dynamic
            app.CalcTotalAcquisitionTimeFcn;

            % Set k-space dimensions and plot the k-space
            app.SetKspaceLimitsFcn;
            app.SetKspaceWLFnc;
            app.PlotKspaceFigFnc;

            % Set buttons
            app.SetLightsFnc({'Green','Red','Red','Red','Red','Red'});
            app.SetButtonsFnc({'On','On','Off','Off','Off','Off','On'});
            app.SetStatus(0);
            app.RecoProgressGauge.Value = 0;
            app.ExportProgressGauge.Value = 0;
            drawnow;

        end

        % Value changed function: ZEditField
        function RecoSizeFieldValueChanged(app, event)

            app.stopMovie_flag = true;
            drawnow;

            % Set buttons
            app.SetLightsFnc({'Null','Red','Red','Red','Red','Red'});
            app.SetButtonsFnc({'On','On','Off','Off','Off','Off','On'});
            app.RecoProgressGauge.Value = 0;
            app.ExportProgressGauge.Value = 0;
            drawnow;

        end

        % Value changed function: CSRecoCheckBox
        function CSRecoCheckBoxValueChanged(app, event)

            app.SetRecoFieldsFnc;

        end

        % Button pushed function: RecoimagesButton
        function RecoImagesButtonPushed(app, event)

            if app.pd.validFile_flag

                % Set buttons
                app.SwitchOffAllButtonsFnc;
                app.SetLightsFnc({'Null','Yellow','Null','Null','Null','Null'});
                app.DisableCoilButtonsFnc;
                app.DisableRecoButtonsFnc;
                app.DisableImageInteractionFnc;
                app.CalcFlowButton.Enable = 'off';
                app.VencLamp.Color = app.red;

                % Indicate busy
                app.SetStatus(3);

                % Reset some buttons, stop the movies
                app.stopReco_flag = false;
                app.pd.validReco_flag = false;
                app.validNIFTIdata_flag = false;
                app.validMovie_flag = false;
                app.stopMovie_flag = true;
                app.stopKspaceMovie_flag = true;
                app.newFig_flag = true;
                app.newPhaseFig_flag = true;
                app.newFlowFig_flag = true;
                app.AbortButton.Enable = 'on';
                app.RecoProgressGauge.Value = 0;
                app.pd.validMask_flag = false;
                drawnow;

                app.TextMessage('Reconstructing the data ...');

                try

                    % Images = (X, Y, Z, NR, NFA, NE)
                    app.pd.images = [];
                    app.pd.complexImages = [];
                    app.pd.phaseImages = [];
                    app.pd.phaseImagesOrig = [];
                    app.pd.mask = [];
                    app.pd.sensitivityMaps = {};

                    % Intitate progress gauage
                    progressCounter = 0;
                    totalCounter = app.NFAViewField.Value*app.NEViewField.Value;

                    % Loop over flip-angles
                    faCounter = 0;
                    while (faCounter<app.NFAViewField.Value) && (~app.stopReco_flag)

                        faCounter = faCounter + 1;

                        if (app.pd.frame_loop_on == 1) && app.bartDetected_flag && (app.CSRecoCheckBox.Value == 1) 

                            % CINE data, for which TE-dimension is the CINE loop
                            switch app.pd.dataType

                                case {"2D","2Depi"}
                                    app.TextMessage('2D CINE compressed-sensing reconstruction ...');
                                    app.pd = app.pd.csReco2DCine(app,faCounter);
                                    app.pd.validReco_flag = true;
                               
                                case "3D"
                                    app.TextMessage('ERROR: Reconstruction of 3D CINE reco not implemented yet ...');
                                    app.pd.validReco_flag = false;

                                case "2Dradial"
                                    app.TextMessage('ERROR: Reconstruction of 2D Radial CINE not implemented yet ...');
                                    app.pd.validReco_flag = false;
                            end

                        else

                            % Single- or multi-echo data

                            % Loop over echo-times
                            teCounter = 0;
                            while (teCounter<app.NEViewField.Value) && (~app.stopReco_flag)

                                teCounter = teCounter + 1;

                                switch app.pd.dataType

                                    case {"2D","2Depi"}

                                        % ------------------------------
                                        %
                                        % 2D cartesian reco
                                        %
                                        % ------------------------------

                                        % Report on progress
                                        app.TextMessage(strcat('Reconstructing flip-angle #',num2str(faCounter),', echo #',num2str(teCounter),' ...'));
                                        app.RecoProgressGauge.Value = round(100*progressCounter/totalCounter);
                                        progressCounter = progressCounter + 1;
                                        drawnow;

                                        if app.CSRecoCheckBox.Value == 1

                                            % CS reconstruction
                                            app.pd = app.pd.csReco2D(app,faCounter,teCounter);

                                        else

                                            % FFT reconstruction
                                            app.pd = app.pd.fftReco2D(app,faCounter,teCounter);

                                        end


                                    case "3D"

                                        % ------------------------------
                                        %
                                        % 3D cartesian reco
                                        %
                                        % ------------------------------

                                        % Report on progress
                                        app.TextMessage(strcat('Reconstructing flip-angle #',num2str(faCounter),', echo #',num2str(teCounter),' ...'));
                                        app.RecoProgressGauge.Value = round(100*progressCounter/totalCounter);
                                        progressCounter = progressCounter + 1;
                                        drawnow;

                                        if app.CSRecoCheckBox.Value == 1

                                            % CS reconstruction
                                            app.pd = app.pd.csReco3D(app,faCounter,teCounter);

                                        else

                                            % FFT reconstruction
                                            app.pd = app.pd.fftReco3D(app,faCounter,teCounter);

                                        end

                                        % FOV shift for P2ROUD (exLUT) data
                                        if strcmp(app.pd.trajType,"P2ROUD")
                                            app.pd = app.pd.shiftImages3D(app);
                                        end


                                    case "2Dradial"

                                        % ------------------------------
                                        %
                                        % 2D radial reconstruction
                                        %
                                        % ------------------------------

                                        % Report progress
                                        app.TextMessage(strcat('Reconstructing flip-angle #',num2str(faCounter),', echo #',num2str(teCounter),' ...'));
                                        app.RecoProgressGauge.Value = round(100*progressCounter/totalCounter);
                                        progressCounter = progressCounter + 1;
                                        drawnow;

                                        if app.CSRecoCheckBox.Value == 1 && app.bartDetected_flag

                                            % 2D Radial reconstruction with BART
                                            app.pd = app.pd.Reco2DRadialCS(app,faCounter,teCounter);

                                        else

                                            % 2D Radial reconstruction with NUFFT in Matlab
                                            app.pd = app.pd.Reco2DRadialNUFFT(app,faCounter,teCounter);

                                        end

                                        % 2D FOV shift
                                        app.pd = app.pd.shiftImages2D(app);


                                    case "3Dute"

                                        % ------------------------------
                                        %
                                        % 3D UTE reco
                                        %
                                        % ------------------------------

                                        % Report on progress
                                        app.TextMessage(strcat('Reconstructing flip-angle #',num2str(faCounter),', echo #',num2str(teCounter),' ...'));
                                        app.RecoProgressGauge.Value = round(100*progressCounter/totalCounter);
                                        progressCounter = progressCounter + 1;
                                        drawnow;

                                        if app.CSRecoCheckBox.Value == 1 && app.bartDetected_flag

                                            % CS BART reconstruction
                                            app.pd = app.pd.Reco3DuteCS(app,faCounter,teCounter);

                                        else

                                            % NUFFT reconstruction in Matlab
                                            app.pd = app.pd.Reco3DuteNUFFT(app,faCounter,teCounter);

                                        end

                                        % FOV shift for UTE
                                        app.pd = app.pd.shiftImages3D(app);
                                    
                                end

                            end

                        end

                    end
               

                    app.RecoProgressGauge.Value = 100;
                    drawnow;

                    % So far successful reco
                    app.pd.validReco_flag = true;

                    % Readout oversampling
                    if app.pd.oversample == 1
                        oversample = round(app.XEditField.Value/4);
                        app.pd.images = app.pd.images(oversample+1:end-oversample,:,:,:,:,:);
                        app.pd.phaseImages = app.pd.phaseImages(oversample+1:end-oversample,:,:,:,:,:);
                    end

                    % Gibbs ringing suppression
                    if app.DeRingCheckBox.Value
                        app.pd = app.pd.unRing(app);
                    end

                    % Denoising
                    if app.DeNoiseCheckBox.Value
                        app.pd = app.pd.PCAdenoise(app);
                    end

                    % Scale images to convenient range
                    app.pd = app.pd.scaleImages;

                catch ME

                    app.TextMessage(ME.message);
                    app.pd.validReco_flag = false;
                
                end

            end

            if app.pd.validReco_flag

                % Nifti check
                app.validNIFTIdata_flag = false;
                if size(app.pd.images,3)>1
                    app.validNIFTIdata_flag = true;
                end

                % Mask
                app.pd.mask = ones(size(app.pd.images));

                % Calculate pixel size 
                app.pd = app.pd.calcPixelSize(app);

                % Replot k-space too
                app.SetKspaceLimitsFcn;
                app.SetKspaceWLFnc;
                app.PlotKspaceFigFnc;

                % Show the images
                if size(app.pd.images,4) > 1
                    app.validMovie_flag = true;
                end
                app.SetImageLimitsFcn;
                app.SetImageWLFnc;
                app.SetMIPWLFnc;
                app.PlotImageFigFnc;
                app.PlotPhaseImageFigFnc;
                app.PlotMipFigFnc;
                app.TextMessage(strcat('Size of image =',{' '},num2str(size(app.pd.images))));

                if app.stopReco_flag
                    app.SetStatus(1);
                    app.SetLightsFnc({'Null','Green','Red','Red','Red','Red'});
                    app.TextMessage('WARNING: Reconstruction interrupted ...');
                else
                    app.SetStatus(0);
                    app.SetLightsFnc({'Null','Green','Red','Red','Red','Red'});
                end

                % Set buttons
                app.SetButtonsFnc({'On','On','On','On','On','On','On'});
                app.EnableCoilButtonsFnc;
                app.EnableRecoButtonsFnc;
                app.ExportProgressGauge.Value = 0;
                app.SetStatus(0);

                % Start the movie when CINE acquisition
                if (app.pd.frame_loop_on == 1)
                    app.OrientationSpinner.Value = 10;
                    app.OrientationSpinnerValueChanged;
                    app.MovieButtonPushed;
                    drawnow;
                end

            else

                % Set buttons
                app.SetButtonsFnc({'On','On','Off','Off','Off','Off','On'});
                app.SetLightsFnc({'Null','Red','Red','Red','Red','Red'});
                app.EnableCoilButtonsFnc;
                app.EnableRecoButtonsFnc;
                app.TextMessage('ERROR: Something went wrong');
                app.SetStatus(2);

            end

        end

        % Button pushed function: AbortButton
        function AbortButtonPushed(app, event)

            app.stopReco_flag = true;
            app.AbortButton.Enable = 'off';
            app.TextMessage('Reco will be aborted ...');
            app.TextMessage('Please wait ...');
            drawnow;

        end

        % Value changed function: Dim3EditField
        function Dim3EditFieldValueChanged(app, event)

            app.Dim3Slider.Value = app.Dim3EditField.Value;
            app.PlotImageFigFnc;
      
        end

        % Value changed function: Dim3Slider
        function Dim3SliderValueChanged(app, event)

            app.Dim3EditField.Value = round(app.Dim3Slider.Value);
            app.PlotImageFigFnc;

        end

        % Value changed function: Dim4EditField
        function Dim4EditFieldValueChanged(app, event)

            app.Dim4Slider.Value = app.Dim4EditField.Value;
            app.PlotImageFigFnc;
            app.SetMIPWLFnc;
            app.PlotMipFigFnc;

        end

        % Value changed function: Dim4Slider
        function Dim4SliderValueChanged(app, event)

            app.Dim4EditField.Value = round(app.Dim4Slider.Value);
            app.PlotImageFigFnc;
            app.SetMIPWLFnc;
            app.PlotMipFigFnc;

        end

        % Value changed function: Dim5EditField
        function Dim5EditFieldValueChanged(app, event)

            app.Dim5Slider.Value = app.Dim5EditField.Value;
            app.PlotImageFigFnc;
            app.SetMIPWLFnc;
            app.PlotMipFigFnc;

        end

        % Value changed function: Dim5Slider
        function Dim5SliderValueChanged(app, event)

            app.Dim5EditField.Value = round(app.Dim5Slider.Value);
            app.PlotImageFigFnc;
            app.SetMIPWLFnc;
            app.PlotMipFigFnc;

        end

        % Value changed function: Dim6EditField
        function Dim6EditFieldValueChanged(app, event)

            app.Dim6Slider.Value = app.Dim6EditField.Value;
            app.PlotImageFigFnc;
            app.SetMIPWLFnc;
            app.PlotMipFigFnc;

        end

        % Value changed function: Dim6Slider
        function Dim6SliderValueChanged(app, event)

            app.Dim6EditField.Value = round(app.Dim6Slider.Value);
            app.PlotImageFigFnc;
            app.SetMIPWLFnc;
            app.PlotMipFigFnc;

        end

        % Value changed function: OrientationSpinner
        function OrientationSpinnerValueChanged(app, event)

            app.MIPOrientationSpinner.Value = app.OrientationSpinner.Value;
            app.PhaseOrientationSpinner.Value = app.OrientationSpinner.Value;
            app.FlowOrientationSpinner.Value = app.OrientationSpinner.Value;
            app.SetImageLimitsFcn;
            app.newFig_flag = true;
            app.newPhaseFig_flag = true;
            app.newFlowFig_flag = true;
            app.PlotImageFigFnc;
            app.PlotMipFigFnc;
            app.PlotPhaseImageFigFnc;
            app.PlotFlowImageFigFnc;
            
        end

        % Value changed function: MIPOrientationSpinner
        function MIPOrientationSpinnerValueChanged(app, event)
            
            app.OrientationSpinner.Value = app.MIPOrientationSpinner.Value;
            app.PhaseOrientationSpinner.Value = app.MIPOrientationSpinner.Value;
            app.FlowOrientationSpinner.Value = app.MIPOrientationSpinner.Value;
            app.SetImageLimitsFcn;
            app.newFig_flag = true;
            app.newPhaseFig_flag = true;
            app.newFlowFig_flag = true;
            app.PlotImageFigFnc;
            app.PlotMipFigFnc;
            app.PlotPhaseImageFigFnc;
            app.PlotFlowImageFigFnc;
            
        end

        % Value changed function: LevelSlider
        function LevelSliderValueChanged(app, event)

            if app.pd.validReco_flag

                app.AutoIntensityCheckBox.Value = 0;
                app.LevelEditField.Value = round(app.LevelSlider.Value);
                app.PlotImageFigFnc;

            end

        end

        % Value changed function: LevelEditField
        function LevelEditFieldValueChanged(app, event)

            if app.pd.validReco_flag

                app.AutoIntensityCheckBox.Value = 0;
                app.LevelSlider.Value = app.LevelEditField.Value;
                app.PlotImageFigFnc;

            end

        end

        % Value changed function: WindowSlider
        function WindowSliderValueChanged(app, event)

            if app.pd.validReco_flag

                app.AutoIntensityCheckBox.Value = 0;
                app.WindowEditField.Value = round(app.WindowSlider.Value);
                app.PlotImageFigFnc;

            end

        end

        % Value changed function: WindowEditField
        function WindowEditFieldValueChanged(app, event)

            if app.pd.validReco_flag

                app.AutoIntensityCheckBox.Value = 0;
                app.WindowSlider.Value = app.WindowEditField.Value;
                app.PlotImageFigFnc;

            end

        end

        % Value changed function: AutoIntensityCheckBox
        function AutoIntensityCheckBoxValueChanged(app, event)

            if app.AutoIntensityCheckBox.Value == 1
                app.PlotImageFigFnc;
            end

        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)

            if app.pd.validReco_flag

                app.AutoIntensityCheckBox.Value = 0;
                app.SetImageWLFnc;
                app.PlotImageFigFnc;

            end

        end

        % Button pushed function: Button
        function MovieButtonPushed(app, event)

            if app.pd.validReco_flag

                try

                    app.Button.Enable = 'off';
                    app.Button_2.Enable = 'on';
                    % app.ExitButton.Enable = 'off';
                    app.stopMovie_flag = false;

                    app.TextMessage('Starting the movie ...');

                    movieCounter = 1;
                    while ~app.stopMovie_flag

                        app.Dim4EditField.Value = movieCounter;
                        app.Dim4Slider.Value = movieCounter;
                        app.PlotImageFigFnc;
                        movieCounter = app.Dim4EditField.Value;

                        movieCounter = movieCounter + 1;
                        movieIm = permute(app.pd.images,app.imageOrient(app.OrientationSpinner.Value,:));
                        if movieCounter>size(movieIm,4)
                            movieCounter = 1;
                        end

                    end

                    app.Button.Enable = 'on';
                    app.Button_2.Enable = 'off';

                catch
                end


            end

        end

        % Button pushed function: Button_2
        function MovieButton_2Pushed(app, event)

            app.TextMessage('Stopping the movie ...');

            app.Button.Enable = 'on';
            app.Button_2.Enable = 'off';
            app.stopMovie_flag = true;
            app.ExitButton.Enable = 'on';
            drawnow;

        end

        % Button pushed function: MaskButton
        function MaskButtonPushed(app, event)
            
            if app.pd.validReco_flag
                
                app.TextMessage('Automatic image segmentation ...');
             
                try
                    
                    % Reset the mask
                    app.pd.mask = ones(size(app.pd.images));

                    % Determine automatic threshold using the Otsu method
                    app.pd.threshold = double(graythresh(mat2gray(app.pd.images(:)))) * max(app.pd.images(:));

                    % Mask based on threshold
                    app.pd.mask(app.pd.images < app.pd.threshold) = 0;

                    % Display the threshold value for the current slice
                    app.ThresholdEditField.Value = round(app.pd.threshold);
                    app.ThresholdSlider.Value = round(app.pd.threshold);

                    % Mask is valid
                    app.pd.validMask_flag = true;
                    app.newFig_flag = true;

                    % Display the mask in the image
                    app.PlotImageFigFnc;
       
                    % Plot MIP and phase image
                    app.PlotMipFigFnc;
                    app.PlotPhaseImageFigFnc;

                catch ME

                    % Failure
                    app.pd.validMask_flag = false;
                    app.newFig_flag = true;
                    app.TextMessage('ERROR: during image segmentation ...');
                    app.TextMessage(ME.message);
               
                end
                
            end
            
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            
            app.ThresholdSlider.Value = app.ThresholdEditField.Value;

            if app.pd.validReco_flag
             
                try
                    
                    % Reset the mask
                    app.pd.mask = ones(size(app.pd.images));

                    % Threshold
                    app.pd.threshold = app.ThresholdEditField.Value;

                    % Mask based on threshold
                    app.pd.mask(app.pd.images < app.pd.threshold) = 0;

                    % Mask is valid
                    app.pd.validMask_flag = true;
                    app.newFig_flag = true;

                    % Display the mask in the image
                    app.PlotImageFigFnc;

                    % Plot MIP and phase image
                    app.PlotMipFigFnc;
                    app.PlotPhaseImageFigFnc;

                catch ME

                    % Failure
                    app.pd.validMask_flag = false;
                    app.newFig_flag = true;
                    app.TextMessage('ERROR: during image segmentation ...');
                    app.TextMessage(ME.message);
               
                end
                
            end
            
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            
            app.ThresholdEditField.Value = round(app.ThresholdSlider.Value);

            app.ThresholdEditFieldValueChanged;
            
        end

        % Button pushed function: ErodeButton
        function ErodeButtonPushed(app, event)

            if app.pd.validReco_flag && app.pd.validMask_flag

                try

                    % Morphological erosion
                    app.pd.mask = 1-imerode(1-app.pd.mask,app.structElement);

                    % Display the result;
                    app.newFig_flag = true;
                    app.PlotImageFigFnc;
                    app.PlotMipFigFnc;
                    app.PlotPhaseImageFigFnc;

                catch
                end

            end

        end

        % Button pushed function: DilateButton
        function DilateButtonPushed(app, event)
            
            if app.pd.validReco_flag && app.pd.validMask_flag

                try

                    % Morphological erosion
                    app.pd.mask = 1-imdilate(1-app.pd.mask,app.structElement);

                    % Display the result;
                    app.newFig_flag = true;
                    app.PlotImageFigFnc;
                    app.PlotMipFigFnc;
                    app.PlotPhaseImageFigFnc;

                catch
                end

            end

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            
            if app.pd.validReco_flag && app.pd.validMask_flag

                try

                    % Morphological erosion
                    app.pd.mask = 1-imclose(1-app.pd.mask,app.structElement);

                    % Display the result;
                    app.newFig_flag = true;
                    app.PlotImageFigFnc;
                    app.PlotMipFigFnc;
                    app.PlotPhaseImageFigFnc;

                catch
                end

            end

        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            
            if app.pd.validReco_flag && app.pd.validMask_flag

                try

                    % Morphological erosion
                    app.pd.mask = 1-imopen(1-app.pd.mask,app.structElement);

                    % Display the result;
                    app.newFig_flag = true;
                    app.PlotImageFigFnc;
                    app.PlotMipFigFnc;
                    app.PlotPhaseImageFigFnc;

                catch
                end

            end

        end

        % Value changed function: MIPxAngleSlider, MIPyAngleSlider
        function MIPAngleSliderValueChanged(app, event)
            
            app.MIPcounter = 0;
            app.PlotMipFigFnc;

        end

        % Button pushed function: PlayMIPxButton
        function PlayMIPxButtonPushed(app, event)

            if app.pd.validReco_flag

                app.stopMIPyMovie_flag = true;
                app.PlayMIPyButton.Icon = 'play.gif';

                if app.stopMIPxMovie_flag == true
                    app.MIPcounter = 0;
                    app.stopMIPxMovie_flag = false;
                    app.PlayMIPxButton.Icon = 'stop.gif';
                else
                    app.stopMIPxMovie_flag = true;
                    app.PlayMIPxButton.Icon = 'play.gif';
                end

                drawnow;
           
                while ~app.stopMIPxMovie_flag

                    newAngleX = app.MIPxAngleSlider.Value + app.MIPstep;
                    app.MIPcounter = app.MIPcounter + app.MIPstep;
                    newAngleX(newAngleX > 179) = -180;
                    app.MIPxAngleSlider.Value = newAngleX;
                    drawnow;

                    app.PlotMipFigFnc;

                end

            end

        end

        % Button pushed function: PlayMIPyButton
        function PlayMIPyButtonPushed(app, event)

            if app.pd.validReco_flag

                app.stopMIPxMovie_flag = true;
                app.PlayMIPxButton.Icon = 'play.gif';

                if app.stopMIPyMovie_flag == true
                    app.MIPcounter = 0;
                    app.stopMIPyMovie_flag = false;
                    app.PlayMIPyButton.Icon = 'stop.gif';
                else
                    app.stopMIPyMovie_flag = true;
                    app.PlayMIPyButton.Icon = 'play.gif';
                end

                drawnow;

                while ~app.stopMIPyMovie_flag

                    app.MIPcounter = app.MIPcounter + app.MIPstep;
                    newAngleY = app.MIPyAngleSlider.Value + app.MIPstep;
                    newAngleY(newAngleY > 179) = -180;
                    app.MIPyAngleSlider.Value = newAngleY;
                    drawnow;

                    app.PlotMipFigFnc;

                end

            end

        end

        % Button pushed function: MIPAngleResetButton
        function MIPAngleSliderResetButtonPushed(app, event)
            
            if app.pd.validReco_flag

                app.stopMIPxMovie_flag = true;
                app.stopMIPyMovie_flag = true;

                app.MIPxAngleSlider.Value = 0;
                app.MIPyAngleSlider.Value = 0;

                % Reset the play icon buttons
                app.PlayMIPxButton.Icon = 'play.gif';
                app.PlayMIPyButton.Icon = 'play.gif';

                app.MIPcounter = 0;

                app.PlotMipFigFnc;

            end

        end

        % Value changed function: MIPResizeButton
        function MIPResizeButtonValueChanged(app, event)
         
            if app.pd.validReco_flag
                
                app.MIPcounter = 0;

                if app.stopMIPxMovie_flag & app.stopMIPyMovie_flag %#ok<AND2> 
    
                    app.PlotMipFigFnc;

                end
              
            end
            
        end

        % Button pushed function: MIPSetButton
        function MIPSetButtonPushed(app, event)
            
            app.SetMIPWLFnc;
            app.PlotMipFigFnc;

        end

        % Value changed function: MIPLevelSlider
        function MIPLevelSliderValueChanged(app, event)

            if app.pd.validReco_flag

                app.MIPLevelEditField.Value = round(app.MIPLevelSlider.Value);
                app.PlotMipFigFnc;

            end

        end

        % Value changed function: MIPWindowSlider
        function MIPWindowSliderValueChanged(app, event)
            
            if app.pd.validReco_flag

                app.MIPWindowEditField.Value = round(app.MIPWindowSlider.Value);
                app.PlotMipFigFnc;

            end
            
        end

        % Value changed function: MIPLevelEditField
        function MIPLevelEditFieldValueChanged(app, event)
           
            if app.pd.validReco_flag

                app.MIPLevelSlider.Value = app.MIPLevelEditField.Value;
                app.PlotMipFigFnc;

            end
            
        end

        % Value changed function: MIPWindowEditField
        function MIPWindowEditFieldValueChanged(app, event)

            if app.pd.validReco_flag

                app.MIPWindowSlider.Value = app.MIPWindowEditField.Value;
                app.PlotMipFigFnc;

            end
            
        end

        % Value changed function: PhaseDim3EditField
        function PhaseDim3EditFieldValueChanged(app, event)
             
            app.PhaseDim3Slider.Value = app.PhaseDim3EditField.Value;
            app.PlotPhaseImageFigFnc;
            
        end

        % Value changed function: PhaseDim3Slider
        function PhaseDim3SliderValueChanged(app, event)
            
            app.PhaseDim3EditField.Value = round(app.PhaseDim3Slider.Value);
            app.PlotPhaseImageFigFnc;
            
        end

        % Value changed function: PhaseDim4EditField
        function PhaseDim4EditFieldValueChanged(app, event)
             
            app.PhaseDim4Slider.Value = app.PhaseDim4EditField.Value;
            app.PlotPhaseImageFigFnc;
            
        end

        % Value changed function: PhaseDim4Slider
        function PhaseDim4SliderValueChanged(app, event)
            
            app.PhaseDim4EditField.Value = round(app.PhaseDim4Slider.Value);
            app.PlotPhaseImageFigFnc
            
        end

        % Value changed function: PhaseDim5EditField
        function PhaseDim5EditFieldValueChanged(app, event)
             
            app.PhaseDim5Slider.Value = app.PhaseDim5EditField.Value;
            app.PlotPhaseImageFigFnc;
            
        end

        % Value changed function: PhaseDim5Slider
        function PhaseDim5SliderValueChanged(app, event)
            
            app.PhaseDim5EditField.Value = round(app.PhaseDim5Slider.Value);
            app.PlotPhaseImageFigFnc
            
        end

        % Value changed function: PhaseDim6EditField
        function PhaseDim6EditFieldValueChanged(app, event)
             
            app.PhaseDim6Slider.Value = app.PhaseDim6EditField.Value;
            app.PlotPhaseImageFigFnc;
            
        end

        % Value changed function: PhaseDim6Slider
        function PhaseDim6SliderValueChanged(app, event)
            
            app.PhaseDim6EditField.Value = round(app.PhaseDim6Slider.Value);
            app.PlotPhaseImageFigFnc
            
        end

        % Value changed function: PhaseOrientationSpinner
        function PhaseOrientationSpinnerValueChanged(app, event)

            app.OrientationSpinner.Value = app.PhaseOrientationSpinner.Value;
            app.MIPOrientationSpinner.Value = app.PhaseOrientationSpinner.Value;
            app.FlowOrientationSpinner.Value = app.PhaseOrientationSpinner.Value;
            app.SetImageLimitsFcn;
            app.newFig_flag = true;
            app.newPhaseFig_flag = true;
            app.newFlowFig_flag = true;
            app.PlotImageFigFnc;
            app.PlotMipFigFnc;
            app.PlotPhaseImageFigFnc;
            app.PlotFlowImageFigFnc;
            
        end

        % Button pushed function: PhaseMovieStartButton
        function PhaseMovieStartButtonPushed(app, event)
            
            if app.pd.validReco_flag

                app.PhaseMovieStartButton.Enable = 'off';
                app.PhaseMovieStopButton.Enable = 'on';
                % app.ExitButton.Enable = 'off';
                app.stopPhaseMovie_flag = false;

                app.TextMessage('Starting the phase movie ...');

                try

                    phaseCounter = 1;
                    while ~app.stopPhaseMovie_flag

                        app.PhaseDim4EditField.Value = phaseCounter;
                        app.PhaseDim4Slider.Value = phaseCounter;
                        app.PlotPhaseImageFigFnc;
                        phaseCounter = app.PhaseDim4EditField.Value;
                        phaseCounter = phaseCounter + 1;
                        phaseIm = permute(app.pd.phaseImages,app.imageOrient(app.PhaseOrientationSpinner.Value,:));
                        if phaseCounter>size(phaseIm,4)
                            phaseCounter = 1;
                        end

                    end

                catch
                end

                app.PhaseMovieStartButton.Enable = 'on';
                app.PhaseMovieStopButton.Enable = 'off';

            end

        end

        % Button pushed function: PhaseMovieStopButton
        function PhaseMovieStopButtonPushed(app, event)
            
            app.TextMessage('Stopping the phase movie ...');

            app.PhaseMovieStartButton.Enable = 'on';
            app.PhaseMovieStopButton.Enable = 'off';
            app.stopPhaseMovie_flag = true;
            app.ExitButton.Enable = 'on';
            drawnow;

        end

        % Button pushed function: PhaseUnwrapButton
        function PhaseUnwrapButtonPushed(app, event)

            if app.pd.validReco_flag

                app.SetStatus(3);
                app.PhaseUnwrapButton.Enable = 'off';
                drawnow;

                % Phase unwrapping
                try
                    app.pd = app.pd.unwrap3D;
                    app.newPhaseFig_flag = true;
                    app.PlotPhaseImageFigFnc;
                catch ME
                    app.TextMessage(ME.message);
                end

                app.SetStatus(0);
                app.PhaseUnwrapButton.Enable = 'on';
                drawnow;

            end

        end

        % Button pushed function: LoadVencTableButton
        function LoadVencTableButtonPushed(app, event)
            
            % Read the venc file
            try
                app.P2ROUD_UIFigure.Visible = 'off';
                [vencFile,vencPath] = uigetfile('*.txt');
                app.P2ROUD_UIFigure.Visible = 'on';
                app.pd.vencTable = dlmread([vencPath,vencFile]); %#ok<DLMRD> 
                drawnow;
                crit1 = true;
            catch ME
                app.TextMessage(ME.message);
                app.TextMessage('ERROR reading venc file ...');
                crit1 = false;
            end
            
            % Check the length of the Venc table
            try
                EncDir = app.pd.vencTable(:,2:4);
                Venc = app.pd.vencTable(:,1);
                if size(app.pd.vencTable,2) < 5
                    app.pd.vencTable(:,5) = 1;
                end
                EncUse = app.pd.vencTable(:,5);

                if length(Venc) == app.NREditField.Value
                    app.TextMessage('Length of Venc table matches data ...');
                    crit2 = true;
                else
                    app.TextMessage('Length of Venc table does not match the data ...');
                    crit2 = false;
                end
            catch ME
                app.TextMessage(ME.message);
                crit2 = false;
            end

            % Check if number of directions is at least 2
            try
                if nnz(EncUse) > 1
                    app.TextMessage('Number of flow-encoding directions > 1 ...');
                    crit3 = true;
                else
                    app.TextMessage('Insufficient number of flow-encoding directions ...');
                    crit3 = false;
                end
            catch ME
                app.TextMessage(ME.message);
                crit3 = false;
            end
            
            % Venc table is okay
            if crit1 && crit2 && crit3
                app.TextMessage('Venc table seems compatible with data ...');
                EncMatrix = [EncDir Venc EncUse];
                for i = size(EncMatrix,1):-1:1
                    app.TextMessage(strcat(num2str(EncMatrix(i,:),3)));
                end
                app.TextMessage('z         y         x       Venc    include');
                app.pd.validVenc_flag = true;
                app.VencLamp.Color = app.green;
                app.CalcFlowButton.Enable = 'on';
                app.SetStatus(0);
            else
                app.TextMessage('Venc table seems not compatible with data ...');
                app.pd.validVenc_flag = false;
                app.VencLamp.Color = app.red;
                app.CalcFlowButton.Enable = 'off';
                app.SetStatus(2);
            end
      
        end

        % Button pushed function: CalcFlowButton
        function CalcFlowButtonPushed(app, event)

            if app.pd.validMask_flag && app.pd.validVenc_flag
                
                app.SetStatus(3);
                app.CalcFlowButton.Enable = 'off';
                drawnow;

                try
                    % Calculate flow
                    app.TextMessage('Calculating 4D-flow data ...');
                    app.pd = app.pd.calcFlow(app);

                    app.pd.validFlow_flag = true;
                    app.newFlowFig_flag = true;
                    app.PlotFlowImageFigFnc;

                catch ME
                    app.TextMessage(ME.message);
                end

                app.CalcFlowButton.Enable = 'on';
                app.SetStatus(0);
                drawnow;

            else

                app.pd.validFlow_flag = false;
                app.SetStatus(1);
                app.TextMessage('WARNING: Please segment the data first ...');

            end

        end

        % Value changed function: FlowDim3EditField
        function FlowDim3EditFieldValueChanged(app, event)
            
            app.FlowDim3Slider.Value = app.FlowDim3EditField.Value;
            app.PlotFlowImageFigFnc;
            
        end

        % Value changed function: FlowDim3Slider
        function FlowDim3SliderValueChanged(app, event)
            
            app.FlowDim3EditField.Value = round(app.FlowDim3Slider.Value);
            app.PlotFlowImageFigFnc;
            
        end

        % Window scroll wheel function: P2ROUD_UIFigure
        function P2ROUD_UIFigureWindowScrollWheel(app, event)

            verticalScrollCount = event.VerticalScrollCount;

            if app.pd.validReco_flag && app.scrollEnable_flag

                if verticalScrollCount > 0

                    newslice = app.Dim3Slider.Value + 1;
                    newslice(newslice>app.Dim3Slider.Limits(2)) = 1;

                else

                    newslice = app.Dim3Slider.Value - 1;
                    newslice(newslice<1) = app.Dim3Slider.Limits(2);

                end

                app.Dim3Slider.Value = newslice;
                app.Dim3SliderValueChanged;

            end

        end

        % Value changed function: FlowDim4EditField
        function FlowDim4EditFieldValueChanged(app, event)
            
            app.FlowDim4Slider.Value = app.FlowDim4EditField.Value;
            app.PlotFlowImageFigFnc;
            
        end

        % Value changed function: FlowDim4Slider
        function FlowDim4SliderValueChanged(app, event)
            
            app.FlowDim4EditField.Value = round(app.FlowDim4Slider.Value);
            app.PlotFlowImageFigFnc;
            
        end

        % Value changed function: FlowDim5EditField
        function FlowDim5EditFieldValueChanged(app, event)
            
            app.FlowDim5Slider.Value = app.FlowDim5EditField.Value;
            app.PlotFlowImageFigFnc;
            
        end

        % Value changed function: FlowDim5Slider
        function FlowDim5SliderValueChanged(app, event)
            
            app.FlowDim5EditField.Value = round(app.FlowDim5Slider.Value);
            app.PlotFlowImageFigFnc;
            
        end

        % Value changed function: FlowDim6EditField
        function FlowDim6EditFieldValueChanged(app, event)
            
            app.FlowDim6Slider.Value = app.FlowDim6EditField.Value;
            app.PlotFlowImageFigFnc;
            
        end

        % Value changed function: FlowDim6Slider
        function FlowDim6SliderValueChanged(app, event)
            
            app.FlowDim6EditField.Value = round(app.FlowDim6Slider.Value);
            app.PlotFlowImageFigFnc;
            
        end

        % Value changed function: FlowOrientationSpinner
        function FlowOrientationSpinnerValueChanged(app, event)
            
            app.OrientationSpinner.Value = app.FlowOrientationSpinner.Value;
            app.MIPOrientationSpinner.Value = app.FlowOrientationSpinner.Value;
            app.PhaseOrientationSpinner.Value = app.FlowOrientationSpinner.Value;
            app.SetImageLimitsFcn;
            app.newFig_flag = true;
            app.newPhaseFig_flag = true;
            app.newFlowFig_flag = true;
            app.PlotImageFigFnc;
            app.PlotMipFigFnc;
            app.PlotPhaseImageFigFnc;
            app.PlotFlowImageFigFnc;
            
        end

        % Value changed function: FlowDirectionSpinner
        function FlowDirectionSpinnerValueChanged(app, event)
            
            switch app.FlowDirectionSpinner.Value

                case 1
                    app.FlowDirectionSpinnerLabel.Text = 'Vz (cm/s)';
                case 2
                    app.FlowDirectionSpinnerLabel.Text = 'Vy (cm/s)';
                case 3
                    app.FlowDirectionSpinnerLabel.Text = 'Vx (cm/s)';
                case 4
                    app.FlowDirectionSpinnerLabel.Text = 'Background';
                case 5
                    app.FlowDirectionSpinnerLabel.Text = '| V | (cm/s)';

            end

            app.newFlowFig_flag = true;
            app.PlotFlowImageFigFnc;

        end

        % Button pushed function: FlowMovieStartButton
        function FlowMovieStartButtonPushed(app, event)
            
            if app.pd.validFlow_flag

                try

                    app.FlowMovieStartButton.Enable = 'off';
                    app.FlowMovieStopButton.Enable = 'on';
                    % app.ExitButton.Enable = 'off';
                    app.stopFlowMovie_flag = false;

                    app.TextMessage('Starting the flow movie ...');

                    flowCounter = 1;
                    while ~app.stopFlowMovie_flag

                        app.FlowDim4EditField.Value = flowCounter;
                        app.FlowDim4Slider.Value = flowCounter;
                        app.PlotFlowImageFigFnc;
                        flowCounter = app.FlowDim4EditField.Value;

                        flowCounter = flowCounter + 1;
                        flowIm = permute(app.pd.flowImages(:,:,:,:,:,:,app.FlowDirectionSpinner.Value),app.imageOrient(app.FlowOrientationSpinner.Value,:));
                        if flowCounter>size(flowIm,4)
                            flowCounter = 1;
                        end

                    end

                    app.FlowMovieStartButton.Enable = 'on';
                    app.FlowMovieStopButton.Enable = 'off';

                catch
                end

            end

        end

        % Button pushed function: FlowMovieStopButton
        function FlowMovieStopButtonPushed(app, event)
            
            app.TextMessage('Stopping the flow movie ...');

            app.FlowMovieStartButton.Enable = 'on';
            app.FlowMovieStopButton.Enable = 'off';
            app.stopFlowMovie_flag = true;
            app.ExitButton.Enable = 'on';
            drawnow;

        end

        % Button pushed function: ExportDCMButton
        function ExportDCMButtonPushed(app, event)

            % Export DICOM

            if app.pd.validReco_flag

                % Set buttons
                app.SwitchOffAllButtonsFnc;
                app.SetLightsFnc({'Null','Null','Yellow','Null','Null','Null'});
                app.SetStatus(3);

                try

                    % Some parameters
                    switch app.pd.dataType

                        case {"2D","2Dradial","2Depi"}
                            app.pd.SLICE_SEPARATION = app.pd.SLICE_SEPARATION/app.pd.SLICE_INTERLEAVE;
                            app.pd.SLICE_THICKNESS = app.FOVViewField3.Value;

                        case {"3D","3Dute"}
                            app.pd.SLICE_SEPARATION = app.FOVViewField3.Value/size(app.pd.images,3);
                            app.pd.SLICE_THICKNESS = app.FOVViewField3.Value/size(app.pd.images,3);

                    end
                    app.pd.SLICE_INTERLEAVE = 1;
                    app.pd.FOV = app.FOVViewField1.Value;
                    app.pd.NO_SLICES_DCM = size(app.pd.images,3);
                    % app.pd.NO_VIEWS = size(app.pd.images,2);
                    % app.pd.NO_VIEWS_2 = size(app.pd.images,3);
                    app.pd.field_strength = 7;
                    app.pd.filename = 111;
                    app.CalcTotalAcquisitionTimeFcn;
                    app.pd.acqdur = app.pd.totalAcqTime;
                    app.pd.timeperframe = app.pd.totalAcqTime/app.NREditField.Value;
                    app.pd.nr_frames = app.NREditField.Value;

                    % Search for dicom file
                    app.TextMessage('DICOM export ...');

                    % Assuming that MRD and Data directory share the same base-directory
                    % Example: base / MRD / 343 / 8455 /

                    % Base / data / 343 / DICOM / 8455 /
                    dcmSpath{1} = replace(app.mrdImportPath,"MRD","Data");
                    try
                        k = strfind(dcmSpath{1},"Data");
                        fs = strfind(dcmSpath{1}(k:end),filesep);
                        dcmSpath{1}= dcmSpath{1}(1:k+fs(3)-1);
                        dcmSpath{1} = insertAfter(dcmSpath{1},k+fs(2)-1,strcat("DICOM",filesep));
                        dcmIndx{1} = 2;
                    catch
                        dcmSpath{1} = app.mrdImportPath;
                    end

                    % Base / data / 343 / DICOM / 8455 / 1 /
                    dcmSpath{2} = strcat(dcmSpath{1},'1',filesep);
                    dcmIndx{2} = 3;

                    % Base / data / 343 / DICOM / 8455 / 2 /
                    dcmSpath{3} = strcat(dcmSpath{1},'2',filesep);
                    dcmIndx{3} = 3;

                    % Base / data / 343 / DICOM / 8455 / 3 /
                    dcmSpath{4} = strcat(dcmSpath{1},'3',filesep);
                    dcmIndx{4} = 3;

                    % Check if one of the directories contains dicom files
                    dcmExist = false;
                    for i=1:length(dcmSpath)
                        if ~isempty(dir(strcat(dcmSpath{i},"*.dcm")))
                            dcmPath = dcmSpath{i};
                            app.TextMessage(strcat("Found DICOM information in ",dcmPath));
                            filesepIdx = strfind(dcmPath, filesep);
                            app.dicomExportPath = dcmPath(1:filesepIdx(end-dcmIndx{i}));
                            dcmExist = true;
                            break;
                        end
                    end

                    if dcmExist

                        % Export, reading dicom information from dcmPath
                        try
                            % Check if directory is actually writable
                            mkdir(strcat(app.dicomExportPath,filesep,"testdir"));
                            rmdir(strcat(app.dicomExportPath,filesep,"testdir"));
                        catch
                            app.P2ROUD_UIFigure.Visible = 'off';
                            app.dicomExportPath = uigetdir(app.dicomExportPath,'DICOM export path');
                            app.P2ROUD_UIFigure.Visible = 'on';
                            drawnow;
                            if ~ischar(app.dicomExportPath)
                                ME = MException('P2ROUD:userCancel','User cancel ...');
                                throw(ME);
                            end
                        end

                        % Export with image orientation information
                        exportPath = exportDicomDCM(app, dcmPath);
                        app.pd.ExportRecoParametersFcn(app, exportPath);

                    else

                        % Ask for a dicom export directory
                        app.P2ROUD_UIFigure.Visible = 'off';
                        app.dicomExportPath = uigetdir(app.dicomExportPath,'DICOM export path');
                        app.P2ROUD_UIFigure.Visible = 'on';
                        drawnow;
                        if ~ischar(app.dicomExportPath)
                            ME = MException('P2ROUD:userCancel','User cancel ...');
                            throw(ME);
                        end
                        app.dicomExportPath = strcat(app.dicomExportPath,filesep,"DICOM",filesep);
                        
                        % Export without image orientation information
                        exportPath = exportDicomMRD(app, app.tag);
                        app.pd.ExportRecoParametersFcn(app, exportPath);

                    end

                    % Success
                    app.TextMessage('DICOM export finished ...');
                    app.SetStatus(0);
                    app.SetLightsFnc({'Null','Null','Green','Null','Null','Null'});
                    SetButtonsFnc(app,{'On','On','On','On','On','On','On'});

                catch ME

                    % Failure
                    app.TextMessage(ME.message);
                    app.TextMessage('ERROR: Something went wrong during DICOM export ...');
                    app.dicomExportPath = '';
                    app.SetStatus(2);
                    app.SetLightsFnc({'Null','Null','Red','Null','Null','Null'});
                    SetButtonsFnc(app,{'On','On','On','On','On','On','On'});
                end

            end

        end

        % Button pushed function: ExportNIFTIButton
        function ExportNIFTIButtonPushed(app, event)

            if app.pd.validReco_flag && app.validNIFTIdata_flag

                % Set buttons
                app.SwitchOffAllButtonsFnc;
                app.SetLightsFnc({'Null','Null','Null','Yellow','Null','Null'});
                app.SetStatus(3);

                try

                    app.TextMessage('Exporting Nifti data ...');

                    % Make base / data / study / Nifti
                    dataDirExists = false;
                    try
                        if contains(app.mrdImportPath, "MRD")
                            app.niftiExportPath = replace(app.mrdImportPath, "MRD", "Data");
                            k = strfind(app.niftiExportPath,"Data");
                            fs = strfind(app.niftiExportPath(k:end),filesep);
                            app.niftiExportPath = app.niftiExportPath(1:k+fs(2)-1);
                            app.niftiExportPath = strcat(app.niftiExportPath,filesep,"Nifti",filesep);
                            app.niftiExportPath = strrep(app.niftiExportPath, strcat(filesep,filesep), filesep);
                            dataDirExists = true;
                        end
                    catch
                    end

                    % Ask for directory if Nifti directory does not exists
                    if dataDirExists
                        try
                            % Check if directory is actually writable
                            mkdir(strcat(app.niftiExportPath,filesep,"testdir"));
                            rmdir(strcat(app.niftiExportPath,filesep,"testdir"));
                        catch
                            app.P2ROUD_UIFigure.Visible = 'off';
                            app.niftiExportPath = uigetdir(app.niftiExportPath,'NIFTI export folder');
                            app.P2ROUD_UIFigure.Visible = 'on';
                            drawnow;
                            if ~ischar(app.niftiExportPath)
                                ME = MException('PROUD:userCancel','User cancel ...');
                                throw(ME);
                            end
                            app.niftiExportPath = strcat(app.niftiExportPath,filesep,"Nifti",filesep);
                        end
                    else

                        app.P2ROUD_UIFigure.Visible = 'off';
                        app.niftiExportPath = uigetdir(app.niftiExportPath,'NIFTI export path');
                        app.P2ROUD_UIFigure.Visible = 'on';
                        drawnow;
                        if ~ischar(app.niftiExportPath)
                            ME = MException('PROUD:userCancel','User cancel ...');
                            throw(ME);
                        end
                        app.niftiExportPath = strcat(app.niftiExportPath,filesep,"Nifti",filesep);
                    end

                    %---- Anatomical NIFTI file ----

                    % Normalize images and determine dimensions
                    image4D = squeeze(app.pd.images);
                    image4D = squeeze(image4D(:,:,:,:,1,1));
                    im = round(32767 * image4D / max(image4D(:)));
                    im = cast(im,'single');

                    % Phase orientation  --- only in the last step, I think
                    if app.pd.PHASE_ORIENTATION == 1
                        app.TextMessage('INFO: phase orientation = 1');
                        im = permute(rot90(permute(im,[2 1 3 4]),1),[2 1 3 4]);
                        FOVx = app.FOVViewField2.Value;
                        FOVy = app.FOVViewField1.Value;
                    else
                        FOVx = app.FOVViewField1.Value;
                        FOVy = app.FOVViewField2.Value;
                    end

                    % Of course orientations are never correct and something needs to be flipped
                    im = flip(im,1);
                    [dimx,dimy,dimz,nrFrames] = size(im);

                    % Set some parameters
                    app.CalcTotalAcquisitionTimeFcn;
                    app.pd.timeperframe = 0;
                    if nrFrames>1
                        app.pd.timeperframe = app.pd.totalAcqTime/app.NREditField.Value;
                    end
                    load('niftiinfo.mat'); %#ok<LOAD>
                    info.Filename = strcat(app.tag,'image4D.nii');
                    info.Filemoddate = datestr(datetime);
                    info.Datatype = 'single';
                    info.BitsPerPixel = 32;
                    info.Filesize = dimx*dimy*dimz*nrFrames*32;

                    switch app.pd.dataType
                        case {"2D","2Dradial","2Depi"}
                            zdim = app.FOVViewField3.Value;
                        case {"3D","3Dute"}
                            zdim = app.FOVViewField3.Value/dimz;
                    end

                    % Time per dynamic frame
                    tdim = app.pd.timeperframe;
                    if size(app.pd.images,6) > 1 % movie, multi-echo
                        tdim = app.TRViewField.Value;
                    end

                    if nrFrames>1
                        info.ImageSize = [dimx dimy dimz nrFrames];
                        info.PixelDimensions = [FOVx/dimx FOVy/dimy zdim tdim];
                    else
                        info.ImageSize = [dimx dimy dimz];
                        info.PixelDimensions = [FOVx/dimx FOVy/dimy zdim];
                    end

                    info.raw.dim = [3 dimx dimy dimz nrFrames 1 1 1 1];
                    info.raw.pixdim = [FOVx/dimx FOVy/dimy zdim tdim 0 0 0 0];
                    info.vox_offset = 0;

                    %---- Flow data NIFTI file ----

                    if app.pd.validFlow_flag

                        % Normalize images and determine dimensions
                        imf = app.pd.flowImages(:,:,:,1,1,:,:);
                        imf = permute(imf,[1 2 3 6 7 4 5]);
                        imf = cast(imf,'single');

                        % Phase orientation
                        if app.pd.PHASE_ORIENTATION == 1
                            imf = permute(rot90(permute(imf,[2 1 3 4 5]),1),[2 1 3 4 5]);
                        end

                        % Of course orientations are never correct and something needs to be flipped
                        imf = flip(imf,1);
                        [dimx,dimy,dimz,nrFramesflow,~] = size(imf);

                        % Set some parameters
                        app.pd.timeperframe = 0;
                        if nrFramesflow>1
                            app.pd.timeperframe = app.pd.tr;
                        end
                        load('niftiinfo1.mat'); %#ok<LOAD>
                        info1.Filename = strcat(app.tag,'image4Dflow.nii');
                        info1.Filemoddate = datestr(datetime);
                        info1.Datatype = 'single';
                        info1.BitsPerPixel = 32;
                        info1.Filesize = dimx*dimy*dimz*nrFramesflow*32;

                        switch app.pd.dataType
                            case "2D"
                                zdim = app.FOVViewField3.Value;
                            case {"3D","3Dute"}
                                zdim = app.FOVViewField3.Value/dimz;
                        end

                        if nrFramesflow>1
                            info1.ImageSize = [dimx dimy dimz nrFramesflow];
                            info1.PixelDimensions = [FOVx/dimx FOVy/dimy zdim app.pd.tr];
                        else
                            info1.ImageSize = [dimx dimy dimz];
                            info1.PixelDimensions = [FOVx/dimx FOVy/dimy zdim];
                        end

                        info1.raw.dim = [3 dimx dimy dimz nrFramesflow 1 1 1 1];
                        info1.raw.pixdim = [FOVx/dimx FOVy/dimy zdim app.pd.tr 0 0 0 0];
                        info1.vox_offset = 0;

                    end

                    % Create new directory
                    ready = false;
                    cnt = 1;
                    while ~ready
                        folderName = strcat(app.niftiExportPath,filesep,app.tag,filesep,num2str(cnt),filesep);
                        if ~exist(folderName, 'dir')
                            mkdir(folderName);
                            ready = true;
                        end
                        cnt = cnt + 1;
                    end
                    app.TextMessage(strcat("NIFTI export folder = ",folderName));

                    niftiwrite(im,strcat(folderName,filesep,app.tag,'image4D.nii'),info);
                    if app.pd.validFlow_flag
                        niftiwrite(squeeze(imf(:,:,:,:,1)),strcat(folderName,filesep,app.tag,'image4Dflow_Vz.nii'),info1);
                        niftiwrite(squeeze(imf(:,:,:,:,2)),strcat(folderName,filesep,app.tag,'image4Dflow_Vy.nii'),info1);
                        niftiwrite(squeeze(imf(:,:,:,:,3)),strcat(folderName,filesep,app.tag,'image4Dflow_Vx.nii'),info1);
                        niftiwrite(squeeze(imf(:,:,:,:,5)),strcat(folderName,filesep,app.tag,'image4Dflow_Vm.nii'),info1);
                    end
                    app.pd.ExportRecoParametersFcn(app,folderName);

                    % Success
                    app.TextMessage('NIFTI export finished ...');
                    app.SetStatus(0);
                    app.SetLightsFnc({'Null','Null','Null','Green','Null','Null'});
                    SetButtonsFnc(app,{'On','On','On','On','On','On','On'});

                catch ME

                    % Failure
                    app.TextMessage(ME.message);
                    app.TextMessage('ERROR: Something went wrong during NIFTI export ...');
                    app.niftiExportPath = '';
                    app.SetStatus(2);
                    app.SetLightsFnc({'Null','Null','Null','Red','Null','Null'});
                    SetButtonsFnc(app,{'On','On','On','On','On','On','On'});

                end

            else

                app.TextMessage("No suitable 3D data for Nifti export available ...");

            end

        end

        % Button pushed function: ExportMRDButton
        function ExportMRDButtonPushed(app, event)

            % Set buttons
            app.SwitchOffAllButtonsFnc;
            app.SetLightsFnc({'Null','Null','Null','Null','Yellow','Null'});
            app.SetStatus(3);

            % Indicate busy
            app.TextMessage('MRD file export ...');

            try

                % Make a k-space from the movie
                % Dimensions = (samples, views, views2, slices, echoes (frames), experiments, flip-angles)
                app.pd = app.pd.backToKspace;

                % Parameters for mrd footer
                [par.NoSamples,par.NoViews,par.NoViews2,par.NoSlices,par.NoEchoes,par.NoExperiments,par.NoFlipAngles] = size(app.pd.mrdKspace);
                if par.NoFlipAngles > 1
                    par.NoExperiments = par.NoExperiments * par.NoFlipAngles;
                end
                par.peorder = 0;
                par.pe2_centric_on = 0;
                par.slicenav = 0;
                par.navon = 0;
                par.radialon = 0;
                par.NoAverages = app.NAViewField.Value;
                par.batchslices = 1;
                par.frameloopon = 0;
                par.viewspersegment = 1;
                par.nodiscard = 0;
                par.View1order = "SEQUENTIAL";
                par.View2order = "SEQUENTIAL";
                par.reconmethod = "FFT";
                if app.pd.frame_loop_on == 1
                    par.frameloopon = 1;
                    par.te = 0;  % set to zero, because no_echoes dimension is used for (cardiac) frames
                end
                par.tr = floor(app.TRViewField.Value);
                par.tr_extra_us = (app.pd.TR - par.tr)*1000;
                par.te = floor(app.TEViewField.Value);
                par.te_us = (app.pd.TE - par.te)*1000;
                par.SLICE_THICKNESS = app.FOVViewField3.Value;
                par.channelCount = 1;
                par.channelWeights = 1;

                % Construct a new footer for the MRD file
                app.pd = app.pd.makeMrdFooter(par);

                % Construct a new rpr file if input rpr file was available
                if app.pd.rprFile_flag
                    app.pd = app.pd.makeRprFile(par);
                end

                % Auto export the new MRD and rpr files
                app.mrdExportPath = app.mrdImportPath;

                % Check if directory is actually writable, else ask for different folder
                try
                    mkdir(strcat(app.mrdExportPath,filesep,"testdir"));
                    rmdir(strcat(app.mrdExportPath,filesep,"testdir"));
                catch
                    app.P2ROUD_UIFigure.Visible = 'off';
                    app.mrdExportPath = uigetdir(app.mrdExportPath,'MRD export folder');
                    app.P2ROUD_UIFigure.Visible = 'on';
                    if ~ischar(app.mrdExportPath)
                        ME = MException('P2ROUD:userCancel','User cancel ...');
                        throw(ME);
                    end
                end

                % Make a unique file name
                if contains(app.tag,'p2roud')
                    k = strfind(app.tag,'_');
                    app.tag = extractAfter(app.tag,k(2));
                end
                cnt = 1;
                while true
                    mrdfilename = ['p2roud_',num2str(cnt),'_',app.tag,'.MRD'];
                    rprfilename = ['p2roud_',num2str(cnt),'_',app.tag,'.rpr'];
                    mrdexportfilename = [app.mrdExportPath,mrdfilename];
                    rprexportfilename = [app.mrdExportPath,rprfilename];
                    if ~isfile(mrdexportfilename)
                        break;
                    end
                    cnt = cnt + 1;
                end

                % Try the export in the MRD input directory
                app.pd.writeDataToMrd(mrdexportfilename, par);
                app.pd.ExportRecoParametersFcn(app, app.mrdExportPath);
                if app.pd.rprFile_flag
                    app.pd.writeToRprFile(rprexportfilename);
                end
                if (exist(strcat(app.mrdImportPath,filesep,'rtable.rtv'), 'file') == 2)
                    movefile(strcat(app.mrdImportPath,filesep,'rtable.rtv'),strcat(app.mrdImportPath,filesep,'origrtable.rtv.orig'));
                    delete(strcat(app.mrdImportPath,filesep,'rtable.rtv'));
                end

                % Try to autoreconstruct and export of SUR files, only try when platform is pc
                if ispc

                    % Determine SUR directory
                    surPathFound = false;
                    try
                        scanPos = strfind(app.mrdImportPath,filesep);
                        scanTag = strcat(app.mrdImportPath(scanPos(end-2)+1:scanPos(end-1)-1),filesep,'Image',filesep,app.mrdImportPath(scanPos(end-1)+1:scanPos(end)-1));
                        surPath = winqueryreg('HKEY_LOCAL_MACHINE','Software\MRS\MRSClinicalUI\Scan','BaseStorageDirectory');
                        surPath1 = strcat(surPath,filesep,scanTag,filesep);
                        surPath2 = strcat(surPath,filesep,scanTag,filesep,'1',filesep);
                        % Check if directory is actually writable
                        mkdir(strcat(surPath2,filesep,"testdir"));
                        rmdir(strcat(surPath2,filesep,"testdir"));
                        app.TextMessage('Image directory found in Windows registry ...');
                        surPathFound = true;
                    catch
                    end

                    % If not found automatically, try different way
                    if ~surPathFound
                        try
                            surPath = replace(app.mrdImportPath,"MRD","Data");
                            k = strfind(surPath,"Data");
                            fs = strfind(surPath(k:end),filesep);
                            surPath = surPath(1:k+fs(3)-1);
                            imageSlash = strcat("Image",filesep);
                            surPath1 = insertAfter(surPath,k+fs(2)-1,imageSlash);
                            surPath1 = strrep(surPath1,dblSlash,filesep);
                            surPath2 = strcat(surPath1,filesep,'1',filesep);
                            surPath2 = strrep(surPath2,dblSlash,filesep);
                            % Check if directory is actually writable
                            mkdir(strcat(surPath2,filesep,"testdir"));
                            rmdir(strcat(surPath2,filesep,"testdir"));
                            surPathFound = true;
                        catch
                        end
                    end

                    if surPathFound
                        try
                            surPath = surPath1;
                            if (exist(surPath2, 'dir'))
                                surPath = surPath2;
                            end

                            app.TextMessage(strcat("SUR directory = ",surPath));

                            % SUR file names
                            sursuffix = app.tag;

                            % Make and save the new SUR files
                            app.pd.recoSurFiles(surPath,sursuffix,mrdexportfilename,rprexportfilename);

                        catch ME1
                            app.TextMessage(ME1.message);
                            app.TextMessage('WARNING: Automatic reconstruction of SUR files failed ...');
                            app.SetStatus(1);
                        end
                    end

                end

                % Success
                app.TextMessage('MRD export successfully completed ...');
                app.SetLightsFnc({'Null','Null','Null','Null','Green','Null'});
                SetButtonsFnc(app,{'On','On','On','On','On','On','On'});
                app.SetStatus(0);

            catch ME

                % Failures
                app.TextMessage(ME.message);
                app.TextMessage('ERROR: Something went wrong during MRD export ...');
                app.mrdExportPath = '';
                app.SetLightsFnc({'Null','Null','Null','Null','Red','Null'});
                SetButtonsFnc(app,{'On','On','On','On','On','On','On'});
                app.SetStatus(2);

            end

        end

        % Button pushed function: ExportGIFButton
        function ExportGIFButtonPushed(app, event)

            if app.pd.validReco_flag

                % Set buttons
                %app.SwitchOffAllButtonsFnc;
                app.SetLightsFnc({'Null','Null','Null','Null','Null','Yellow'});
                app.SetStatus(3);

                % Indicate busy
                app.SetStatus(3);
                app.TextMessage('Exporting to GIF ...');

                % Make base / data / study / GIF / directory
                try

                    try
                        gifExportDir = replace(app.mrdImportPath, "MRD", "Data");
                        k = strfind(gifExportDir,"Data");
                        fs = strfind(gifExportDir(k:end),filesep);
                        gifExportDir = gifExportDir(1:k+fs(2)-1);
                        % Check if directory is actually writable
                        mkdir(strcat(gifExportDir,filesep,"testdir"));
                        rmdir(strcat(gifExportDir,filesep,"testdir"));
                    catch
                        app.P2ROUD_UIFigure.Visible = 'off';
                        gifExportDir = uigetdir(app.gifExportPath,'GIF export path');
                        app.P2ROUD_UIFigure.Visible = 'on';
                        drawnow;
                        if ~ischar(gifExportDir)
                            ME = MException('PROUD:userCancel','User cancel ...');
                            throw(ME);
                        end
                    end
                    app.gifExportPath = gifExportDir;

                    gifExportDir= exportGifProud(app);
                    app.pd.ExportRecoParametersFcn(app, gifExportDir);

                    app.TextMessage('GIF export finished ...');
                    app.SetStatus(0);
                    app.SetLightsFnc({'Null','Null','Null','Null','Null','Green'});
                    SetButtonsFnc(app,{'On','On','On','On','On','On','On'});

                catch ME

                    app.TextMessage('ERROR: Something went wrong during GIF export ...');
                    app.TextMessage(ME.message);
                    app.gifExportPath = '';
                    app.SetStatus(2);
                    app.SetLightsFnc({'Null','Null','Null','Null','Null','Red'});
                    SetButtonsFnc(app,{'On','On','On','On','On','On','On'});

                end

            end

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
         
            % Remove subfolders from search path
            if (~isdeployed)
                info = dir(which('P2ROUD.mlapp'));
                app.appDir = info.folder;
                rmpath(genpath(app.appDir));
                cd(app.oldDir);
            end

            % Exit
            app.delete;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.P2ROUD_UIFigure = uifigure('Visible', 'off');
            app.P2ROUD_UIFigure.Icon = fullfile(pathToMLAPP, 'Picon.gif');
            app.P2ROUD_UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @P2ROUD_UIFigureWindowScrollWheel, true);
            app.TitleLabel = uilabel(app.P2ROUD_UIFigure);
            app.TitleLabel.Text = 'P2ROUD';
            app.TulpImage = uiimage(app.P2ROUD_UIFigure);
            app.CreditsLabel = uilabel(app.P2ROUD_UIFigure);
            app.CreditsLabel.Tooltip = {'g.j.strijkers@amsterdamumc.nl'};
            app.CreditsLabel.Text = 'Gustav Strijkers, Bram Coolen, Amsterdam UMC, Version 5.0.0';
            app.MRSImage = uiimage(app.P2ROUD_UIFigure);
            app.P2ROUDLabel = uilabel(app.P2ROUD_UIFigure);
            app.P2ROUDLabel.Text = 'Preclinical PROspective Undersampling in multiple Dimensions';
            app.DashboardTabGroup = uitabgroup(app.P2ROUD_UIFigure);
            app.DashboardTab = uitab(app.DashboardTabGroup);
            app.DashboardTab.Title = 'Dashboard';
            app.LoadMRDButton = uibutton(app.DashboardTab, 'push');
            app.LoadMRDButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMRDButtonPushed, true);
            app.LoadMRDButton.Enable = 'off';
            app.LoadMRDButton.Tooltip = {'Load MRD data'};
            app.LoadMRDButton.Text = 'Load MRD';
            app.Lamp1 = uilamp(app.DashboardTab);
            app.RecoimagesButton = uibutton(app.DashboardTab, 'push');
            app.RecoimagesButton.ButtonPushedFcn = createCallbackFcn(app, @RecoImagesButtonPushed, true);
            app.RecoimagesButton.Enable = 'off';
            app.RecoimagesButton.Tooltip = {'Reconstruct the images'};
            app.RecoimagesButton.Text = 'Reco images';
            app.Lamp2 = uilamp(app.DashboardTab);
            app.ExitButton = uibutton(app.DashboardTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Tooltip = {'Exit the app'};
            app.ExitButton.Text = '    Exit';
            app.ReconstructiondimensionsPanel = uipanel(app.DashboardTab);
            app.ReconstructiondimensionsPanel.Tooltip = {'Reconstruction dimensions'};
            app.ReconstructiondimensionsPanel.Title = 'Reconstruction dimensions';
            app.XEditFieldLabel = uilabel(app.ReconstructiondimensionsPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.ReconstructiondimensionsPanel, 'numeric');
            app.XEditField.Limits = [16 1024];
            app.XEditField.RoundFractionalValues = 'on';
            app.XEditField.ValueDisplayFormat = '%.0f';
            app.XEditField.Tooltip = {'Readout dimension'};
            app.XEditField.Value = 128;
            app.YEditFieldLabel = uilabel(app.ReconstructiondimensionsPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.ReconstructiondimensionsPanel, 'numeric');
            app.YEditField.Limits = [16 1024];
            app.YEditField.RoundFractionalValues = 'on';
            app.YEditField.ValueDisplayFormat = '%.0f';
            app.YEditField.Tooltip = {'Phase encoding dimension'};
            app.YEditField.Value = 128;
            app.ZEditFieldLabel = uilabel(app.ReconstructiondimensionsPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.ReconstructiondimensionsPanel, 'numeric');
            app.ZEditField.Limits = [1 1024];
            app.ZEditField.RoundFractionalValues = 'on';
            app.ZEditField.ValueDisplayFormat = '%.0f';
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @RecoSizeFieldValueChanged, true);
            app.ZEditField.Tooltip = {'Slice dimension / 2nd phase-encoding dimension'};
            app.ZEditField.Value = 128;
            app.NREditFieldLabel_4 = uilabel(app.ReconstructiondimensionsPanel);
            app.NREditFieldLabel_4.Text = 'NR';
            app.NREditField = uieditfield(app.ReconstructiondimensionsPanel, 'numeric');
            app.NREditField.Limits = [1 1024];
            app.NREditField.RoundFractionalValues = 'on';
            app.NREditField.ValueDisplayFormat = '%.0f';
            app.NREditField.ValueChangedFcn = createCallbackFcn(app, @NREditFieldValueChanged, true);
            app.NREditField.Tooltip = {'Number of dynamics/repetitions'};
            app.NREditField.Value = 1;
            app.ReconstructionparametersPanel = uipanel(app.DashboardTab);
            app.ReconstructionparametersPanel.Tooltip = {'Reconstruction parameters'};
            app.ReconstructionparametersPanel.Title = 'Reconstruction parameters';
            app.TVxyzEditFieldLabel = uilabel(app.ReconstructionparametersPanel);
            app.TVxyzEditFieldLabel.Text = 'TVxyz';
            app.TVxyzEditField = uieditfield(app.ReconstructionparametersPanel, 'numeric');
            app.TVxyzEditField.Limits = [0 2];
            app.TVxyzEditField.ValueDisplayFormat = '%.4f';
            app.TVxyzEditField.Tooltip = {'Total Varation regularization in spatial domain'};
            app.TVtimeEditFieldLabel = uilabel(app.ReconstructionparametersPanel);
            app.TVtimeEditFieldLabel.Text = 'TVtime';
            app.TVtimeEditField = uieditfield(app.ReconstructionparametersPanel, 'numeric');
            app.TVtimeEditField.Limits = [0 2];
            app.TVtimeEditField.ValueDisplayFormat = '%.4f';
            app.TVtimeEditField.Tooltip = {'Total Variation regularization in time domain'};
            app.TVtimeEditField.Value = 0.001;
            app.WVxyzEditFieldLabel = uilabel(app.ReconstructionparametersPanel);
            app.WVxyzEditFieldLabel.Text = 'WVxyz';
            app.WVxyzEditField = uieditfield(app.ReconstructionparametersPanel, 'numeric');
            app.WVxyzEditField.Limits = [0 2];
            app.WVxyzEditField.ValueDisplayFormat = '%.4f';
            app.WVxyzEditField.Tooltip = {'L1 Wavelet regularization in spatial domain '};
            app.WVxyzEditField.Value = 0.001;
            app.CSRecoCheckBox = uicheckbox(app.ReconstructionparametersPanel);
            app.CSRecoCheckBox.ValueChangedFcn = createCallbackFcn(app, @CSRecoCheckBoxValueChanged, true);
            app.CSRecoCheckBox.Tooltip = {'Check for compressed sensing reconstruction'};
            app.CSRecoCheckBox.Text = '';
            app.CSRecoLabel = uilabel(app.ReconstructionparametersPanel);
            app.CSRecoLabel.Tooltip = {'Compressed-sensing reconstruction'};
            app.CSRecoLabel.Text = 'CS reco';
            app.LRxyzEditFieldLabel = uilabel(app.ReconstructionparametersPanel);
            app.LRxyzEditFieldLabel.Text = 'LRxyz';
            app.LRxyzEditField = uieditfield(app.ReconstructionparametersPanel, 'numeric');
            app.LRxyzEditField.Limits = [0 2];
            app.LRxyzEditField.ValueDisplayFormat = '%.4f';
            app.LRxyzEditField.Tooltip = {'Locally Low-rank regularization in spatial domain'};
            app.LRxyzEditField.Value = 0.001;
            app.DeNoiseCheckBox = uicheckbox(app.ReconstructionparametersPanel);
            app.DeNoiseCheckBox.Tooltip = {'Check for PCA denoising'};
            app.DeNoiseCheckBox.Text = '';
            app.DeNoiseLabel = uilabel(app.ReconstructionparametersPanel);
            app.DeNoiseLabel.Tooltip = {'PCA denoising'};
            app.DeNoiseLabel.Text = 'deNoise';
            app.DeNoiseWindowEditField = uieditfield(app.ReconstructionparametersPanel, 'numeric');
            app.DeNoiseWindowEditField.Limits = [2 64];
            app.DeNoiseWindowEditField.RoundFractionalValues = 'on';
            app.DeNoiseWindowEditField.ValueDisplayFormat = '%.0f';
            app.DeNoiseWindowEditField.Tooltip = {'PCA denoising filter window (pixels)'};
            app.DeNoiseWindowEditField.Value = 8;
            app.DeRingLabel = uilabel(app.ReconstructionparametersPanel);
            app.DeRingLabel.Tooltip = {'Gibbs ringing filter'};
            app.DeRingLabel.Text = 'deRing';
            app.DeRingCheckBox = uicheckbox(app.ReconstructionparametersPanel);
            app.DeRingCheckBox.Tooltip = {'Check for Gibbs ringing filter'};
            app.DeRingCheckBox.Text = '';
            app.AbortButton = uibutton(app.DashboardTab, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
            app.AbortButton.Enable = 'off';
            app.AbortButton.Tooltip = {'Abort the reconstruction'};
            app.AbortButton.Text = 'abort';
            app.RecoProgressGauge = uigauge(app.DashboardTab, 'linear');
            app.RecoProgressGauge.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95.1];
            app.KspaceTabGroup = uitabgroup(app.P2ROUD_UIFigure);
            app.KspaceTab = uitab(app.KspaceTabGroup);
            app.KspaceTab.Title = 'K-space';
            app.KspaceFig = uiaxes(app.KspaceTab);
            app.KspaceFig.XTick = [];
            app.KspaceFig.YTick = [];
            app.KDim3EditField = uieditfield(app.KspaceTab, 'numeric');
            app.KDim3EditField.Limits = [1 Inf];
            app.KDim3EditField.ValueDisplayFormat = '%.0f';
            app.KDim3EditField.ValueChangedFcn = createCallbackFcn(app, @KDim3EditFieldValueChanged, true);
            app.KDim3EditField.Value = 1;
            app.KDim3Slider = uislider(app.KspaceTab);
            app.KDim3Slider.Limits = [1 200];
            app.KDim3Slider.MajorTicks = [];
            app.KDim3Slider.ValueChangedFcn = createCallbackFcn(app, @KDim3SliderValueChanged, true);
            app.KDim3Slider.MinorTicks = [];
            app.KDim3Slider.Value = 1;
            app.KDim4Slider = uislider(app.KspaceTab);
            app.KDim4Slider.Limits = [1 200];
            app.KDim4Slider.MajorTicks = [];
            app.KDim4Slider.ValueChangedFcn = createCallbackFcn(app, @KDim4SliderValueChanged, true);
            app.KDim4Slider.MinorTicks = [];
            app.KDim4Slider.Value = 1;
            app.KDim4EditField = uieditfield(app.KspaceTab, 'numeric');
            app.KDim4EditField.Limits = [1 Inf];
            app.KDim4EditField.ValueDisplayFormat = '%.0f';
            app.KDim4EditField.ValueChangedFcn = createCallbackFcn(app, @KDim4EditFieldValueChanged, true);
            app.KDim4EditField.Value = 1;
            app.KxLabel = uilabel(app.KspaceTab);
            app.KxLabel.Text = '     Kx     ';
            app.KyLabel = uilabel(app.KspaceTab);
            app.KyLabel.Text = '     Ky     ';
            app.ButtonGroup = uibuttongroup(app.KspaceTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.kvaluesButton = uiradiobutton(app.ButtonGroup);
            app.kvaluesButton.Tooltip = {'Check to display K-space'};
            app.kvaluesButton.Text = '| k-values |';
            app.kvaluesButton.Value = true;
            app.averagesButton = uiradiobutton(app.ButtonGroup);
            app.averagesButton.Tooltip = {'Check to display K-space averages'};
            app.averagesButton.Text = 'averages';
            app.fillingButton = uiradiobutton(app.ButtonGroup);
            app.fillingButton.Tooltip = {'Check to display K-space filling'};
            app.fillingButton.Text = 'filling';
            app.senseMapButton = uiradiobutton(app.ButtonGroup);
            app.senseMapButton.Tooltip = {'Check to display K-space'};
            app.senseMapButton.Text = 'sense map';
            app.KDim3Label = uilabel(app.KspaceTab);
            app.KDim3Label.Text = '            Kz';
            app.KEchoSlider = uislider(app.KspaceTab);
            app.KEchoSlider.Limits = [1 200];
            app.KEchoSlider.MajorTicks = [];
            app.KEchoSlider.ValueChangedFcn = createCallbackFcn(app, @KEchoSliderValueChanged, true);
            app.KEchoSlider.MinorTicks = [];
            app.KEchoSlider.Value = 1;
            app.KDim4Label = uilabel(app.KspaceTab);
            app.KDim4Label.Text = '             NR';
            app.KFlipAngleSlider = uislider(app.KspaceTab);
            app.KFlipAngleSlider.Limits = [1 200];
            app.KFlipAngleSlider.MajorTicks = [];
            app.KFlipAngleSlider.ValueChangedFcn = createCallbackFcn(app, @KFlipAngleSliderValueChanged, true);
            app.KFlipAngleSlider.MinorTicks = [];
            app.KFlipAngleSlider.Value = 1;
            app.FillingViewField = uieditfield(app.KspaceTab, 'numeric');
            app.FillingViewField.Editable = 'off';
            app.FillingViewField.Tooltip = {'K-space filling percentage'};
            app.FillingPercLabel = uilabel(app.KspaceTab);
            app.FillingPercLabel.Text = '%';
            app.KStartButton = uibutton(app.KspaceTab, 'push');
            app.KStartButton.ButtonPushedFcn = createCallbackFcn(app, @KStartButtonPushed, true);
            app.KStartButton.Tooltip = {'Start the K-space trajectory movie'};
            app.KStartButton.Text = 'Start';
            app.KStopButton = uibutton(app.KspaceTab, 'push');
            app.KStopButton.ButtonPushedFcn = createCallbackFcn(app, @KStopButtonPushed, true);
            app.KStopButton.Tooltip = {'Stop the K-space trajectory movie'};
            app.KStopButton.Text = 'Stop';
            app.KspacemovieLabel = uilabel(app.KspaceTab);
            app.KspacemovieLabel.Text = {'K-space'; 'movie'};
            app.FastSlowSwitch = uiswitch(app.KspaceTab, 'rocker');
            app.FastSlowSwitch.Items = {'fast', 'slow'};
            app.FastSlowSwitch.ValueChangedFcn = createCallbackFcn(app, @FastSlowSwitchValueChanged, true);
            app.FastSlowSwitch.Tooltip = {'Slow or fast K-space movie'};
            app.FastSlowSwitch.Value = 'fast';
            app.KLevelSlider = uislider(app.KspaceTab);
            app.KLevelSlider.Limits = [0 65536];
            app.KLevelSlider.MajorTicks = [];
            app.KLevelSlider.ValueChangedFcn = createCallbackFcn(app, @KLevelSliderValueChanged, true);
            app.KLevelSlider.MinorTicks = [];
            app.KLevelSlider.Tooltip = {'Level slider'};
            app.KWLabel = uilabel(app.KspaceTab);
            app.KWLabel.Text = 'W';
            app.KLLabel = uilabel(app.KspaceTab);
            app.KLLabel.Text = 'L';
            app.KWindowSlider = uislider(app.KspaceTab);
            app.KWindowSlider.Limits = [0 65536];
            app.KWindowSlider.MajorTicks = [];
            app.KWindowSlider.ValueChangedFcn = createCallbackFcn(app, @KWindowSliderValueChanged, true);
            app.KWindowSlider.MinorTicks = [];
            app.KWindowSlider.Tooltip = {'Window slider'};
            app.KLevelEditField = uieditfield(app.KspaceTab, 'numeric');
            app.KLevelEditField.Limits = [0 Inf];
            app.KLevelEditField.ValueDisplayFormat = '%.0f';
            app.KLevelEditField.ValueChangedFcn = createCallbackFcn(app, @KLevelEditFieldValueChanged, true);
            app.KLevelEditField.Tooltip = {'Level'};
            app.KWindowEditField = uieditfield(app.KspaceTab, 'numeric');
            app.KWindowEditField.Limits = [0 Inf];
            app.KWindowEditField.ValueDisplayFormat = '%.0f';
            app.KWindowEditField.ValueChangedFcn = createCallbackFcn(app, @KWindowEditFieldValueChanged, true);
            app.KWindowEditField.Tooltip = {'Window'};
            app.EchoLabel = uilabel(app.KspaceTab);
            app.EchoLabel.Text = 'echo';
            app.KEchoEditField = uieditfield(app.KspaceTab, 'numeric');
            app.KEchoEditField.Limits = [1 Inf];
            app.KEchoEditField.ValueDisplayFormat = '%.0f';
            app.KEchoEditField.ValueChangedFcn = createCallbackFcn(app, @KEchoEditFieldValueChanged, true);
            app.KEchoEditField.Value = 1;
            app.orientationLabel = uilabel(app.KspaceTab);
            app.orientationLabel.Text = 'orientation';
            app.KOrientationSpinner = uispinner(app.KspaceTab);
            app.KOrientationSpinner.Limits = [1 6];
            app.KOrientationSpinner.ValueChangedFcn = createCallbackFcn(app, @KOrientationSpinnerValueChanged, true);
            app.KOrientationSpinner.Tooltip = {'Image orientation'};
            app.KOrientationSpinner.Value = 1;
            app.FALabel = uilabel(app.KspaceTab);
            app.FALabel.Text = 'FA';
            app.KFlipAngleEditField = uieditfield(app.KspaceTab, 'numeric');
            app.KFlipAngleEditField.Limits = [1 Inf];
            app.KFlipAngleEditField.ValueDisplayFormat = '%.0f';
            app.KFlipAngleEditField.ValueChangedFcn = createCallbackFcn(app, @KFlipAngleEditFieldValueChanged, true);
            app.KFlipAngleEditField.Value = 1;
            app.CoilSpinnerLabel = uilabel(app.KspaceTab);
            app.CoilSpinnerLabel.Text = 'Coil';
            app.CoilSpinner = uispinner(app.KspaceTab);
            app.CoilSpinner.Limits = [1 16];
            app.CoilSpinner.ValueDisplayFormat = '%.0f';
            app.CoilSpinner.ValueChangedFcn = createCallbackFcn(app, @CoilSpinnerValueChanged, true);
            app.CoilSpinner.Tooltip = {'Receiver coil'};
            app.CoilSpinner.Value = 1;
            app.CoilsTab = uitab(app.KspaceTabGroup);
            app.CoilsTab.Title = 'Coils';
            app.CoilSelectionPanel = uipanel(app.CoilsTab);
            app.CoilSelectionPanel.Tooltip = {'Receiver coil selection and relative sensitivities'};
            app.CoilSelectionPanel.Title = 'Coil selection';
            app.C1CheckBox = uicheckbox(app.CoilSelectionPanel);
            app.C1CheckBox.ValueChangedFcn = createCallbackFcn(app, @CoilCheckBoxValueChanged, true);
            app.C1CheckBox.Text = ' 1';
            app.C1CheckBox.Value = true;
            app.C2CheckBox = uicheckbox(app.CoilSelectionPanel);
            app.C2CheckBox.ValueChangedFcn = createCallbackFcn(app, @CoilCheckBoxValueChanged, true);
            app.C2CheckBox.Text = ' 2';
            app.C3CheckBox = uicheckbox(app.CoilSelectionPanel);
            app.C3CheckBox.ValueChangedFcn = createCallbackFcn(app, @CoilCheckBoxValueChanged, true);
            app.C3CheckBox.Text = ' 3';
            app.C4CheckBox = uicheckbox(app.CoilSelectionPanel);
            app.C4CheckBox.ValueChangedFcn = createCallbackFcn(app, @CoilCheckBoxValueChanged, true);
            app.C4CheckBox.Text = ' 4';
            app.C5CheckBox = uicheckbox(app.CoilSelectionPanel);
            app.C5CheckBox.ValueChangedFcn = createCallbackFcn(app, @CoilCheckBoxValueChanged, true);
            app.C5CheckBox.Text = ' 5';
            app.C6CheckBox = uicheckbox(app.CoilSelectionPanel);
            app.C6CheckBox.ValueChangedFcn = createCallbackFcn(app, @CoilCheckBoxValueChanged, true);
            app.C6CheckBox.Text = ' 6';
            app.C7CheckBox = uicheckbox(app.CoilSelectionPanel);
            app.C7CheckBox.ValueChangedFcn = createCallbackFcn(app, @CoilCheckBoxValueChanged, true);
            app.C7CheckBox.Text = ' 7';
            app.C8CheckBox = uicheckbox(app.CoilSelectionPanel);
            app.C8CheckBox.ValueChangedFcn = createCallbackFcn(app, @CoilCheckBoxValueChanged, true);
            app.C8CheckBox.Text = ' 8';
            app.S1EditField = uieditfield(app.CoilSelectionPanel, 'numeric');
            app.S1EditField.Limits = [0 10];
            app.S1EditField.ValueDisplayFormat = '%.3f';
            app.S1EditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityEditFieldValueChanged, true);
            app.S1EditField.Value = 1;
            app.CoilLabel = uilabel(app.CoilSelectionPanel);
            app.CoilLabel.Text = 'Coil';
            app.S2EditField = uieditfield(app.CoilSelectionPanel, 'numeric');
            app.S2EditField.Limits = [0 10];
            app.S2EditField.ValueDisplayFormat = '%.3f';
            app.S2EditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityEditFieldValueChanged, true);
            app.S2EditField.Value = 1;
            app.RelativesensitivityLabel = uilabel(app.CoilSelectionPanel);
            app.RelativesensitivityLabel.Text = 'Relative sensitivity';
            app.S3EditField = uieditfield(app.CoilSelectionPanel, 'numeric');
            app.S3EditField.Limits = [0 10];
            app.S3EditField.ValueDisplayFormat = '%.3f';
            app.S3EditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityEditFieldValueChanged, true);
            app.S3EditField.Value = 1;
            app.S4EditField = uieditfield(app.CoilSelectionPanel, 'numeric');
            app.S4EditField.Limits = [0 10];
            app.S4EditField.ValueDisplayFormat = '%.3f';
            app.S4EditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityEditFieldValueChanged, true);
            app.S4EditField.Value = 1;
            app.S5EditField = uieditfield(app.CoilSelectionPanel, 'numeric');
            app.S5EditField.Limits = [0 10];
            app.S5EditField.ValueDisplayFormat = '%.3f';
            app.S5EditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityEditFieldValueChanged, true);
            app.S5EditField.Value = 1;
            app.S6EditField = uieditfield(app.CoilSelectionPanel, 'numeric');
            app.S6EditField.Limits = [0 10];
            app.S6EditField.ValueDisplayFormat = '%.3f';
            app.S6EditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityEditFieldValueChanged, true);
            app.S6EditField.Value = 1;
            app.S7EditField = uieditfield(app.CoilSelectionPanel, 'numeric');
            app.S7EditField.Limits = [0 10];
            app.S7EditField.ValueDisplayFormat = '%.3f';
            app.S7EditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityEditFieldValueChanged, true);
            app.S7EditField.Value = 1;
            app.S8EditField = uieditfield(app.CoilSelectionPanel, 'numeric');
            app.S8EditField.Limits = [0 10];
            app.S8EditField.ValueDisplayFormat = '%.3f';
            app.S8EditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityEditFieldValueChanged, true);
            app.S8EditField.Value = 1;
            app.AutoSensitivityCheckBox = uicheckbox(app.CoilSelectionPanel);
            app.AutoSensitivityCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoSensitivityCheckBoxValueChanged, true);
            app.AutoSensitivityCheckBox.Tooltip = {'Automatically determine coil sensitivities (requires Bart toolbox)'};
            app.AutoSensitivityCheckBox.Text = ' auto sensitivity';
            app.AutoSensitivityCheckBox.Value = true;
            app.SensitivityMapsPanel = uipanel(app.CoilsTab);
            app.SensitivityMapsPanel.Tooltip = {'Receiver coil selection and relative sensitivities'};
            app.SensitivityMapsPanel.Title = 'Sensitivity maps';
            app.SenseFig1 = uiaxes(app.SensitivityMapsPanel);
            app.SenseFig1.XTick = [];
            app.SenseFig1.YTick = [];
            app.SenseFig2 = uiaxes(app.SensitivityMapsPanel);
            app.SenseFig2.XTick = [];
            app.SenseFig2.YTick = [];
            app.SenseFig3 = uiaxes(app.SensitivityMapsPanel);
            app.SenseFig3.XTick = [];
            app.SenseFig3.YTick = [];
            app.SenseFig4 = uiaxes(app.SensitivityMapsPanel);
            app.SenseFig4.XTick = [];
            app.SenseFig4.YTick = [];
            app.SenseFig5 = uiaxes(app.SensitivityMapsPanel);
            app.SenseFig5.XTick = [];
            app.SenseFig5.YTick = [];
            app.SenseFig6 = uiaxes(app.SensitivityMapsPanel);
            app.SenseFig6.XTick = [];
            app.SenseFig6.YTick = [];
            app.SenseFig7 = uiaxes(app.SensitivityMapsPanel);
            app.SenseFig7.XTick = [];
            app.SenseFig7.YTick = [];
            app.SenseFig8 = uiaxes(app.SensitivityMapsPanel);
            app.SenseFig8.XTick = [];
            app.SenseFig8.YTick = [];
            app.Label = uilabel(app.SensitivityMapsPanel);
            app.Label.Text = '1';
            app.Label_2 = uilabel(app.SensitivityMapsPanel);
            app.Label_2.Text = '2';
            app.Label_3 = uilabel(app.SensitivityMapsPanel);
            app.Label_3.Text = '3';
            app.Label_4 = uilabel(app.SensitivityMapsPanel);
            app.Label_4.Text = '4';
            app.Label_5 = uilabel(app.SensitivityMapsPanel);
            app.Label_5.Text = '5';
            app.Label_6 = uilabel(app.SensitivityMapsPanel);
            app.Label_6.Text = '6';
            app.Label_7 = uilabel(app.SensitivityMapsPanel);
            app.Label_7.Text = '7';
            app.Label_8 = uilabel(app.SensitivityMapsPanel);
            app.Label_8.Text = '8';
            app.RadialTab = uitab(app.KspaceTabGroup);
            app.RadialTab.Title = 'Radial';
            app.GradientdelaysPanel = uipanel(app.RadialTab);
            app.GradientdelaysPanel.Title = 'Gradient delays';
            app.GradDelayCalibrationCheckBox = uicheckbox(app.GradientdelaysPanel);
            app.GradDelayCalibrationCheckBox.Enable = 'off';
            app.GradDelayCalibrationCheckBox.Tooltip = {'Automatic calibration of gradient delays'};
            app.GradDelayCalibrationCheckBox.Text = '';
            app.GradDelayCalibrationLabel = uilabel(app.GradientdelaysPanel);
            app.GradDelayCalibrationLabel.Tooltip = {'Automatic calibration of gradient delays'};
            app.GradDelayCalibrationLabel.Text = 'calibration';
            app.RingMethodCheckBox = uicheckbox(app.GradientdelaysPanel);
            app.RingMethodCheckBox.Enable = 'off';
            app.RingMethodCheckBox.Tooltip = {'Ring method calibration [Rosenzweig et al., MRM 81:1898-1906 (2019)]'};
            app.RingMethodCheckBox.Text = '';
            app.RingMethodLabel = uilabel(app.GradientdelaysPanel);
            app.RingMethodLabel.Tooltip = {'Ring method calibration [Rosenzweig et al., MRM 81:1898-1906 (2019)]'};
            app.RingMethodLabel.Text = 'ring method';
            app.AbortGradCalButton = uibutton(app.GradientdelaysPanel, 'push');
            app.AbortGradCalButton.ButtonPushedFcn = createCallbackFcn(app, @AbortGradCalButtonPushed, true);
            app.AbortGradCalButton.Enable = 'off';
            app.AbortGradCalButton.Tooltip = {'Abort gradient calibration'};
            app.AbortGradCalButton.Text = 'abort';
            app.GxdelayEditFieldLabel = uilabel(app.GradientdelaysPanel);
            app.GxdelayEditFieldLabel.Text = 'Gx delay';
            app.GxDelayEditField = uieditfield(app.GradientdelaysPanel, 'numeric');
            app.GxDelayEditField.Limits = [-10 10];
            app.GxDelayEditField.ValueDisplayFormat = '%.5f';
            app.GxDelayEditField.Enable = 'off';
            app.GxDelayEditField.Tooltip = {'X-gradient delay'};
            app.GydelayEditFieldLabel = uilabel(app.GradientdelaysPanel);
            app.GydelayEditFieldLabel.Text = 'Gy delay';
            app.GyDelayEditField = uieditfield(app.GradientdelaysPanel, 'numeric');
            app.GyDelayEditField.Limits = [-10 10];
            app.GyDelayEditField.ValueDisplayFormat = '%.5f';
            app.GyDelayEditField.Enable = 'off';
            app.GyDelayEditField.Tooltip = {'Y-gradient delay'};
            app.GzdelayEditFieldLabel = uilabel(app.GradientdelaysPanel);
            app.GzdelayEditFieldLabel.Text = 'Gz delay';
            app.GzDelayEditField = uieditfield(app.GradientdelaysPanel, 'numeric');
            app.GzDelayEditField.Limits = [-10 10];
            app.GzDelayEditField.ValueDisplayFormat = '%.5f';
            app.GzDelayEditField.Enable = 'off';
            app.GzDelayEditField.Tooltip = {'Z-gradient delay'};
            app.dataoffsetLabel = uilabel(app.GradientdelaysPanel);
            app.dataoffsetLabel.Tooltip = {'Number of discarded datapoints at the beginning of UTE readout'};
            app.dataoffsetLabel.Text = 'data offset';
            app.DataOffsetRadialEditField = uieditfield(app.GradientdelaysPanel, 'numeric');
            app.DataOffsetRadialEditField.Limits = [0 10];
            app.DataOffsetRadialEditField.ValueDisplayFormat = '%.0f';
            app.DataOffsetRadialEditField.Enable = 'off';
            app.DataOffsetRadialEditField.Tooltip = {'Number of discarded datapoints at the beginning of UTE readout'};
            app.CorrectionsPanel = uipanel(app.RadialTab);
            app.CorrectionsPanel.Title = 'Corrections';
            app.PhaseCorrectCheckBox = uicheckbox(app.CorrectionsPanel);
            app.PhaseCorrectCheckBox.Enable = 'off';
            app.PhaseCorrectCheckBox.Tooltip = {'Check for phase correction'};
            app.PhaseCorrectCheckBox.Text = '';
            app.PhaseCorrectCheckBox.Value = true;
            app.PhaseCorrectLabel = uilabel(app.CorrectionsPanel);
            app.PhaseCorrectLabel.Tooltip = {'Check for phase correction'};
            app.PhaseCorrectLabel.Text = 'phase correction';
            app.CenterEchoCheckBox = uicheckbox(app.CorrectionsPanel);
            app.CenterEchoCheckBox.Enable = 'off';
            app.CenterEchoCheckBox.Tooltip = {'Check to center the echo'};
            app.CenterEchoCheckBox.Text = '';
            app.CenterEchoCheckBox.Value = true;
            app.CenterEchoLabel = uilabel(app.CorrectionsPanel);
            app.CenterEchoLabel.Tooltip = {'Check to center the echo'};
            app.CenterEchoLabel.Text = 'center echo';
            app.DensityCorrectCheckBox = uicheckbox(app.CorrectionsPanel);
            app.DensityCorrectCheckBox.Enable = 'off';
            app.DensityCorrectCheckBox.Tooltip = {'Check for density correction'};
            app.DensityCorrectCheckBox.Text = '';
            app.DensityCorrectLabel = uilabel(app.CorrectionsPanel);
            app.DensityCorrectLabel.Tooltip = {'Check for density correction'};
            app.DensityCorrectLabel.Text = 'density correction';
            app.ScanParameterTabGroup = uitabgroup(app.P2ROUD_UIFigure);
            app.ScanParametersTab = uitab(app.ScanParameterTabGroup);
            app.ScanParametersTab.Title = 'Scan parameters';
            app.KMatrixViewField2 = uieditfield(app.ScanParametersTab, 'numeric');
            app.KMatrixViewField2.Limits = [1 Inf];
            app.KMatrixViewField2.ValueDisplayFormat = '%.0f';
            app.KMatrixViewField2.Editable = 'off';
            app.KMatrixViewField2.Tooltip = {'Phase-encoding matrix size'};
            app.KMatrixViewField2.Value = 1;
            app.KMatrixViewField3 = uieditfield(app.ScanParametersTab, 'numeric');
            app.KMatrixViewField3.Limits = [1 Inf];
            app.KMatrixViewField3.ValueDisplayFormat = '%.0f';
            app.KMatrixViewField3.Editable = 'off';
            app.KMatrixViewField3.Tooltip = {'Number of slices / 2nd phase-encoding matrix size'};
            app.KMatrixViewField3.Value = 1;
            app.FOVViewField2 = uieditfield(app.ScanParametersTab, 'numeric');
            app.FOVViewField2.Limits = [0 Inf];
            app.FOVViewField2.ValueDisplayFormat = '%.1f';
            app.FOVViewField2.Editable = 'off';
            app.FOVViewField2.Tooltip = {'FOV in phas-encoding direction'};
            app.FOVViewField3 = uieditfield(app.ScanParametersTab, 'numeric');
            app.FOVViewField3.Limits = [0 Inf];
            app.FOVViewField3.ValueDisplayFormat = '%.1f';
            app.FOVViewField3.Editable = 'off';
            app.FOVViewField3.Tooltip = {'Slice thickness / FOV in 2nd phase-encoding direction'};
            app.MXLabel = uilabel(app.ScanParametersTab);
            app.MXLabel.Text = 'Kx';
            app.MYLabel = uilabel(app.ScanParametersTab);
            app.MYLabel.Text = 'Ky';
            app.MZLabel = uilabel(app.ScanParametersTab);
            app.MZLabel.Text = 'Kz';
            app.NREditFieldLabel_3 = uilabel(app.ScanParametersTab);
            app.NREditFieldLabel_3.Text = 'NR';
            app.NRViewField = uieditfield(app.ScanParametersTab, 'numeric');
            app.NRViewField.Limits = [0 Inf];
            app.NRViewField.ValueDisplayFormat = '%.0f';
            app.NRViewField.Editable = 'off';
            app.NRViewField.Tooltip = {'Number of dynamics/repetitions'};
            app.sequenceLabel = uilabel(app.ScanParametersTab);
            app.sequenceLabel.Text = 'sequence';
            app.SequenceViewField = uieditfield(app.ScanParametersTab, 'text');
            app.SequenceViewField.Editable = 'off';
            app.SequenceViewField.Tooltip = {'MRI sequence name'};
            app.matrixLabel = uilabel(app.ScanParametersTab);
            app.matrixLabel.Text = 'matrix';
            app.KMatrixViewField1 = uieditfield(app.ScanParametersTab, 'numeric');
            app.KMatrixViewField1.Limits = [1 Inf];
            app.KMatrixViewField1.ValueDisplayFormat = '%.0f';
            app.KMatrixViewField1.Editable = 'off';
            app.KMatrixViewField1.Tooltip = {'Readout matrix size'};
            app.KMatrixViewField1.Value = 1;
            app.FOVmmLabel = uilabel(app.ScanParametersTab);
            app.FOVmmLabel.Text = 'FOV (mm)';
            app.FOVViewField1 = uieditfield(app.ScanParametersTab, 'numeric');
            app.FOVViewField1.ValueDisplayFormat = '%.1f';
            app.FOVViewField1.Editable = 'off';
            app.FOVViewField1.Tooltip = {'FOV in readout direction'};
            app.TRmsLabel = uilabel(app.ScanParametersTab);
            app.TRmsLabel.Text = 'TR (ms)';
            app.TRViewField = uieditfield(app.ScanParametersTab, 'numeric');
            app.TRViewField.ValueDisplayFormat = '%.2f';
            app.TRViewField.Editable = 'off';
            app.TRViewField.Tooltip = {'Repetition time'};
            app.TEmsLabel = uilabel(app.ScanParametersTab);
            app.TEmsLabel.Text = 'TE (ms)';
            app.TEViewField = uieditfield(app.ScanParametersTab, 'numeric');
            app.TEViewField.ValueDisplayFormat = '%.2f';
            app.TEViewField.Editable = 'off';
            app.TEViewField.Tooltip = {'Echo time'};
            app.NALabel = uilabel(app.ScanParametersTab);
            app.NALabel.Text = 'NA';
            app.NAViewField = uieditfield(app.ScanParametersTab, 'numeric');
            app.NAViewField.Editable = 'off';
            app.NAViewField.Tooltip = {'Number of averages'};
            app.NFALabel = uilabel(app.ScanParametersTab);
            app.NFALabel.Text = 'NFA';
            app.NFAViewField = uieditfield(app.ScanParametersTab, 'numeric');
            app.NFAViewField.Editable = 'off';
            app.NFAViewField.Tooltip = {'Number of flip-angles'};
            app.NEEditFieldLabel = uilabel(app.ScanParametersTab);
            app.NEEditFieldLabel.Text = 'NE';
            app.NEViewField = uieditfield(app.ScanParametersTab, 'numeric');
            app.NEViewField.Editable = 'off';
            app.NEViewField.Tooltip = {'Number of echoes'};
            app.MRDfileLabel = uilabel(app.ScanParametersTab);
            app.MRDfileLabel.Text = 'MRD file';
            app.MRDfileViewField = uieditfield(app.ScanParametersTab, 'text');
            app.MRDfileViewField.Editable = 'off';
            app.MRDfileViewField.Tooltip = {'MRD file name'};
            app.FAdegEditFieldLabel = uilabel(app.ScanParametersTab);
            app.FAdegEditFieldLabel.Text = 'FA (deg)';
            app.FAViewField = uieditfield(app.ScanParametersTab, 'text');
            app.FAViewField.Editable = 'off';
            app.FAViewField.Tooltip = {'Flip angle(s)'};
            app.TrajectoryLabel = uilabel(app.ScanParametersTab);
            app.TrajectoryLabel.Text = 'trajectory';
            app.TrajectoryViewField = uieditfield(app.ScanParametersTab, 'text');
            app.TrajectoryViewField.Editable = 'off';
            app.TrajectoryViewField.Tooltip = {'Trajectory file'};
            app.scantimeLabel = uilabel(app.ScanParametersTab);
            app.scantimeLabel.Text = 'scan time';
            app.ScanTimeViewField = uieditfield(app.ScanParametersTab, 'text');
            app.ScanTimeViewField.Editable = 'off';
            app.ScanTimeViewField.Tooltip = {'Scan time'};
            app.TimeNRLabel = uilabel(app.ScanParametersTab);
            app.TimeNRLabel.Text = 'time / dyn';
            app.TimeDynViewField = uieditfield(app.ScanParametersTab, 'text');
            app.TimeDynViewField.Editable = 'off';
            app.TimeDynViewField.Tooltip = {'Time per dynamic'};
            app.VencLabel = uilabel(app.ScanParametersTab);
            app.VencLabel.Text = 'Venc (DAC)';
            app.VencViewField = uieditfield(app.ScanParametersTab, 'text');
            app.VencViewField.Editable = 'off';
            app.VencViewField.Tooltip = {'Flow encoding Venc'};
            app.GeometryTab = uitab(app.ScanParameterTabGroup);
            app.GeometryTab.Title = 'Geometry';
            app.FOVLabel = uilabel(app.GeometryTab);
            app.FOVLabel.Tooltip = {'Field of view'};
            app.FOVLabel.Text = 'FOV (mm)';
            app.FieldOfViewViewField2 = uieditfield(app.GeometryTab, 'numeric');
            app.FieldOfViewViewField2.Limits = [0 Inf];
            app.FieldOfViewViewField2.ValueDisplayFormat = '%.2f';
            app.FieldOfViewViewField2.Editable = 'off';
            app.FieldOfViewViewField2.Tooltip = {'Field of view'};
            app.FOVLabel_2 = uilabel(app.GeometryTab);
            app.FOVLabel_2.Tooltip = {'Aspect ratio'};
            app.FOVLabel_2.Text = 'aspect ratio';
            app.AspectRatioViewField = uieditfield(app.GeometryTab, 'numeric');
            app.AspectRatioViewField.Limits = [0 Inf];
            app.AspectRatioViewField.ValueDisplayFormat = '%.2f';
            app.AspectRatioViewField.Editable = 'off';
            app.AspectRatioViewField.Tooltip = {'Aspect ratio'};
            app.FOVLabel_3 = uilabel(app.GeometryTab);
            app.FOVLabel_3.Tooltip = {'Phase orientation'};
            app.FOVLabel_3.Text = 'phase orientation';
            app.PhaseOrientationViewField = uieditfield(app.GeometryTab, 'numeric');
            app.PhaseOrientationViewField.Limits = [0 Inf];
            app.PhaseOrientationViewField.ValueDisplayFormat = '%.0f';
            app.PhaseOrientationViewField.Editable = 'off';
            app.PhaseOrientationViewField.Tooltip = {'Phase orientation, horizontal (1) or vertical (0)'};
            app.FOVLabel_4 = uilabel(app.GeometryTab);
            app.FOVLabel_4.Tooltip = {'Slice thickness'};
            app.FOVLabel_4.Text = 'slice thickness (mm)';
            app.SliceThicknessViewField = uieditfield(app.GeometryTab, 'numeric');
            app.SliceThicknessViewField.Limits = [0 Inf];
            app.SliceThicknessViewField.ValueDisplayFormat = '%.2f';
            app.SliceThicknessViewField.Editable = 'off';
            app.SliceThicknessViewField.Tooltip = {'Slice thickness'};
            app.FOVLabel_5 = uilabel(app.GeometryTab);
            app.FOVLabel_5.Tooltip = {'X offset'};
            app.FOVLabel_5.Text = 'offset X (mm)';
            app.OffsetXViewField = uieditfield(app.GeometryTab, 'numeric');
            app.OffsetXViewField.ValueDisplayFormat = '%.2f';
            app.OffsetXViewField.Editable = 'off';
            app.OffsetXViewField.Tooltip = {'X offset'};
            app.FOVLabel_6 = uilabel(app.GeometryTab);
            app.FOVLabel_6.Tooltip = {'Y offset'};
            app.FOVLabel_6.Text = 'offset Y (mm)';
            app.OffsetYViewField = uieditfield(app.GeometryTab, 'numeric');
            app.OffsetYViewField.ValueDisplayFormat = '%.2f';
            app.OffsetYViewField.Editable = 'off';
            app.OffsetYViewField.Tooltip = {'Y offset'};
            app.OffsetZViewField = uieditfield(app.GeometryTab, 'numeric');
            app.OffsetZViewField.ValueDisplayFormat = '%.2f';
            app.OffsetZViewField.Editable = 'off';
            app.OffsetZViewField.Tooltip = {'Z offset'};
            app.FOVLabel_7 = uilabel(app.GeometryTab);
            app.FOVLabel_7.Tooltip = {'Z offset'};
            app.FOVLabel_7.Text = 'offset Z (mm)';
            app.AngleXViewField = uieditfield(app.GeometryTab, 'numeric');
            app.AngleXViewField.ValueDisplayFormat = '%.0f';
            app.AngleXViewField.Editable = 'off';
            app.AngleXViewField.Tooltip = {'X angle'};
            app.FOVLabel_8 = uilabel(app.GeometryTab);
            app.FOVLabel_8.Tooltip = {'X angle'};
            app.FOVLabel_8.Text = 'angle X (deg)';
            app.AngleYViewField = uieditfield(app.GeometryTab, 'numeric');
            app.AngleYViewField.ValueDisplayFormat = '%.0f';
            app.AngleYViewField.Editable = 'off';
            app.AngleYViewField.Tooltip = {'Y angle'};
            app.FOVLabel_9 = uilabel(app.GeometryTab);
            app.FOVLabel_9.Tooltip = {'Y angle'};
            app.FOVLabel_9.Text = 'angle Y (deg)';
            app.AngleZViewField = uieditfield(app.GeometryTab, 'numeric');
            app.AngleZViewField.ValueDisplayFormat = '%.0f';
            app.AngleZViewField.Editable = 'off';
            app.AngleZViewField.Tooltip = {'Z angle'};
            app.FOVLabel_10 = uilabel(app.GeometryTab);
            app.FOVLabel_10.Tooltip = {'Z angle'};
            app.FOVLabel_10.Text = 'angle Z (deg)';
            app.FOVLabel_11 = uilabel(app.GeometryTab);
            app.FOVLabel_11.Tooltip = {'Slice gap'};
            app.FOVLabel_11.Text = 'slice gap (mm)';
            app.SliceGapViewField = uieditfield(app.GeometryTab, 'numeric');
            app.SliceGapViewField.ValueDisplayFormat = '%.2f';
            app.SliceGapViewField.Editable = 'off';
            app.SliceGapViewField.Tooltip = {'Slice gap'};
            app.FOVLabel_12 = uilabel(app.GeometryTab);
            app.FOVLabel_12.Tooltip = {'Slab overlap (pixels)'};
            app.FOVLabel_12.Text = 'slab overlap (pixels)';
            app.SlabOverlapEditField = uieditfield(app.GeometryTab, 'numeric');
            app.SlabOverlapEditField.Limits = [0 Inf];
            app.SlabOverlapEditField.ValueDisplayFormat = '%.0f';
            app.SlabOverlapEditField.Tooltip = {'Slab overlap (pixels)'};
            app.SlabOverlapEditField.Value = 1;
            app.ReconstructionTabGroup = uitabgroup(app.P2ROUD_UIFigure);
            app.ReconstructionTab = uitab(app.ReconstructionTabGroup);
            app.ReconstructionTab.Title = 'Reconstruction';
            app.RecoFig = uiaxes(app.ReconstructionTab);
            app.RecoFig.XTick = [];
            app.RecoFig.YTick = [];
            app.Dim6Slider = uislider(app.ReconstructionTab);
            app.Dim6Slider.Limits = [1 200];
            app.Dim6Slider.MajorTicks = [];
            app.Dim6Slider.ValueChangedFcn = createCallbackFcn(app, @Dim6SliderValueChanged, true);
            app.Dim6Slider.MinorTicks = [];
            app.Dim6Slider.Value = 1;
            app.Dim5Slider = uislider(app.ReconstructionTab);
            app.Dim5Slider.Limits = [1 200];
            app.Dim5Slider.MajorTicks = [];
            app.Dim5Slider.ValueChangedFcn = createCallbackFcn(app, @Dim5SliderValueChanged, true);
            app.Dim5Slider.MinorTicks = [];
            app.Dim5Slider.Value = 1;
            app.Dim4Slider = uislider(app.ReconstructionTab);
            app.Dim4Slider.Limits = [1 200];
            app.Dim4Slider.MajorTicks = [];
            app.Dim4Slider.ValueChangedFcn = createCallbackFcn(app, @Dim4SliderValueChanged, true);
            app.Dim4Slider.MinorTicks = [];
            app.Dim4Slider.Value = 1;
            app.Dim3Slider = uislider(app.ReconstructionTab);
            app.Dim3Slider.Limits = [1 200];
            app.Dim3Slider.MajorTicks = [];
            app.Dim3Slider.ValueChangedFcn = createCallbackFcn(app, @Dim3SliderValueChanged, true);
            app.Dim3Slider.MinorTicks = [];
            app.Dim3Slider.Value = 1;
            app.Dim6EditField = uieditfield(app.ReconstructionTab, 'numeric');
            app.Dim6EditField.Limits = [1 Inf];
            app.Dim6EditField.ValueDisplayFormat = '%.0f';
            app.Dim6EditField.ValueChangedFcn = createCallbackFcn(app, @Dim6EditFieldValueChanged, true);
            app.Dim6EditField.Value = 1;
            app.Dim5EditField = uieditfield(app.ReconstructionTab, 'numeric');
            app.Dim5EditField.Limits = [1 Inf];
            app.Dim5EditField.ValueDisplayFormat = '%.0f';
            app.Dim5EditField.ValueChangedFcn = createCallbackFcn(app, @Dim5EditFieldValueChanged, true);
            app.Dim5EditField.Value = 1;
            app.Dim4EditField = uieditfield(app.ReconstructionTab, 'numeric');
            app.Dim4EditField.Limits = [1 Inf];
            app.Dim4EditField.ValueDisplayFormat = '%.0f';
            app.Dim4EditField.ValueChangedFcn = createCallbackFcn(app, @Dim4EditFieldValueChanged, true);
            app.Dim4EditField.Value = 1;
            app.Dim3EditField = uieditfield(app.ReconstructionTab, 'numeric');
            app.Dim3EditField.Limits = [1 Inf];
            app.Dim3EditField.ValueDisplayFormat = '%.0f';
            app.Dim3EditField.ValueChangedFcn = createCallbackFcn(app, @Dim3EditFieldValueChanged, true);
            app.Dim3EditField.Value = 1;
            app.YLabel = uilabel(app.ReconstructionTab);
            app.YLabel.Text = '     Y     ';
            app.XLabel = uilabel(app.ReconstructionTab);
            app.XLabel.Text = '     X     ';
            app.Dim6Label = uilabel(app.ReconstructionTab);
            app.Dim6Label.Text = 'echo';
            app.Dim5Label = uilabel(app.ReconstructionTab);
            app.Dim5Label.Text = 'FA';
            app.Dim4Label = uilabel(app.ReconstructionTab);
            app.Dim4Label.Text = '             NR';
            app.Dim3Label = uilabel(app.ReconstructionTab);
            app.Dim3Label.Text = '              Z';
            app.LevelSlider = uislider(app.ReconstructionTab);
            app.LevelSlider.Limits = [0 65536];
            app.LevelSlider.MajorTicks = [];
            app.LevelSlider.ValueChangedFcn = createCallbackFcn(app, @LevelSliderValueChanged, true);
            app.LevelSlider.MinorTicks = [];
            app.LevelSlider.Tooltip = {'Level'};
            app.WLabel = uilabel(app.ReconstructionTab);
            app.WLabel.Text = 'W';
            app.LLabel = uilabel(app.ReconstructionTab);
            app.LLabel.Text = 'L';
            app.WindowSlider = uislider(app.ReconstructionTab);
            app.WindowSlider.Limits = [0 65536];
            app.WindowSlider.MajorTicks = [];
            app.WindowSlider.ValueChangedFcn = createCallbackFcn(app, @WindowSliderValueChanged, true);
            app.WindowSlider.MinorTicks = [];
            app.WindowSlider.Tooltip = {'Window'};
            app.LevelEditField = uieditfield(app.ReconstructionTab, 'numeric');
            app.LevelEditField.Limits = [0 Inf];
            app.LevelEditField.ValueDisplayFormat = '%.0f';
            app.LevelEditField.ValueChangedFcn = createCallbackFcn(app, @LevelEditFieldValueChanged, true);
            app.LevelEditField.Tooltip = {'Level'};
            app.WindowEditField = uieditfield(app.ReconstructionTab, 'numeric');
            app.WindowEditField.Limits = [0 Inf];
            app.WindowEditField.ValueDisplayFormat = '%.0f';
            app.WindowEditField.ValueChangedFcn = createCallbackFcn(app, @WindowEditFieldValueChanged, true);
            app.WindowEditField.Tooltip = {'Window'};
            app.SetButton = uibutton(app.ReconstructionTab, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Tooltip = {'Set level and window'};
            app.SetButton.Text = 'Set';
            app.Button = uibutton(app.ReconstructionTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @MovieButtonPushed, true);
            app.Button.Tooltip = {'Start movie'};
            app.Button.Text = '';
            app.Button_2 = uibutton(app.ReconstructionTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @MovieButton_2Pushed, true);
            app.Button_2.Tooltip = {'Stop movie'};
            app.Button_2.Text = '';
            app.AutoIntensityCheckBox = uicheckbox(app.ReconstructionTab);
            app.AutoIntensityCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoIntensityCheckBoxValueChanged, true);
            app.AutoIntensityCheckBox.Tooltip = {'Check for auto level and window'};
            app.AutoIntensityCheckBox.Text = '';
            app.AutoIntensityCheckBox.Value = true;
            app.AutoLabel = uilabel(app.ReconstructionTab);
            app.AutoLabel.Tooltip = {'Auto level and window'};
            app.AutoLabel.Text = 'auto';
            app.MovieImage = uiimage(app.ReconstructionTab);
            app.MovieImage.Enable = 'off';
            app.MovieImage.Tooltip = {'Movie dimension'};
            app.orientationSpinnerLabel = uilabel(app.ReconstructionTab);
            app.orientationSpinnerLabel.Text = 'orientation';
            app.OrientationSpinner = uispinner(app.ReconstructionTab);
            app.OrientationSpinner.Limits = [1 12];
            app.OrientationSpinner.ValueChangedFcn = createCallbackFcn(app, @OrientationSpinnerValueChanged, true);
            app.OrientationSpinner.Tooltip = {'Image orienation'};
            app.OrientationSpinner.Value = 1;
            app.ThresholdEditFieldLabel = uilabel(app.ReconstructionTab);
            app.ThresholdEditFieldLabel.Text = 'threshold';
            app.ThresholdEditField = uieditfield(app.ReconstructionTab, 'numeric');
            app.ThresholdEditField.Limits = [0 Inf];
            app.ThresholdEditField.ValueDisplayFormat = '%.0f';
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Tooltip = {'Mask threshold'};
            app.ThresholdSlider = uislider(app.ReconstructionTab);
            app.ThresholdSlider.Limits = [0 4095];
            app.ThresholdSlider.MajorTicks = [];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.MinorTicks = [];
            app.ThresholdSlider.Tooltip = {'Mask threshold slider'};
            app.ErodeButton = uibutton(app.ReconstructionTab, 'push');
            app.ErodeButton.ButtonPushedFcn = createCallbackFcn(app, @ErodeButtonPushed, true);
            app.ErodeButton.Tooltip = {'Mask erosion'};
            app.ErodeButton.Text = 'Erode';
            app.DilateButton = uibutton(app.ReconstructionTab, 'push');
            app.DilateButton.ButtonPushedFcn = createCallbackFcn(app, @DilateButtonPushed, true);
            app.DilateButton.Tooltip = {'Mask dilation'};
            app.DilateButton.Text = 'Dilate';
            app.CloseButton = uibutton(app.ReconstructionTab, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Tooltip = {'Mask closing'};
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.ReconstructionTab, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Tooltip = {'Mask opening'};
            app.OpenButton.Text = 'Open';
            app.MaskButton = uibutton(app.ReconstructionTab, 'push');
            app.MaskButton.ButtonPushedFcn = createCallbackFcn(app, @MaskButtonPushed, true);
            app.MaskButton.Tooltip = {'Image masking/segmentation'};
            app.MaskButton.Text = 'Mask';
            app.MIPTab = uitab(app.ReconstructionTabGroup);
            app.MIPTab.Tooltip = {''};
            app.MIPTab.Title = 'MIP';
            app.MIPFig = uiaxes(app.MIPTab);
            app.MIPFig.XTick = [];
            app.MIPFig.YTick = [];
            app.MIPyAngleSlider = uislider(app.MIPTab);
            app.MIPyAngleSlider.Limits = [-180 180];
            app.MIPyAngleSlider.MajorTicks = [];
            app.MIPyAngleSlider.ValueChangedFcn = createCallbackFcn(app, @MIPAngleSliderValueChanged, true);
            app.MIPyAngleSlider.MinorTicks = [];
            app.MIPxAngleSlider = uislider(app.MIPTab);
            app.MIPxAngleSlider.Limits = [-180 180];
            app.MIPxAngleSlider.MajorTicks = [];
            app.MIPxAngleSlider.ValueChangedFcn = createCallbackFcn(app, @MIPAngleSliderValueChanged, true);
            app.MIPxAngleSlider.MinorTicks = [];
            app.MIPyAngleLabel = uilabel(app.MIPTab);
            app.MIPyAngleLabel.Text = 'Y';
            app.MIPxAngleLabel = uilabel(app.MIPTab);
            app.MIPxAngleLabel.Text = 'X';
            app.MIPRotateLabel = uilabel(app.MIPTab);
            app.MIPRotateLabel.Text = 'Rotate';
            app.MIPAngleResetButton = uibutton(app.MIPTab, 'push');
            app.MIPAngleResetButton.ButtonPushedFcn = createCallbackFcn(app, @MIPAngleSliderResetButtonPushed, true);
            app.MIPAngleResetButton.Tooltip = {'Reset MIP orientation and stop the movie'};
            app.MIPAngleResetButton.Text = 'Reset';
            app.MIPLevelSlider = uislider(app.MIPTab);
            app.MIPLevelSlider.Limits = [0 65536];
            app.MIPLevelSlider.MajorTicks = [];
            app.MIPLevelSlider.ValueChangedFcn = createCallbackFcn(app, @MIPLevelSliderValueChanged, true);
            app.MIPLevelSlider.MinorTicks = [];
            app.MIPLevelSlider.Tooltip = {'Level'};
            app.MIPWLabel = uilabel(app.MIPTab);
            app.MIPWLabel.Text = 'W';
            app.MIPLLabel = uilabel(app.MIPTab);
            app.MIPLLabel.Text = 'L';
            app.MIPWindowSlider = uislider(app.MIPTab);
            app.MIPWindowSlider.Limits = [0 65536];
            app.MIPWindowSlider.MajorTicks = [];
            app.MIPWindowSlider.ValueChangedFcn = createCallbackFcn(app, @MIPWindowSliderValueChanged, true);
            app.MIPWindowSlider.MinorTicks = [];
            app.MIPWindowSlider.Tooltip = {'window'};
            app.MIPLevelEditField = uieditfield(app.MIPTab, 'numeric');
            app.MIPLevelEditField.Limits = [0 Inf];
            app.MIPLevelEditField.ValueDisplayFormat = '%.0f';
            app.MIPLevelEditField.ValueChangedFcn = createCallbackFcn(app, @MIPLevelEditFieldValueChanged, true);
            app.MIPLevelEditField.Tooltip = {'Level'};
            app.MIPWindowEditField = uieditfield(app.MIPTab, 'numeric');
            app.MIPWindowEditField.Limits = [0 Inf];
            app.MIPWindowEditField.ValueDisplayFormat = '%.0f';
            app.MIPWindowEditField.ValueChangedFcn = createCallbackFcn(app, @MIPWindowEditFieldValueChanged, true);
            app.MIPWindowEditField.Tooltip = {'Window'};
            app.MIPSetButton = uibutton(app.MIPTab, 'push');
            app.MIPSetButton.ButtonPushedFcn = createCallbackFcn(app, @MIPSetButtonPushed, true);
            app.MIPSetButton.Tooltip = {'Set Level and Window'};
            app.MIPSetButton.Text = 'Set';
            app.MIPYLabel = uilabel(app.MIPTab);
            app.MIPYLabel.Text = '     Y     ';
            app.MIPXLabel = uilabel(app.MIPTab);
            app.MIPXLabel.Text = '     X     ';
            app.orientationLabel_2 = uilabel(app.MIPTab);
            app.orientationLabel_2.Text = 'orientation';
            app.MIPOrientationSpinner = uispinner(app.MIPTab);
            app.MIPOrientationSpinner.Limits = [1 12];
            app.MIPOrientationSpinner.ValueChangedFcn = createCallbackFcn(app, @MIPOrientationSpinnerValueChanged, true);
            app.MIPOrientationSpinner.Tooltip = {'Image orienation'};
            app.MIPOrientationSpinner.Value = 1;
            app.PlayMIPyButton = uibutton(app.MIPTab, 'push');
            app.PlayMIPyButton.ButtonPushedFcn = createCallbackFcn(app, @PlayMIPyButtonPushed, true);
            app.PlayMIPyButton.Tooltip = {'Start MIP movie'};
            app.PlayMIPyButton.Text = '';
            app.PlayMIPxButton = uibutton(app.MIPTab, 'push');
            app.PlayMIPxButton.ButtonPushedFcn = createCallbackFcn(app, @PlayMIPxButtonPushed, true);
            app.PlayMIPxButton.Tooltip = {'Play MIP movie'};
            app.PlayMIPxButton.Text = '';
            app.MIPResizeButton = uibutton(app.MIPTab, 'state');
            app.MIPResizeButton.ValueChangedFcn = createCallbackFcn(app, @MIPResizeButtonValueChanged, true);
            app.MIPResizeButton.Tooltip = {'Downsample MIP for speed'};
            app.MIPResizeButton.Text = 'Resize';
            app.PhaseTab = uitab(app.ReconstructionTabGroup);
            app.PhaseTab.Title = 'Phase';
            app.PhaseRecoFig = uiaxes(app.PhaseTab);
            app.PhaseRecoFig.XTick = [];
            app.PhaseRecoFig.YTick = [];
            app.PhaseYLabel = uilabel(app.PhaseTab);
            app.PhaseYLabel.Text = '     Y     ';
            app.PhaseXLabel = uilabel(app.PhaseTab);
            app.PhaseXLabel.Text = '     X     ';
            app.PhaseMovieStartButton = uibutton(app.PhaseTab, 'push');
            app.PhaseMovieStartButton.ButtonPushedFcn = createCallbackFcn(app, @PhaseMovieStartButtonPushed, true);
            app.PhaseMovieStartButton.Tooltip = {'Start movie'};
            app.PhaseMovieStartButton.Text = '';
            app.PhaseMovieStopButton = uibutton(app.PhaseTab, 'push');
            app.PhaseMovieStopButton.ButtonPushedFcn = createCallbackFcn(app, @PhaseMovieStopButtonPushed, true);
            app.PhaseMovieStopButton.Tooltip = {'Stop movie'};
            app.PhaseMovieStopButton.Text = '';
            app.PhaseDim3Slider = uislider(app.PhaseTab);
            app.PhaseDim3Slider.Limits = [1 200];
            app.PhaseDim3Slider.MajorTicks = [];
            app.PhaseDim3Slider.ValueChangedFcn = createCallbackFcn(app, @PhaseDim3SliderValueChanged, true);
            app.PhaseDim3Slider.MinorTicks = [];
            app.PhaseDim3Slider.Value = 1;
            app.PhaseDim5Slider = uislider(app.PhaseTab);
            app.PhaseDim5Slider.Limits = [1 200];
            app.PhaseDim5Slider.MajorTicks = [];
            app.PhaseDim5Slider.ValueChangedFcn = createCallbackFcn(app, @PhaseDim5SliderValueChanged, true);
            app.PhaseDim5Slider.MinorTicks = [];
            app.PhaseDim5Slider.Value = 1;
            app.PhaseDim4Slider = uislider(app.PhaseTab);
            app.PhaseDim4Slider.Limits = [1 200];
            app.PhaseDim4Slider.MajorTicks = [];
            app.PhaseDim4Slider.ValueChangedFcn = createCallbackFcn(app, @PhaseDim4SliderValueChanged, true);
            app.PhaseDim4Slider.MinorTicks = [];
            app.PhaseDim4Slider.Value = 1;
            app.PhaseDim3EditField = uieditfield(app.PhaseTab, 'numeric');
            app.PhaseDim3EditField.Limits = [1 Inf];
            app.PhaseDim3EditField.ValueDisplayFormat = '%.0f';
            app.PhaseDim3EditField.ValueChangedFcn = createCallbackFcn(app, @PhaseDim3EditFieldValueChanged, true);
            app.PhaseDim3EditField.Value = 1;
            app.PhaseDim5EditField = uieditfield(app.PhaseTab, 'numeric');
            app.PhaseDim5EditField.Limits = [1 Inf];
            app.PhaseDim5EditField.ValueDisplayFormat = '%.0f';
            app.PhaseDim5EditField.ValueChangedFcn = createCallbackFcn(app, @PhaseDim5EditFieldValueChanged, true);
            app.PhaseDim5EditField.Value = 1;
            app.PhaseDim4EditField = uieditfield(app.PhaseTab, 'numeric');
            app.PhaseDim4EditField.Limits = [1 Inf];
            app.PhaseDim4EditField.ValueDisplayFormat = '%.0f';
            app.PhaseDim4EditField.ValueChangedFcn = createCallbackFcn(app, @PhaseDim4EditFieldValueChanged, true);
            app.PhaseDim4EditField.Value = 1;
            app.PhaseDim6EditField = uieditfield(app.PhaseTab, 'numeric');
            app.PhaseDim6EditField.Limits = [1 Inf];
            app.PhaseDim6EditField.ValueDisplayFormat = '%.0f';
            app.PhaseDim6EditField.ValueChangedFcn = createCallbackFcn(app, @PhaseDim6EditFieldValueChanged, true);
            app.PhaseDim6EditField.Value = 1;
            app.PhaseDim6Slider = uislider(app.PhaseTab);
            app.PhaseDim6Slider.Limits = [1 200];
            app.PhaseDim6Slider.MajorTicks = [];
            app.PhaseDim6Slider.ValueChangedFcn = createCallbackFcn(app, @PhaseDim6SliderValueChanged, true);
            app.PhaseDim6Slider.MinorTicks = [];
            app.PhaseDim6Slider.Value = 1;
            app.PhaseDim3Label = uilabel(app.PhaseTab);
            app.PhaseDim3Label.Text = '              Z';
            app.PhaseDim4Label = uilabel(app.PhaseTab);
            app.PhaseDim4Label.Text = '             NR';
            app.PhaseDim5Label = uilabel(app.PhaseTab);
            app.PhaseDim5Label.Text = 'FA';
            app.PhaseDim6Label = uilabel(app.PhaseTab);
            app.PhaseDim6Label.Text = 'echo';
            app.PhaseMovieImage = uiimage(app.PhaseTab);
            app.PhaseMovieImage.Enable = 'off';
            app.PhaseMovieImage.Tooltip = {'Movie dimension'};
            app.OrientationSpinner_2Label_2 = uilabel(app.PhaseTab);
            app.OrientationSpinner_2Label_2.Text = 'orientation';
            app.PhaseOrientationSpinner = uispinner(app.PhaseTab);
            app.PhaseOrientationSpinner.Limits = [1 12];
            app.PhaseOrientationSpinner.ValueChangedFcn = createCallbackFcn(app, @PhaseOrientationSpinnerValueChanged, true);
            app.PhaseOrientationSpinner.Tooltip = {'Image orientation'};
            app.PhaseOrientationSpinner.Value = 1;
            app.PhaseUnwrapButton = uibutton(app.PhaseTab, 'push');
            app.PhaseUnwrapButton.ButtonPushedFcn = createCallbackFcn(app, @PhaseUnwrapButtonPushed, true);
            app.PhaseUnwrapButton.Tooltip = {'Phase unwrapping'};
            app.PhaseUnwrapButton.Text = 'Unwrap';
            app.maxPhaseLabel = uilabel(app.PhaseTab);
            app.maxPhaseLabel.Text = 'max';
            app.minPhaseLabel = uilabel(app.PhaseTab);
            app.minPhaseLabel.Text = 'min';
            app.FlowTab = uitab(app.ReconstructionTabGroup);
            app.FlowTab.Title = '4D-Flow';
            app.FlowRecoFig = uiaxes(app.FlowTab);
            app.FlowRecoFig.XTick = [];
            app.FlowRecoFig.YTick = [];
            app.FlowYLabel = uilabel(app.FlowTab);
            app.FlowYLabel.Text = '     Y     ';
            app.FlowXLabel = uilabel(app.FlowTab);
            app.FlowXLabel.Text = '     X     ';
            app.FlowDim3Slider = uislider(app.FlowTab);
            app.FlowDim3Slider.Limits = [1 200];
            app.FlowDim3Slider.MajorTicks = [];
            app.FlowDim3Slider.ValueChangedFcn = createCallbackFcn(app, @FlowDim3SliderValueChanged, true);
            app.FlowDim3Slider.MinorTicks = [];
            app.FlowDim3Slider.Value = 1;
            app.FlowDim5Slider = uislider(app.FlowTab);
            app.FlowDim5Slider.Limits = [1 200];
            app.FlowDim5Slider.MajorTicks = [];
            app.FlowDim5Slider.ValueChangedFcn = createCallbackFcn(app, @FlowDim5SliderValueChanged, true);
            app.FlowDim5Slider.MinorTicks = [];
            app.FlowDim5Slider.Value = 1;
            app.FlowDim4Slider = uislider(app.FlowTab);
            app.FlowDim4Slider.Limits = [1 200];
            app.FlowDim4Slider.MajorTicks = [];
            app.FlowDim4Slider.ValueChangedFcn = createCallbackFcn(app, @FlowDim4SliderValueChanged, true);
            app.FlowDim4Slider.MinorTicks = [];
            app.FlowDim4Slider.Value = 1;
            app.FlowDim3EditField = uieditfield(app.FlowTab, 'numeric');
            app.FlowDim3EditField.Limits = [1 Inf];
            app.FlowDim3EditField.ValueDisplayFormat = '%.0f';
            app.FlowDim3EditField.ValueChangedFcn = createCallbackFcn(app, @FlowDim3EditFieldValueChanged, true);
            app.FlowDim3EditField.Value = 1;
            app.FlowDim5EditField = uieditfield(app.FlowTab, 'numeric');
            app.FlowDim5EditField.Limits = [1 Inf];
            app.FlowDim5EditField.ValueDisplayFormat = '%.0f';
            app.FlowDim5EditField.ValueChangedFcn = createCallbackFcn(app, @FlowDim5EditFieldValueChanged, true);
            app.FlowDim5EditField.Value = 1;
            app.FlowDim4EditField = uieditfield(app.FlowTab, 'numeric');
            app.FlowDim4EditField.Limits = [1 Inf];
            app.FlowDim4EditField.ValueDisplayFormat = '%.0f';
            app.FlowDim4EditField.ValueChangedFcn = createCallbackFcn(app, @FlowDim4EditFieldValueChanged, true);
            app.FlowDim4EditField.Value = 1;
            app.FlowDim6EditField = uieditfield(app.FlowTab, 'numeric');
            app.FlowDim6EditField.Limits = [1 Inf];
            app.FlowDim6EditField.ValueDisplayFormat = '%.0f';
            app.FlowDim6EditField.ValueChangedFcn = createCallbackFcn(app, @FlowDim6EditFieldValueChanged, true);
            app.FlowDim6EditField.Value = 1;
            app.FlowDim6Slider = uislider(app.FlowTab);
            app.FlowDim6Slider.Limits = [1 200];
            app.FlowDim6Slider.MajorTicks = [];
            app.FlowDim6Slider.ValueChangedFcn = createCallbackFcn(app, @FlowDim6SliderValueChanged, true);
            app.FlowDim6Slider.MinorTicks = [];
            app.FlowDim6Slider.Value = 1;
            app.FlowDim3Label = uilabel(app.FlowTab);
            app.FlowDim3Label.Text = '              Z';
            app.FlowDim4Label = uilabel(app.FlowTab);
            app.FlowDim4Label.Text = '             NR';
            app.FlowDim5Label = uilabel(app.FlowTab);
            app.FlowDim5Label.Text = 'FA';
            app.FlowDim6Label = uilabel(app.FlowTab);
            app.FlowDim6Label.Text = 'echo';
            app.FlowMovieImage = uiimage(app.FlowTab);
            app.FlowMovieImage.Enable = 'off';
            app.FlowOrientationSpinnerLabel = uilabel(app.FlowTab);
            app.FlowOrientationSpinnerLabel.Text = 'orientation';
            app.FlowOrientationSpinner = uispinner(app.FlowTab);
            app.FlowOrientationSpinner.Limits = [1 12];
            app.FlowOrientationSpinner.ValueChangedFcn = createCallbackFcn(app, @FlowOrientationSpinnerValueChanged, true);
            app.FlowOrientationSpinner.Tooltip = {'Image orientation'};
            app.FlowOrientationSpinner.Value = 1;
            app.FlowMovieStartButton = uibutton(app.FlowTab, 'push');
            app.FlowMovieStartButton.ButtonPushedFcn = createCallbackFcn(app, @FlowMovieStartButtonPushed, true);
            app.FlowMovieStartButton.Tooltip = {'Start movie'};
            app.FlowMovieStartButton.Text = '';
            app.FlowMovieStopButton = uibutton(app.FlowTab, 'push');
            app.FlowMovieStopButton.ButtonPushedFcn = createCallbackFcn(app, @FlowMovieStopButtonPushed, true);
            app.FlowMovieStopButton.Tooltip = {'Stop movie'};
            app.FlowMovieStopButton.Text = '';
            app.CalcFlowButton = uibutton(app.FlowTab, 'push');
            app.CalcFlowButton.ButtonPushedFcn = createCallbackFcn(app, @CalcFlowButtonPushed, true);
            app.CalcFlowButton.Enable = 'off';
            app.CalcFlowButton.Tooltip = {'Calculate 4D flow'};
            app.CalcFlowButton.Text = 'Calc flow';
            app.LoadVencTableButton = uibutton(app.FlowTab, 'push');
            app.LoadVencTableButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVencTableButtonPushed, true);
            app.LoadVencTableButton.Tooltip = {'Load flow encoding table'};
            app.LoadVencTableButton.Text = 'Venc table';
            app.FlowDirectionSpinnerLabel = uilabel(app.FlowTab);
            app.FlowDirectionSpinnerLabel.Text = 'Vz (cm/s)';
            app.FlowDirectionSpinner = uispinner(app.FlowTab);
            app.FlowDirectionSpinner.Limits = [1 5];
            app.FlowDirectionSpinner.ValueChangedFcn = createCallbackFcn(app, @FlowDirectionSpinnerValueChanged, true);
            app.FlowDirectionSpinner.Tooltip = {'Flow encoding direction'};
            app.FlowDirectionSpinner.Value = 1;
            app.VencLamp = uilamp(app.FlowTab);
            app.minLabel = uilabel(app.FlowTab);
            app.minLabel.Text = 'min';
            app.maxLabel = uilabel(app.FlowTab);
            app.maxLabel.Text = 'max';
            app.MessagesTabGroup = uitabgroup(app.P2ROUD_UIFigure);
            app.MessagesTab = uitab(app.MessagesTabGroup);
            app.MessagesTab.Title = 'Messages';
            app.MessageBox = uitextarea(app.MessagesTab);
            app.MessageBox.Tooltip = {'Messages'};
            app.StatusBox = uitextarea(app.MessagesTab);
            app.StatusBox.Tooltip = {'App status'};
            app.StatusImage = uiimage(app.MessagesTab);
            app.statusLabel = uilabel(app.MessagesTab);
            app.statusLabel.Text = 'status';
            app.ExportPanel = uipanel(app.P2ROUD_UIFigure);
            app.ExportPanel.Tooltip = {'Reconstruction dimensions'};
            app.ExportPanel.Title = 'Export';
            app.ExportDCMButton = uibutton(app.ExportPanel, 'push');
            app.ExportDCMButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDCMButtonPushed, true);
            app.ExportDCMButton.Enable = 'off';
            app.ExportDCMButton.Tooltip = {'Export to DICOM'};
            app.ExportDCMButton.Text = 'Export DCM';
            app.Lamp3 = uilamp(app.ExportPanel);
            app.ExportProgressGauge = uigauge(app.ExportPanel, 'linear');
            app.ExportProgressGauge.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.ExportNIFTIButton = uibutton(app.ExportPanel, 'push');
            app.ExportNIFTIButton.ButtonPushedFcn = createCallbackFcn(app, @ExportNIFTIButtonPushed, true);
            app.ExportNIFTIButton.Enable = 'off';
            app.ExportNIFTIButton.Tooltip = {'Export to NIFTI'};
            app.ExportNIFTIButton.Text = 'Export NIFTI';
            app.Lamp4 = uilamp(app.ExportPanel);
            app.ExportMRDButton = uibutton(app.ExportPanel, 'push');
            app.ExportMRDButton.ButtonPushedFcn = createCallbackFcn(app, @ExportMRDButtonPushed, true);
            app.ExportMRDButton.Enable = 'off';
            app.ExportMRDButton.Tooltip = {'Export to MRD file'};
            app.ExportMRDButton.Text = 'Export MRD';
            app.Lamp5 = uilamp(app.ExportPanel);
            app.ExportGIFButton = uibutton(app.ExportPanel, 'push');
            app.ExportGIFButton.ButtonPushedFcn = createCallbackFcn(app, @ExportGIFButtonPushed, true);
            app.ExportGIFButton.Enable = 'off';
            app.ExportGIFButton.Tooltip = {'Export to GIF'};
            app.ExportGIFButton.Text = 'Export GIF';
            app.Lamp6 = uilamp(app.ExportPanel);
            % Show the figure after all components are created
            app.P2ROUD_UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = P2ROUD

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.P2ROUD_UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.P2ROUD_UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.P2ROUD_UIFigure)
        end
    end
end